classdef igaSync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        TWTLabel                        matlab.ui.control.Label
        ChooseYourCADFileButton         matlab.ui.control.Button
        TUMLabel                        matlab.ui.control.Label
        CurvesTab                       matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        DesignTab                       matlab.ui.container.Tab
        ControlPointsSwitch             matlab.ui.control.Switch
        ControlPointsSwitchLabel        matlab.ui.control.Label
        ClearPlotButton                 matlab.ui.control.Button
        VisualizeButton                 matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DegreeElevationTab              matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        DegreeChangeSpinner             matlab.ui.control.Spinner
        DegreeChangeSpinnerLabel        matlab.ui.control.Label
        KnotInsertionTab                matlab.ui.container.Tab
        NumberofKnotsSpinner            matlab.ui.control.Spinner
        NumberofKnotsSpinnerLabel       matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        BeamIGAAFEMLabButton            matlab.ui.control.Button
        ResultTypeDropDown              matlab.ui.control.DropDown
        ResultTypeDropDownLabel         matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        LeftBCTab                       matlab.ui.container.Tab
        ClampLeftRotationCheckBox       matlab.ui.control.CheckBox
        ClampLeftDisplacementCheckBox   matlab.ui.control.CheckBox
        RightBCTab                      matlab.ui.container.Tab
        ClampRightRotationCheckBox      matlab.ui.control.CheckBox
        ClampRightDisplacementCheckBox  matlab.ui.control.CheckBox
        LoadkNmEditField                matlab.ui.control.NumericEditField
        LoadkNmEditFieldLabel           matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        DepthmEditField                 matlab.ui.control.NumericEditField
        DepthmEditFieldLabel            matlab.ui.control.Label
        WidthmEditField                 matlab.ui.control.NumericEditField
        WidthmEditFieldLabel            matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        YoungsModuluskNm2EditField      matlab.ui.control.NumericEditField
        YoungsModuluskNm2Label          matlab.ui.control.Label
        BeamTypeDropDown                matlab.ui.control.DropDown
        BeamTypeDropDownLabel           matlab.ui.control.Label
        PerformBeamIGAButton            matlab.ui.control.Button
        PostProcessingTab_3             matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        PlatesTab                       matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        DesignTab_2                     matlab.ui.container.Tab
        PatchDropDown                   matlab.ui.control.DropDown
        PatchDropDownLabel              matlab.ui.control.Label
        SurfacePlotPropertiesPanel      matlab.ui.container.Panel
        PatchLabelsCheckBox             matlab.ui.control.CheckBox
        NumandIDsCheckBox               matlab.ui.control.CheckBox
        ElementsCheckBox                matlab.ui.control.CheckBox
        ControlPolygonCheckBox          matlab.ui.control.CheckBox
        ClearPlotButton_2               matlab.ui.control.Button
        VisualizeButton_2               matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        DegreeElevationTab_2            matlab.ui.container.Tab
        Label2                          matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        DeltaqetaSpinner                matlab.ui.control.Spinner
        DeltaqetaLabel                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        DeltapxiSpinner                 matlab.ui.control.Spinner
        DeltaPxiLabel                   matlab.ui.control.Label
        KnotInsertionTab_2              matlab.ui.container.Tab
        NumberofKnotsetaSpinner         matlab.ui.control.Spinner
        NumberofKnotsetaLabel           matlab.ui.control.Label
        NumberofKnotsxiSpinner          matlab.ui.control.Spinner
        NumberofKnotsxiLabel            matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        AnalysisTab_2                   matlab.ui.container.Tab
        AutomaticCouplingButton         matlab.ui.control.Button
        PerformAnalysisButton           matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        SetBCongeometryButton           matlab.ui.control.Button
        textbfYoungsModuluskNm2EditField  matlab.ui.control.NumericEditField
        YoungsModuluskNmLabel           matlab.ui.control.Label
        TabGroup5                       matlab.ui.container.TabGroup
        BCsusingParametersTab           matlab.ui.container.Tab
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        Dirich_shell_4                  matlab.ui.control.Label
        Dirich_shell_3                  matlab.ui.control.Label
        Dirich_shell_2                  matlab.ui.control.Label
        Dirich_shell_1                  matlab.ui.control.Label
        CheckBox_1                      matlab.ui.control.CheckBox
        textbfSurfaceLoadkNm2EditField  matlab.ui.control.NumericEditField
        SurfaceLoadkNm2Label            matlab.ui.control.Label
        ShearFactoralphaEditField       matlab.ui.control.NumericEditField
        ShearFactoralphaEditFieldLabel  matlab.ui.control.Label
        ThicknessmEditField             matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        PoissonsRatioEditField_2        matlab.ui.control.NumericEditField
        PoissonsRatioEditField_2Label   matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        PostProcessingTab_2             matlab.ui.container.Tab
        PlotTypeDropDown                matlab.ui.control.DropDown
        PlotTypeDropDownLabel           matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        CommandHistoryTab               matlab.ui.container.Tab
        outputHistory                   matlab.ui.control.TextArea
        EditField                       matlab.ui.control.EditField
    end


    properties (Access = private)
        begin=1; dim=1; polyOrder=2; weight=1;knotVector=[0 0 0 1 1 2 2 3 3 4 4 4];controlPoints; % initial start up circle display
        pU; pV; knotU; knotV;weigtArray;plot1;plot2;plot3;propGeomCircle;changeProp;deformProp; % plots --get rid of these soon <not relevant anymore>
        globDisp; propStructure; propGeomTrim; % single_patch --get rid of it soon <not relevant anymore>
        num_patch; dict_list;change_dict_list;deg_dict_list;  % multi_patch data parsing
        file_path;Loop;
        coupling_info,dir_info,dir_mtx,dir_curve, coupling_mtx,coupling_curve % multi_patch data coupling and dirichlet
        commandCounter; % For History

    end


    methods (Access = private)


        function updatePlot(app)
            if app.dim==1 & app.num_patch==1
                cla(app.UIAxes);
                app.Label.Text= ' ';
                ax= app.UIAxes;
                numPtsCircle=100;
                isPlotNumsAndIds = true;
                app.plotNURBSCurve(ax,app.changeProp, numPtsCircle, isPlotNumsAndIds);
                app.Label.Text= sprintf('Polynomial Degree: %d', app.changeProp.polOrd);

            else

                cla(app.UIAxes_2);
                app.propGeomTrim.poly=zeros(100,2,app.num_patch);
                for i=1:app.num_patch
                    app.Label_3.Text= ' ';
                     app.Label2.Text= ' ';
                    app.Loop=i;
                    numPtsXi=100;
                    numPtsEta=100;
                    prop.polOrd= [double(app.change_dict_list{i}.vDeg);double(app.change_dict_list{i}.uDeg)];
                    app.Label_3.Text= sprintf('Degree xi : %d', prop.polOrd(1));
                    app.Label2.Text= sprintf(' Degree eta : %d', prop.polOrd(2));

                    prop.knotVct= {app.change_dict_list{i}.vknotVector,app.change_dict_list{i}.uknotVector};
                    prop.weights=app.change_dict_list{i}.weights;
                    prop.controlPoints=app.change_dict_list{i}.controlPoints;
                    prop.dim=app.dim;

                    numBFs=[ numel(prop.knotVct{1})-prop.polOrd(1)-1;
                        numel(prop.knotVct{2})-prop.polOrd(2)-1];
                    numBFs=double(numBFs);

                    app.propGeomTrim.isTrim=double(app.change_dict_list{i}.isTrim);
                    app.changeProp=prop;
                    % 100 points will be transfered from trim polygon
                    if app.propGeomTrim.isTrim==1
                        app.propGeomTrim.poly(:,:,i)=transpose(app.change_dict_list{i}.TrimPolygon);
                    end

                    app.plotNURBSSurface(app.UIAxes_2,prop, numPtsXi, numPtsEta, numBFs,i);
                    axis(app.UIAxes_2, 'tight');
                    hold(app.UIAxes_2,'on');
                end
                hold(app.UIAxes_2,'off');

            end
        end


        function plotNURBSCurve(app,ax,propGeom, numPts, isPlotNumsAndIds)
            numDerivs = 0;
            numBFs = size(propGeom.controlPoints, 2);
            minGeo = min(propGeom.controlPoints, [], "all");
            maxGeo = max(propGeom.controlPoints, [], "all");
            charLen = (minGeo - maxGeo)/20;
            xi = linspace(propGeom.knotVct(1), propGeom.knotVct(end), numPts);
            X = zeros(numel(xi), 3);
            for ii = 1:numel(xi)
                bfs = app.computeNURBSBasisFunctions1d(propGeom.knotVct, propGeom.polOrd, xi(ii), propGeom.weights, numDerivs);
                X(ii, :) = bfs*transpose(propGeom.controlPoints);
            end
            app.plot1=plot(ax,X(:, 1), X(:, 2), LineWidth=2, Color=([0.1,0.8,0.8]));
            hold(ax,'on');
            if isPlotNumsAndIds
                for ii = 1:numBFs
                    app.plot3=text(ax,propGeom.controlPoints(1, ii) + charLen, propGeom.controlPoints(2, ii) + ...
                        charLen, sprintf("CP$_{%d}$", ii), Interpreter="latex");
                end
                app.plot2= plot(ax,propGeom.controlPoints(1, :), propGeom.controlPoints(2, :), "r--", Marker='o', MarkerSize=10, ...
                    MarkerFaceColor='r' , LineWidth=2);
            end

        end


        function colmatw = computeNURBSBasisFunctions1d(app,knotVct, polOrd, xi, wmat, ordDerv)
            num_cps= numel(knotVct)-polOrd-1;
            colmat = app.computeBSplineBasisFunctions1d(knotVct, polOrd, brk2knt(xi, ordDerv + 1));
            knotSpanId = app.findKnotSpan(xi, knotVct, num_cps);
            F = sum(colmat(:, knotSpanId-polOrd:knotSpanId) .* wmat(knotSpanId-polOrd:knotSpanId), 2);
            colmatw = zeros(size(colmat));
            for ii = 1:polOrd + 1
                for jj = 0:ordDerv
                    indexBF = knotSpanId - polOrd + ii - 1;
                    v = colmat(jj + 1, indexBF)*wmat(1, indexBF);
                    for kk = 1:jj
                        v = v - nchoosek(jj, kk)*F(kk + 1, 1)*colmatw(jj - kk + 1, indexBF);
                    end
                    colmatw(jj + 1, knotSpanId - polOrd + ii - 1) = v/F(1, 1);
                end
            end
        end


        function colmat = computeBSplineBasisFunctions1d(app,knotVct, polOrd, xi)

            colmat = spcol(knotVct, polOrd + 1, xi);
        end


        function idKnotSpan = findKnotSpan(app,xi, knotVct, numCPs)
            eps = 1e-7;
            numKnots = length(knotVct);
            polOrd = numKnots - numCPs - 1;
            if any(diff(knotVct(1, 1:polOrd + 1)) ~= 0) || any(diff(knotVct(1, end-polOrd:end)) ~= 0)
                mystr = sprintf("%1.1f ", knotVct);
                error(strcat("Provided knot vector knotVct = [ ", mystr, "]", "is not open as polynomial order is %d"), polOrd)
            end
            if norm(xi) < knotVct(1) - eps
                xi = knotVct(1);
            end
            if abs(xi - knotVct(numCPs + 1)) < eps
                idKnotSpan = numCPs;
                return
            end
            for idKnotSpan = 1:numKnots-1
                if xi < knotVct(idKnotSpan + 1)
                    return
                end
            end
            %error('The provided parametric location xi = %1.1f lies outside of the knot vector knotVct = [%1.1f]', xi, knotVct);
            error('The provided parametric location xi = %1.1f lies outside of the knot vector knotVct', xi);
        end


        function performOrderElevationForNURBSCurve(app,propGeom, tp)
            cla(app.UIAxes);
            p = propGeom.polOrd;
            Xi = propGeom.knotVct;
            numCPs = size(propGeom.controlPoints, 2);
            CP = zeros(numCPs, 4);
            CP(:, 1:3) = transpose(propGeom.controlPoints);
            CP(:, 4) = transpose(propGeom.weights);
            nxi = size(propGeom.controlPoints, 2);
            % Number of knots of the unrefined geometry
            mxi = nxi + p + 1;
            % Polynomial degree of the curve after refinement
            pr = p + tp;
            % Initialize arrays
            bezalfs = zeros(pr+1,p+1);
            bpts = zeros(p+1,length(CP(1,:)));
            ebpts = zeros(pr+1,length(CP(1,:)));
            Nextbpts = zeros(p-1,length(CP(1,:)));
            Pw = zeros(nxi,length(CP(1,:)));
            %% 1. Compute the projective Control Points Pw
            for i = 1:nxi
                Pw(i,1:3) = CP(i,1:3)*CP(i,4);
                Pw(i,4)   = CP(i,4);
            end

            %% 2. Degree elevate to polynomial degree p + tp
            if tp>0
                % preallocate Ur
                ku = 1;

                for i=1:mxi-1
                    if Xi(i)~=Xi(i+1)
                        ku = ku + 1;
                    end
                end
                Xir = zeros(1,mxi+tp*ku);

                % compute Bezier degree elevation coefficients
                bezalfs(1,1) = 1.0;
                bezalfs(pr+1,p+1) = 1.0;

                % bezalfs are symmetric to row pr/2
                for i = 1:fix(pr/2)
                    inv = 1/app.getBinomialCoefficients(pr,i);
                    mpi = min(p,i);

                    % alphas for degree elevation
                    for j = max(0,i-tp):mpi
                        bezalfs(i+1,j+1) = inv*app.getBinomialCoefficients(p,j)*app.getBinomialCoefficients(tp,i-j);
                        bezalfs(pr-i+1,p-j+1) = bezalfs(i+1,j+1);    % symmetry !
                    end
                end

                mh = pr;
                kind = pr+1;
                r = -1;
                a = p + 1;
                b = p + 2;
                cind = 1;
                ua = Xi(a);
                Qw(1,:) = Pw(1,:);

                % left end of Ur
                for i = 1:pr+1
                    Xir(i) = ua;
                end

                % initialize first Bezier seg
                for i = 1:p+1
                    bpts(i,:) = Pw(i,:);
                end

                % big loop through knot vector
                while b<mxi
                    i = b;

                    % make b the rightmost ocurrence of ub
                    while b<mxi && Xi(b)==Xi(b+1)
                        b = b + 1;
                    end

                    % multiplicity of ub
                    mult = b - i + 1;
                    mh = mh + mult + tp;
                    ub = Xi(b);

                    % r from last segment
                    oldr = r;

                    % ub to be inserted r times
                    r = p - mult;

                    if oldr>0
                        lbz = fix((oldr+2)/2);
                    else
                        lbz = 1;
                    end

                    if r>0
                        rbz = pr-fix((r+1)/2);
                    else
                        rbz = pr;
                    end

                    % insert knots to get Bezier segment
                    alphas = zeros(1,p-mult);
                    if r>0
                        numer = ub - ua;

                        % alpha for knot insertion
                        for k = p:-1:mult+1
                            alphas(k-mult) = numer/(Xi(a+k+1)-ua);
                        end

                        % r times knot insertion
                        for j = 1:r
                            save = r - j + 1;
                            s = mult + j;

                            % new CP due to knot insertion
                            for k = p+1:-1:s+1
                                bpts(k,:) = alphas(k-s)*bpts(k,:)+(1-alphas(k-s))*bpts(k-1,:);
                            end
                            Nextbpts(save,:) = bpts(p+1,:);
                        end
                    end
                    % end of inserting knots

                    % degree elevate Bezier
                    for i = lbz:pr
                        % only points lbz,..,pr are used below
                        ebpts(i+1,1:4) = 0;
                        mpi = min(p,i);

                        % new CP due to degree elevation
                        for j = max(0,i-tp):mpi
                            ebpts(i+1,:) = ebpts(i+1,:) + bezalfs(i+1,j+1)*bpts(j+1,:);
                        end
                    end
                    % end of degree elevating Bezier

                    % knot removal ua oldr times
                    if oldr>1
                        first = kind-2;
                        last = kind;
                        den = ub-ua;
                        bet = (ub-Xir(kind))/den;

                        for tr = 1:oldr-1
                            i = first;
                            j = last;
                            kj = j-kind+1;

                            while j-i>tr
                                % loop and compute the new CP for one removal step
                                if i<cind
                                    alpha = (ub-Xir(i+1))/(ua-Xir(i+1));
                                    Qw(i+1,:) = (alpha*Qw(i+1,:)+(1.0-alpha)*Qw(i,:));
                                end
                                if j>=lbz
                                    if ((j-tr)<=(kind-pr+oldr))
                                        gam = (ub-Xir(j-tr+1))/den;
                                        ebpts(kj+1,:) = gam*ebpts(kj+1,:) + (1.0-gam)*ebpts(kj+2,:);
                                    else
                                        ebpts(kj+1,:) = bet*ebpts(kj+1,:) + (1.0-bet)*ebpts(kj+2,:);
                                    end
                                end
                                i = i + 1;
                                j = j - 1;
                                kj = kj - 1;
                            end

                            first = first - 1;
                            last = last + 1;
                        end
                    end
                    % end of removing knot, u=U(a)

                    % load the knot ua
                    if a~=p+1
                        for i = 0:(pr-oldr-1)
                            Xir(kind+1) = ua;
                            kind = kind+1;
                        end
                    end

                    % load CPs into Qw
                    for j = lbz:rbz
                        Qw(cind+1,:) =  ebpts(j+1,:);
                        cind = cind +1;
                    end

                    % setup for the next pass through loop
                    if b<mxi
                        for j = 0:r-1
                            bpts(j+1,:) = Nextbpts(j+1,:);
                        end
                        for j = r:p
                            bpts(j+1,:) = Pw(b-p+j,:);
                        end
                        a = b;
                        b = b+1;
                        ua = ub;
                        % end knots
                    else
                        for i = 0:pr
                            Xir(kind+i+1) = ub;
                        end
                    end
                end
                % end of big loop through knot vector
            elseif (tp==0)
                Xir = Xi;
                pr = p;
                Qw = Pw;
            end
            transpose(Qw(:, 4));
            %% 3. Retransform the projective Control Points from Qw to CPr
            app.changeProp.dim = app.propGeomCircle.dim;
            app.changeProp.knotVct = Xir;
            app.changeProp.polOrd = pr;
            app.changeProp.weights = transpose(Qw(:, 4));
            app.changeProp.controlPoints = transpose(Qw(:, 1:3))./app.changeProp.weights;

            app.controlPoints=app.changeProp.controlPoints;
            app.weigtArray= app.changeProp.weights;
            app.knotVector=app.changeProp.knotVct;
            app.dim=app.changeProp.dim;
            app.polyOrder=pr;
        end


        function bCoef = getBinomialCoefficients(app,a, b)

            if a<b
              	 error('Given pair (%d,%d) to compute the binomial coefficient is incorrect',a,b);
            end

            bCoefVector = [1
  	 	 	   1
               1
               1
               2
               1
               1
               3
               3
               1
               1
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               1];

            index = a*(a+1)/2 + b + 1;
            bCoef = bCoefVector(index,1);

        end


        function propGeom = performUniformKnotRefinementNURBSCurve(app,propGeom, numKnots)

            cla(app.UIAxes);
            knotsRef = linspace(propGeom.knotVct(1), propGeom.knotVct(end), numKnots + 2);
            knotsRef([1 end]) = [];
            eps = 1e-6;
            if any(knotsRef < min(propGeom.knotVct, [], "all") - eps)
                error("Knots [%1.1f] are smaller than the left most knot in the knot vector which is %1.1f", ...
                    knotsRef(knotsRef < min(propGeom.knotVct, [], "all")), min(propGeom.knotVct, [], "all"))
            end
            if any(knotsRef < min(propGeom.knotVct, [], "all")) && any(knotsRef >= min(propGeom.knotVct, [], "all") - eps)
                knotsRef(knotsRef < min(propGeom.knotVct, [], "all") && knotsRef >= min(propGeom.knotVct, [], "all") - eps) = ...
                    min(propGeom.knotVct, [], "all");
            end
            if any(knotsRef > max(propGeom.knotVct, [], "all") + eps)
                error("Knots [%1.1f] are greater than the right most knot in the knot vector which is %1.1f", ...
                    knotsRef(knotsRef > max(propGeom.knotVct, [], "all")), max(propGeom.knotVct, [], "all"))
            end
            if any(knotsRef > max(propGeom.knotVct, [], "all")) && any(knotsRef <= max(propGeom.knotVct, [], "all") + eps)
                knotsRef(knotsRef > max(propGeom.knotVct, [], "all") && knotsRef <= max(propGeom.knotVct, [], "all") + eps) = ...
                    max(propGeom.knotVct, [], "all");
            end
            controlPointsProj = propGeom.controlPoints.*propGeom.weights;
            numCPsOrg = size(propGeom.controlPoints, 1);
            spl = spmak(propGeom.knotVct, [controlPointsProj; propGeom.weights]);
            splRef = fnrfn(spl, sort(knotsRef));

            propGeom.knotVct = splRef.knots;
            propGeom.controlPoints = splRef.coefs(1:numCPsOrg, :)./splRef.coefs(numCPsOrg + 1:end, :);
            propGeom.weights = splRef.coefs(numCPsOrg + 1:end, :);

        end


        function CallPythonScript(app, filePath)
            % Check whether
            %Specify the full path to Anaconda Python executable
            anacondaPythonExecutable = 'C:\Users\oslon\anaconda3\python.exe';
            pythonScript = 'C:\Users\oslon\Desktop\MSc\Thesis\Code Scripts\Python Files\parse.py';
            outputFile = 'output.mat';
            %Check if Anaconda Python executable exists
            if exist(anacondaPythonExecutable, 'file') == 2
                %Build the command
                command = sprintf('%s "%s" "%s"', anacondaPythonExecutable, pythonScript, filePath);
                %Call Python script
                [status, ~] = system(command, '-echo');
                %result = jsondecode(output);
                if status == 0
                    data = load(outputFile);

                    dict_=data.dict_list;

                    app.dict_list=dict_;        % to store original geometry
                    app.change_dict_list=dict_;     % to store changed geometry
                    app.deg_dict_list=dict_;        % to store degree refined geometry

                    num_patches=numel(dict_);
                    app.dim=data.dimension;
                    app.num_patch=num_patches;
                    app.updateLabelDropDown();  % Dropdown should have as many labels as the number of patches
                    
                    if app.dim==1 & num_patches==1% Curve
                        app.polyOrder=double(dict_{1}.polDegree);
                        app.weigtArray=double(dict_{1}.weights);
                        app.controlPoints=dict_{1}.controlPoints;
                        app.knotVector=dict_{1}.knotVector;
                        app.propGeomCircle.controlPoints=app.controlPoints;
                        app.propGeomCircle.knotVct=app.knotVector;
                        app.propGeomCircle.weights=app.weigtArray;
                        app.propGeomCircle.polOrd=app.polyOrder;
                        app.changeProp=app.propGeomCircle;

                    % elseif app.dim==2 & num_patches==1 % Surface
                    %     app.polyOrder= [double(dict_{1}.vDeg);double(dict_{1}.uDeg)];
                    %     app.knotVector= {dict_{1}.vknotVector,dict_{1}.uknotVector};
                    %     app.weigtArray=dict_{1}.weights;
                    %     app.controlPoints=dict_{1}.controlPoints;
                    %     app.propGeomCircle.dim=app.dim;
                    %     app.propGeomCircle.knotVct=app.knotVector;
                    %     app.propGeomCircle.polOrd=app.polyOrder;
                    %     app.propGeomCircle.weights=app.weigtArray;
                    %     app.propGeomCircle.controlPoints=app.controlPoints;
                    %     app.changeProp=app.propGeomCircle;
                    %     app.propGeomTrim.isTrim=double(dict_{1}.isTrim);
                    %     if app.propGeomTrim.isTrim==1
                    %         app.propGeomTrim.poly=transpose(dict_{1}.TrimPolygon);
                    %     else
                    %         app.propGeomTrim.poly=[];
                    %     end

                    end
                else
                    disp('File Not Found');
                end
            else
                disp('Anaconda Python executable not found. Please check the path.');
            end

            disp('Python called Successfully')
            app.begin=0;
            app.updatePlot;
        end


        function [x, hasConverged] = solve_LinearSystemMatlabBackslashSolver ...
                (app,A, b, x)

            hasConverged = true;
            isWarningMessageEnabled = 'off';
            % isWarningMessageEnabled = 'on';

            %% 1. Solve the linear system with the matlab backslash operator
            x = A\b;

        end


        function homDOFs = findDofsForBernoulliBeams2D ...
                (app,homDOFs, xi, direction, CP)


            % counter
            r = length(homDOFs) + 1;

            % number of control points
            nxi = length(CP(:,1));

            %% 1. Iterate and add new DOFs into the array preserving the old ones
            for i = xi(1)*(nxi-1)+1:xi(2)*(nxi-1)+1
                homDOFs(r) = 2*(i-1) + direction;

                % Round to nearest integer
                homDOFs(r) = round(homDOFs(r));
                % Update counter
                r = r + 1;
            end

            % sort rb and delete double entries
            homDOFs = sort(homDOFs);

            % Initialize counter
            i = 1;

            %% 2. Loop over the DOF array and delete double entries
            while i < length(homDOFs)
                if homDOFs(i) == homDOFs(i+1)
                    homDOFs(i+1)=[];

                    % Decrease counter
                    i = i - 1;
                end
                % Increase counter
                i = i + 1;
            end

        end


        function homDOFs = findDofsForTimoshenkoBeams2D ...
                (app,homDOFs, xi, direction, CP)

            % counter
            r = length(homDOFs)+1;

            % number of control points
            nxi = length(CP(:,1));

            %% 1. Iterate and add new DOFs into the vector preserving the old ones
            for i = xi(1)*(nxi-1)+1:xi(2)*(nxi-1)+1
                homDOFs(r) = 3*(i-1) + direction;

                % Round to nearest integer
                homDOFs(r) = round(homDOFs(r));

                % Update counter
                r = r + 1;
            end

            % sort rb and delete double entries
            homDOFs = sort(homDOFs);

            % Initialize counter
            i = 1;

            %% 2. Loop over all elements of the DOF array and delete double entries
            while i < length(homDOFs)
                if homDOFs(i)==homDOFs(i+1)
                    homDOFs(i+1)=[];

                    % Decrease counter
                    i = i - 1;
                end
                % Update counter
                i = i + 1;
            end

        end


        function [F, tanMtxLoad] = computeLoadVctLinePressureVectorForIGABernoulliBeam2D ...
                (app,Fold, BSplinePatch, xib, etab, loadFnc, loadDir, isFollower, t, ...
                int, outMsg)

            % Get the computational information from the patch
            p = BSplinePatch.p;
            Xi = BSplinePatch.Xi;
            CP = BSplinePatch.CP;
            isNURBS = BSplinePatch.isNURBS;

            size(CP)
            disp('In load pressure Bernoulli')
            fprintf('the knot vector here is : %s \n', num2str(Xi))

            % Initialize the load vector
            F = zeros(size(CP, 1)*2, 1);

            % Initialize dummy output
            tanMtxLoad = 'undefined';

            % Number of Control Points
            nxi = length(CP(:, 1));

            % choose number of gauss points
            if strcmp(int.type,'default')
                noGP = ceil (0.5*(p + 1));
            elseif strcmp(int.type,'user')
                noGP = int.noGPLoad;
            end

            % Get start end end point for th integration
            j1 = app.findKnotSpan(xib(1), Xi, nxi);
            j2 = app.findKnotSpan(xib(2), Xi, nxi);

            % Get the Gauss Points and their coordinates
            [GP,GW] = app.getGaussPointsAndWeightsOverUnitDomain(noGP);

            %% 1. Loop over all elements
            for i = j1:j2
                % check if we are in a non-zero element
                if Xi(i + 1) - Xi(i) ~= 0
                    %% 1i. Loop over all the quadrature points
                    for j = 1:length(GP)
                        %% 1i.1. Transformation from unit interval to the knot span
                        xi = ((1 - GP(j))*Xi(i) + (1 + GP(j))*Xi(i + 1))/2;
                        xiSpan = app.findKnotSpan(xi, Xi, nxi);

                        %% 1i.2. Compute the NURBS basis functions and their derivatives
                        numDrv = 2;
                        dR = app.computeNURBSBasisFunctions1d...
                            (Xi,p, xi, Xi, transpose(CP(:,4)),numDrv);

                        %% 1i.3. Compute the base vectors on the curve
                        [A1, A2] = app.computeBaseVectorAndNormalToNURBSCurve2D ...
                            (xiSpan, p, CP, dR);

                        %% 1i.4. Compute the length of the normal vector and the normal to the 2D curve vector
                        L = norm(A1);
                        n = A2/norm(A2);
                        t = A1/L;

                        %% 1i.5. Compute and assemble the components of the global load vector
                        for v = 1:p + 1
                            index1 = 1 + (xiSpan - p - 1)*2 + (v - 1)*2;
                            index2 = 2 + (xiSpan - p - 1)*2 + (v - 1)*2;
                            if loadDir == 1
                                F(index1, 1) = F(index1, 1) + ...
                                    loadFnc*dR(v, 1)*t(1, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                                F(index2, 1) = F(index2, 1) + ...
                                    loadFnc*dR(v, 1)*t(2, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                            elseif loadDir == 2
                                F(index1, 1) = F(index1, 1) + ...
                                    loadFnc*dR(v, 1)*n(1, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                                F(index2, 1) = F(index2, 1) + ...
                                    loadFnc*dR(v, 1)*n(2, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                            else
                                error('Direction %d is not implemented for the loading of a 2D isogeometric Timoshenko beam', loadDir);
                            end
                        end
                    end
                end
            end

            %% 2. If the given old load vector is not null, sum them up with the newly computed one
            if isvector(Fold)
                F = F + Fold;
            end


        end


        function [GP, GW] = getGaussPointsAndWeightsOverUnitDomain(app,n)


            if (n==0)
                GP = 0; GW = 0;
            elseif(n==1)
                GP(1) = -0.000000000000000e-01;   GW(1) =  2.000000000000000e+00;

            elseif(n==2)
                GP(1) =  5.773502691896258e-01;   GW(1) =  1.000000000000000e+00;
                GP(2) = -5.773502691896258e-01;   GW(2) =  1.000000000000000e+00;

            elseif(n==3)
                GP(1) =  7.745966692414834e-01;   GW(1) =  5.555555555555556e-01;
                GP(2) = -0.000000000000000e-01;   GW(2) =  8.888888888888889e-01;
                GP(3) = -7.745966692414834e-01;   GW(3) =  5.555555555555556e-01;

            elseif(n==4)
                GP( 1) =  8.611363115940526e-01;   GW( 1) =  3.478548451374539e-01;
                GP( 2) =  3.399810435848563e-01;   GW( 2) =  6.521451548625461e-01;
                GP( 3) = -3.399810435848563e-01;   GW( 3) =  6.521451548625461e-01;
                GP( 4) = -8.611363115940526e-01;   GW( 4) =  3.478548451374539e-01;

            elseif(n==5)
                GP( 1) =  9.061798459386640e-01;   GW( 1) =  2.369268850561891e-01;
                GP( 2) =  5.384693101056831e-01;   GW( 2) =  4.786286704993665e-01;
                GP( 3) = -0.000000000000000e-01;   GW( 3) =  5.688888888888889e-01;
                GP( 4) = -5.384693101056831e-01;   GW( 4) =  4.786286704993665e-01;
                GP( 5) = -9.061798459386640e-01;   GW( 5) =  2.369268850561891e-01;

            elseif(n==6)
                GP( 1) =  9.324695142031520e-01;   GW( 1) =  1.713244923791703e-01;
                GP( 2) =  6.612093864662645e-01;   GW( 2) =  3.607615730481386e-01;
                GP( 3) =  2.386191860831969e-01;   GW( 3) =  4.679139345726910e-01;
                GP( 4) = -2.386191860831969e-01;   GW( 4) =  4.679139345726910e-01;
                GP( 5) = -6.612093864662645e-01;   GW( 5) =  3.607615730481386e-01;
                GP( 6) = -9.324695142031520e-01;   GW( 6) =  1.713244923791703e-01;

            elseif(n==7)
                GP( 1) =  9.491079123427585e-01;   GW( 1) =  1.294849661688697e-01;
                GP( 2) =  7.415311855993944e-01;   GW( 2) =  2.797053914892767e-01;
                GP( 3) =  4.058451513773972e-01;   GW( 3) =  3.818300505051189e-01;
                GP( 4) = -0.000000000000000e-01;   GW( 4) =  4.179591836734694e-01;
                GP( 5) = -4.058451513773972e-01;   GW( 5) =  3.818300505051189e-01;
                GP( 6) = -7.415311855993944e-01;   GW( 6) =  2.797053914892767e-01;
                GP( 7) = -9.491079123427585e-01;   GW( 7) =  1.294849661688697e-01;

            elseif(n==8)
                GP( 1) =  9.602898564975362e-01;   GW( 1)=  1.012285362903763e-01;
                GP( 2) =  7.966664774136267e-01;   GW( 2)=  2.223810344533745e-01;
                GP( 3) =  5.255324099163290e-01;   GW( 3)=  3.137066458778873e-01;
                GP( 4) =  1.834346424956498e-01;   GW( 4)=  3.626837833783620e-01;
                GP( 5) = -1.834346424956498e-01;   GW( 5)=  3.626837833783620e-01;
                GP( 6) = -5.255324099163290e-01;   GW( 6)=  3.137066458778873e-01;
                GP( 7) = -7.966664774136267e-01;   GW( 7)=  2.223810344533745e-01;
                GP( 8) = -9.602898564975362e-01;   GW( 8)=  1.012285362903763e-01;

            elseif(n==9)
                GP( 1) =  9.681602395076261e-01;   GW( 1) =  8.127438836157441e-02;
                GP( 2) =  8.360311073266358e-01;   GW( 2) =  1.806481606948574e-01;
                GP( 3) =  6.133714327005904e-01;   GW( 3) =  2.606106964029355e-01;
                GP( 4) =  3.242534234038089e-01;   GW( 4) =  3.123470770400028e-01;
                GP( 5) = -0.000000000000000e-01;   GW( 5) =  3.302393550012598e-01;
                GP( 6) = -3.242534234038089e-01;   GW( 6) =  3.123470770400028e-01;
                GP( 7) = -6.133714327005904e-01;   GW( 7) =  2.606106964029355e-01;
                GP( 8) = -8.360311073266358e-01;   GW( 8) =  1.806481606948574e-01;
                GP( 9) = -9.681602395076261e-01;   GW( 9) =  8.127438836157441e-02;

            elseif(n==10)
                GP( 1) =  9.739065285171717e-01;   GW( 1) =  6.667134430868814e-02;
                GP( 2) =  8.650633666889845e-01;   GW( 2) =  1.494513491505806e-01;
                GP( 3) =  6.794095682990244e-01;   GW( 3) =  2.190863625159820e-01;
                GP( 4) =  4.333953941292472e-01;   GW( 4) =  2.692667193099964e-01;
                GP( 5) =  1.488743389816312e-01;   GW( 5) =  2.955242247147529e-01;
                GP( 6) = -1.488743389816312e-01;   GW( 6) =  2.955242247147529e-01;
                GP( 7) = -4.333953941292472e-01;   GW( 7) =  2.692667193099964e-01;
                GP( 8) = -6.794095682990244e-01;   GW( 8) =  2.190863625159820e-01;
                GP( 9) = -8.650633666889845e-01;   GW( 9) =  1.494513491505806e-01;
                GP(10) = -9.739065285171717e-01;   GW(10) =  6.667134430868814e-02;

            elseif(n==11)
                GP( 1) =  9.782286581460570e-01;   GW( 1) =  5.566856711617367e-02;
                GP( 2) =  8.870625997680953e-01;   GW( 2) =  1.255803694649046e-01;
                GP( 3) =  7.301520055740493e-01;   GW( 3) =  1.862902109277343e-01;
                GP( 4) =  5.190961292068118e-01;   GW( 4) =  2.331937645919905e-01;
                GP( 5) =  2.695431559523450e-01;   GW( 5) =  2.628045445102467e-01;
                GP( 6) = -0.000000000000000e-01;   GW( 6) =  2.729250867779006e-01;
                GP( 7) = -2.695431559523450e-01;   GW( 7) =  2.628045445102467e-01;
                GP( 8) = -5.190961292068118e-01;   GW( 8) =  2.331937645919905e-01;
                GP( 9) = -7.301520055740493e-01;   GW( 9) =  1.862902109277343e-01;
                GP(10) = -8.870625997680953e-01;   GW(10) =  1.255803694649046e-01;
                GP(11) = -9.782286581460570e-01;   GW(11) =  5.566856711617367e-02;

            elseif(n==12)
                GP( 1) =  9.815606342467193e-01;   GW( 1) =  4.717533638651183e-02;
                GP( 2) =  9.041172563704749e-01;   GW( 2) =  1.069393259953184e-01;
                GP( 3) =  7.699026741943047e-01;   GW( 3) =  1.600783285433462e-01;
                GP( 4) =  5.873179542866174e-01;   GW( 4) =  2.031674267230659e-01;
                GP( 5) =  3.678314989981802e-01;   GW( 5) =  2.334925365383548e-01;
                GP( 6) =  1.252334085114689e-01;   GW( 6) =  2.491470458134028e-01;
                GP( 7) = -1.252334085114689e-01;   GW( 7) =  2.491470458134028e-01;
                GP( 8) = -3.678314989981802e-01;   GW( 8) =  2.334925365383548e-01;
                GP( 9) = -5.873179542866174e-01;   GW( 9) =  2.031674267230659e-01;
                GP(10) = -7.699026741943047e-01;   GW(10) =  1.600783285433462e-01;
                GP(11) = -9.041172563704749e-01;   GW(11) =  1.069393259953184e-01;
                GP(12) = -9.815606342467193e-01;   GW(12) =  4.717533638651183e-02;

            elseif(n==13)
                GP( 1) =  9.841830547185881e-01;   GW( 1) =  4.048400476531588e-02;
                GP( 2) =  9.175983992229780e-01;   GW( 2) =  9.212149983772845e-02;
                GP( 3) =  8.015780907333099e-01;   GW( 3) =  1.388735102197872e-01;
                GP( 4) =  6.423493394403402e-01;   GW( 4) =  1.781459807619457e-01;
                GP( 5) =  4.484927510364469e-01;   GW( 5) =  2.078160475368885e-01;
                GP( 6) =  2.304583159551348e-01;   GW( 6) =  2.262831802628972e-01;
                GP( 7) = -0.000000000000000e-01;   GW( 7) =  2.325515532308739e-01;
                GP( 8) = -2.304583159551348e-01;   GW( 8) =  2.262831802628972e-01;
                GP( 9) = -4.484927510364469e-01;   GW( 9) =  2.078160475368885e-01;
                GP(10) = -6.423493394403402e-01;   GW(10) =  1.781459807619457e-01;
                GP(11) = -8.015780907333099e-01;   GW(11) =  1.388735102197872e-01;
                GP(12) = -9.175983992229780e-01;   GW(12) =  9.212149983772845e-02;
                GP(13) = -9.841830547185881e-01;   GW(13) =  4.048400476531588e-02;

            elseif(n==14)
                GP( 1) =  9.862838086968123e-01;   GW( 1) =  3.511946033175186e-02;
                GP( 2) =  9.284348836635735e-01;   GW( 2) =  8.015808715976021e-02;
                GP( 3) =  8.272013150697650e-01;   GW( 3) =  1.215185706879032e-01;
                GP( 4) =  6.872929048116855e-01;   GW( 4) =  1.572031671581935e-01;
                GP( 5) =  5.152486363581541e-01;   GW( 5) =  1.855383974779378e-01;
                GP( 6) =  3.191123689278898e-01;   GW( 6) =  2.051984637212956e-01;
                GP( 7) =  1.080549487073437e-01;   GW( 7) =  2.152638534631578e-01;
                GP( 8) = -1.080549487073437e-01;   GW( 8) =  2.152638534631578e-01;
                GP( 9) = -3.191123689278898e-01;   GW( 9) =  2.051984637212956e-01;
                GP(10) = -5.152486363581541e-01;   GW(10) =  1.855383974779378e-01;
                GP(11) = -6.872929048116855e-01;   GW(11) =  1.572031671581935e-01;
                GP(12) = -8.272013150697650e-01;   GW(12) =  1.215185706879032e-01;
                GP(13) = -9.284348836635735e-01;   GW(13) =  8.015808715976021e-02;
                GP(14) = -9.862838086968123e-01;   GW(14) =  3.511946033175186e-02;

            elseif(n==15)
                GP( 1) =  9.879925180204854e-01;   GW( 1) =  3.075324199611727e-02;
                GP( 2) =  9.372733924007059e-01;   GW( 2) =  7.036604748810812e-02;
                GP( 3) =  8.482065834104272e-01;   GW( 3) =  1.071592204671719e-01;
                GP( 4) =  7.244177313601700e-01;   GW( 4) =  1.395706779261543e-01;
                GP( 5) =  5.709721726085388e-01;   GW( 5) =  1.662692058169939e-01;
                GP( 6) =  3.941513470775634e-01;   GW( 6) =  1.861610000155622e-01;
                GP( 7) =  2.011940939974345e-01;   GW( 7) =  1.984314853271116e-01;
                GP( 8) = -0.000000000000000e-01;   GW( 8) =  2.025782419255613e-01;
                GP( 9) = -2.011940939974345e-01;   GW( 9) =  1.984314853271116e-01;
                GP(10) = -3.941513470775634e-01;   GW(10) =  1.861610000155622e-01;
                GP(11) = -5.709721726085388e-01;   GW(11) =  1.662692058169939e-01;
                GP(12) = -7.244177313601700e-01;   GW(12) =  1.395706779261543e-01;
                GP(13) = -8.482065834104272e-01;   GW(13) =  1.071592204671719e-01;
                GP(14) = -9.372733924007059e-01;   GW(14) =  7.036604748810812e-02;
                GP(15) = -9.879925180204854e-01;   GW(15) =  3.075324199611727e-02;

            elseif(n==16)
                GP( 1) =  9.894009349916499e-01;   GW( 1) =  2.715245941175409e-02;
                GP( 2) =  9.445750230732326e-01;   GW( 2) =  6.225352393864789e-02;
                GP( 3) =  8.656312023878317e-01;   GW( 3) =  9.515851168249278e-02;
                GP( 4) =  7.554044083550030e-01;   GW( 4) =  1.246289712555339e-01;
                GP( 5) =  6.178762444026437e-01;   GW( 5) =  1.495959888165767e-01;
                GP( 6) =  4.580167776572274e-01;   GW( 6) =  1.691565193950025e-01;
                GP( 7) =  2.816035507792589e-01;   GW( 7) =  1.826034150449236e-01;
                GP( 8) =  9.501250983763744e-02;   GW( 8) =  1.894506104550685e-01;
                GP( 9) = -9.501250983763744e-02;   GW( 9) =  1.894506104550685e-01;
                GP(10) = -2.816035507792589e-01;   GW(10) =  1.826034150449236e-01;
                GP(11) = -4.580167776572274e-01;   GW(11) =  1.691565193950025e-01;
                GP(12) = -6.178762444026437e-01;   GW(12) =  1.495959888165767e-01;
                GP(13) = -7.554044083550030e-01;   GW(13) =  1.246289712555339e-01;
                GP(14) = -8.656312023878317e-01;   GW(14) =  9.515851168249278e-02;
                GP(15) = -9.445750230732326e-01;   GW(15) =  6.225352393864789e-02;
                GP(16) = -9.894009349916499e-01;   GW(16) =  2.715245941175409e-02;

            elseif(n==17)
                GP( 1)  =  9.905754753144173e-01;   GW( 1)  =  2.414830286854793e-02;
                GP( 2)  =  9.506755217687678e-01;   GW( 2)  =  5.545952937398720e-02;
                GP( 3)  =  8.802391537269859e-01;   GW( 3)  =  8.503614831717918e-02;
                GP( 4)  =  7.815140038968014e-01;   GW( 4)  =  1.118838471934040e-01;
                GP( 5)  =  6.576711592166908e-01;   GW( 5)  =  1.351363684685255e-01;
                GP( 6)  =  5.126905370864770e-01;   GW( 6)  =  1.540457610768103e-01;
                GP( 7)  =  3.512317634538763e-01;   GW( 7)  =  1.680041021564500e-01;
                GP( 8)  =  1.784841814958479e-01;   GW( 8)  =  1.765627053669926e-01;
                GP( 9)  = -0.000000000000000e-01;   GW( 9)  =  1.794464703562065e-01;
                GP(10)  = -1.784841814958479e-01;   GW(10)  =  1.765627053669926e-01;
                GP(11)  = -3.512317634538763e-01;   GW(11)  =  1.680041021564500e-01;
                GP(12)  = -5.126905370864770e-01;   GW(12)  =  1.540457610768103e-01;
                GP(13)  = -6.576711592166908e-01;   GW(13)  =  1.351363684685255e-01;
                GP(14)  = -7.815140038968014e-01;   GW(14)  =  1.118838471934040e-01;
                GP(15)  = -8.802391537269859e-01;   GW(15)  =  8.503614831717918e-02;
                GP(16)  = -9.506755217687678e-01;   GW(16)  =  5.545952937398720e-02;
                GP(17)  = -9.905754753144173e-01;   GW(17)  =  2.414830286854793e-02;

            elseif(n==18)
                GP( 1)  =  9.915651684209309e-01;   GW( 1)  =  2.161601352648331e-02;
                GP( 2)  =  9.558239495713978e-01;   GW( 2)  =  4.971454889496980e-02;
                GP( 3)  =  8.926024664975557e-01;   GW( 3)  =  7.642573025488906e-02;
                GP( 4)  =  8.037049589725231e-01;   GW( 4)  =  1.009420441062872e-01;
                GP( 5)  =  6.916870430603532e-01;   GW( 5)  =  1.225552067114785e-01;
                GP( 6)  =  5.597708310739475e-01;   GW( 6)  =  1.406429146706507e-01;
                GP( 7)  =  4.117511614628426e-01;   GW( 7)  =  1.546846751262652e-01;
                GP( 8)  =  2.518862256915055e-01;   GW( 8)  =  1.642764837458327e-01;
                GP( 9)  =  8.477501304173530e-02;   GW( 9)  =  1.691423829631436e-01;
                GP(10)  = -8.477501304173530e-02;   GW(10)  =  1.691423829631436e-01;
                GP(11)  = -2.518862256915055e-01;   GW(11)  =  1.642764837458327e-01;
                GP(12)  = -4.117511614628426e-01;   GW(12)  =  1.546846751262652e-01;
                GP(13)  = -5.597708310739475e-01;   GW(13)  =  1.406429146706507e-01;
                GP(14)  = -6.916870430603532e-01;   GW(14)  =  1.225552067114785e-01;
                GP(15)  = -8.037049589725231e-01;   GW(15)  =  1.009420441062872e-01;
                GP(16)  = -8.926024664975557e-01;   GW(16)  =  7.642573025488906e-02;
                GP(17)  = -9.558239495713978e-01;   GW(17)  =  4.971454889496980e-02;
                GP(18)  = -9.915651684209309e-01;   GW(18)  =  2.161601352648331e-02;

            elseif(n==19)
                GP( 1)  =  9.924068438435844e-01;   GW( 1)  =  1.946178822972648e-02;
                GP( 2)  =  9.602081521348300e-01;   GW( 2)  =  4.481422676569960e-02;
                GP( 3)  =  9.031559036148179e-01;   GW( 3)  =  6.904454273764123e-02;
                GP( 4)  =  8.227146565371428e-01;   GW( 4)  =  9.149002162245000e-02;
                GP( 5)  =  7.209661773352294e-01;   GW( 5)  =  1.115666455473340e-01;
                GP( 6)  =  6.005453046616810e-01;   GW( 6)  =  1.287539625393362e-01;
                GP( 7)  =  4.645707413759609e-01;   GW( 7)  =  1.426067021736066e-01;
                GP( 8)  =  3.165640999636298e-01;   GW( 8)  =  1.527660420658597e-01;
                GP( 9)  =  1.603586456402254e-01;   GW( 9)  =  1.589688433939543e-01;
                GP(10)  = -0.000000000000000e-01;   GW(10)  =  1.610544498487837e-01;
                GP(11)  = -1.603586456402254e-01;   GW(11)  =  1.589688433939543e-01;
                GP(12)  = -3.165640999636298e-01;   GW(12)  =  1.527660420658597e-01;
                GP(13)  = -4.645707413759609e-01;   GW(13)  =  1.426067021736066e-01;
                GP(14)  = -6.005453046616810e-01;   GW(14)  =  1.287539625393362e-01;
                GP(15)  = -7.209661773352294e-01;   GW(15)  =  1.115666455473340e-01;
                GP(16)  = -8.227146565371428e-01;   GW(16)  =  9.149002162245000e-02;
                GP(17)  = -9.031559036148179e-01;   GW(17)  =  6.904454273764123e-02;
                GP(18)  = -9.602081521348300e-01;   GW(18)  =  4.481422676569960e-02;
                GP(19)  = -9.924068438435844e-01;   GW(19)  =  1.946178822972648e-02;

            elseif(n==20)
                GP( 1)  =  9.931285991850949e-01;   GW( 1)  =  1.761400713915212e-02;
                GP( 2)  =  9.639719272779138e-01;   GW( 2)  =  4.060142980038694e-02;
                GP( 3)  =  9.122344282513259e-01;   GW( 3)  =  6.267204833410906e-02;
                GP( 4)  =  8.391169718222188e-01;   GW( 4)  =  8.327674157670475e-02;
                GP( 5)  =  7.463319064601508e-01;   GW( 5)  =  1.019301198172404e-01;
                GP( 6)  =  6.360536807265150e-01;   GW( 6)  =  1.181945319615184e-01;
                GP( 7)  =  5.108670019508271e-01;   GW( 7)  =  1.316886384491766e-01;
                GP( 8)  =  3.737060887154196e-01;   GW( 8)  =  1.420961093183821e-01;
                GP( 9)  =  2.277858511416451e-01;   GW( 9)  =  1.491729864726037e-01;
                GP(10)  =  7.652652113349733e-02;   GW(10)  =  1.527533871307259e-01;
                GP(11)  = -7.652652113349733e-02;   GW(11)  =  1.527533871307259e-01;
                GP(12)  = -2.277858511416451e-01;   GW(12)  =  1.491729864726037e-01;
                GP(13)  = -3.737060887154196e-01;   GW(13)  =  1.420961093183821e-01;
                GP(14)  = -5.108670019508271e-01;   GW(14)  =  1.316886384491766e-01;
                GP(15)  = -6.360536807265150e-01;   GW(15)  =  1.181945319615184e-01;
                GP(16)  = -7.463319064601508e-01;   GW(16)  =  1.019301198172404e-01;
                GP(17)  = -8.391169718222188e-01;   GW(17)  =  8.327674157670475e-02;
                GP(18)  = -9.122344282513259e-01;   GW(18)  =  6.267204833410906e-02;
                GP(19)  = -9.639719272779138e-01;   GW(19)  =  4.060142980038694e-02;
                GP(20)  = -9.931285991850949e-01;   GW(20)  =  1.761400713915212e-02;

            elseif(n==21)
                GP( 1)  =  9.937521706203895e-01;   GW( 1)  =  1.601722825777433e-02;
                GP( 2)  =  9.672268385663063e-01;   GW( 2)  =  3.695378977085249e-02;
                GP( 3)  =  9.200993341504008e-01;   GW( 3)  =  5.713442542685721e-02;
                GP( 4)  =  8.533633645833173e-01;   GW( 4)  =  7.610011362837930e-02;
                GP( 5)  =  7.684399634756779e-01;   GW( 5)  =  9.344442345603386e-02;
                GP( 6)  =  6.671388041974123e-01;   GW( 6)  =  1.087972991671484e-01;
                GP( 7)  =  5.516188358872198e-01;   GW( 7)  =  1.218314160537285e-01;
                GP( 8)  =  4.243421202074388e-01;   GW( 8)  =  1.322689386333375e-01;
                GP( 9)  =  2.880213168024011e-01;   GW( 9)  =  1.398873947910732e-01;
                GP(10)  =  1.455618541608951e-01;   GW(10)  =  1.445244039899701e-01;
                GP(11)  = -0.000000000000000e-01;   GW(11)  =  1.460811336496904e-01;
                GP(12)  = -1.455618541608951e-01;   GW(12)  =  1.445244039899701e-01;
                GP(13)  = -2.880213168024011e-01;   GW(13)  =  1.398873947910732e-01;
                GP(14)  = -4.243421202074388e-01;   GW(14)  =  1.322689386333375e-01;
                GP(15)  = -5.516188358872198e-01;   GW(15)  =  1.218314160537285e-01;
                GP(16)  = -6.671388041974123e-01;   GW(16)  =  1.087972991671484e-01;
                GP(17)  = -7.684399634756779e-01;   GW(17)  =  9.344442345603386e-02;
                GP(18)  = -8.533633645833173e-01;   GW(18)  =  7.610011362837930e-02;
                GP(19)  = -9.200993341504008e-01;   GW(19)  =  5.713442542685721e-02;
                GP(20)  = -9.672268385663063e-01;   GW(20)  =  3.695378977085249e-02;
                GP(21)  = -9.937521706203895e-01;   GW(21)  =  1.601722825777433e-02;

            elseif(n==22)
                GP( 1)  =  9.942945854823993e-01;   GW( 1)  =  1.462799529827220e-02;
                GP( 2)  =  9.700604978354287e-01;   GW( 2)  =  3.377490158481415e-02;
                GP( 3)  =  9.269567721871740e-01;   GW( 3)  =  5.229333515268329e-02;
                GP( 4)  =  8.658125777203001e-01;   GW( 4)  =  6.979646842452049e-02;
                GP( 5)  =  7.878168059792082e-01;   GW( 5)  =  8.594160621706773e-02;
                GP( 6)  =  6.944872631866828e-01;   GW( 6)  =  1.004141444428810e-01;
                GP( 7)  =  5.876404035069116e-01;   GW( 7)  =  1.129322960805392e-01;
                GP( 8)  =  4.693558379867570e-01;   GW( 8)  =  1.232523768105124e-01;
                GP( 9)  =  3.419358208920842e-01;   GW( 9)  =  1.311735047870624e-01;
                GP(10)  =  2.078604266882213e-01;   GW(10)  =  1.365414983460152e-01;
                GP(11)  =  6.973927331972222e-02;   GW(11)  =  1.392518728556320e-01;
                GP(12)  = -6.973927331972222e-02;   GW(12)  =  1.392518728556320e-01;
                GP(13)  = -2.078604266882213e-01;   GW(13)  =  1.365414983460152e-01;
                GP(14)  = -3.419358208920842e-01;   GW(14)  =  1.311735047870624e-01;
                GP(15)  = -4.693558379867570e-01;   GW(15)  =  1.232523768105124e-01;
                GP(16)  = -5.876404035069116e-01;   GW(16)  =  1.129322960805392e-01;
                GP(17)  = -6.944872631866828e-01;   GW(17)  =  1.004141444428810e-01;
                GP(18)  = -7.878168059792082e-01;   GW(18)  =  8.594160621706773e-02;
                GP(19)  = -8.658125777203001e-01;   GW(19)  =  6.979646842452049e-02;
                GP(20)  = -9.269567721871740e-01;   GW(20)  =  5.229333515268329e-02;
                GP(21)  = -9.700604978354287e-01;   GW(21)  =  3.377490158481415e-02;
                GP(22)  = -9.942945854823993e-01;   GW(22)  =  1.462799529827220e-02;

            elseif(n==23)
                GP( 1)  =  9.947693349975521e-01;   GW( 1)  =  1.341185948714177e-02;
                GP( 2)  =  9.725424712181152e-01;   GW( 2)  =  3.098800585697944e-02;
                GP( 3)  =  9.329710868260161e-01;   GW( 3)  =  4.803767173108467e-02;
                GP( 4)  =  8.767523582704417e-01;   GW( 4)  =  6.423242140852585e-02;
                GP( 5)  =  8.048884016188399e-01;   GW( 5)  =  7.928141177671895e-02;
                GP( 6)  =  7.186613631319502e-01;   GW( 6)  =  9.291576606003515e-02;
                GP( 7)  =  6.196098757636462e-01;   GW( 7)  =  1.048920914645414e-01;
                GP( 8)  =  5.095014778460075e-01;   GW( 8)  =  1.149966402224114e-01;
                GP( 9)  =  3.903010380302908e-01;   GW( 9)  =  1.230490843067295e-01;
                GP(10)  =  2.641356809703449e-01;   GW(10)  =  1.289057221880821e-01;
                GP(11)  =  1.332568242984661e-01;   GW(11)  =  1.324620394046966e-01;
                GP(12)  = -0.000000000000000e-01;   GW(12)  =  1.336545721861062e-01;
                GP(13)  = -1.332568242984661e-01;   GW(13)  =  1.324620394046966e-01;
                GP(14)  = -2.641356809703449e-01;   GW(14)  =  1.289057221880821e-01;
                GP(15)  = -3.903010380302908e-01;   GW(15)  =  1.230490843067295e-01;
                GP(16)  = -5.095014778460075e-01;   GW(16)  =  1.149966402224114e-01;
                GP(17)  = -6.196098757636462e-01;   GW(17)  =  1.048920914645414e-01;
                GP(18)  = -7.186613631319502e-01;   GW(18)  =  9.291576606003515e-02;
                GP(19)  = -8.048884016188399e-01;   GW(19)  =  7.928141177671895e-02;
                GP(20)  = -8.767523582704417e-01;   GW(20)  =  6.423242140852585e-02;
                GP(21)  = -9.329710868260161e-01;   GW(21)  =  4.803767173108467e-02;
                GP(22)  = -9.725424712181152e-01;   GW(22)  =  3.098800585697944e-02;
                GP(23)  = -9.947693349975521e-01;   GW(23)  =  1.341185948714177e-02;

            elseif(n==24)
                GP( 1)  =  9.951872199970214e-01;   GW( 1)  =  1.234122979998720e-02;
                GP( 2)  =  9.747285559713095e-01;   GW( 2)  =  2.853138862893366e-02;
                GP( 3)  =  9.382745520027328e-01;   GW( 3)  =  4.427743881741981e-02;
                GP( 4)  =  8.864155270044010e-01;   GW( 4)  =  5.929858491543678e-02;
                GP( 5)  =  8.200019859739029e-01;   GW( 5)  =  7.334648141108031e-02;
                GP( 6)  =  7.401241915785544e-01;   GW( 6)  =  8.619016153195328e-02;
                GP( 7)  =  6.480936519369756e-01;   GW( 7)  =  9.761865210411389e-02;
                GP( 8)  =  5.454214713888395e-01;   GW( 8)  =  1.074442701159656e-01;
                GP( 9)  =  4.337935076260451e-01;   GW( 9)  =  1.155056680537256e-01;
                GP(10)  =  3.150426796961634e-01;   GW(10)  =  1.216704729278034e-01;
                GP(11)  =  1.911188674736163e-01;   GW(11)  =  1.258374563468283e-01;
                GP(12)  =  6.405689286260563e-02;   GW(12)  =  1.279381953467522e-01;
                GP(13)  = -6.405689286260563e-02;   GW(13)  =  1.279381953467522e-01;
                GP(14)  = -1.911188674736163e-01;   GW(14)  =  1.258374563468283e-01;
                GP(15)  = -3.150426796961634e-01;   GW(15)  =  1.216704729278034e-01;
                GP(16)  = -4.337935076260451e-01;   GW(16)  =  1.155056680537256e-01;
                GP(17)  = -5.454214713888395e-01;   GW(17)  =  1.074442701159656e-01;
                GP(18)  = -6.480936519369756e-01;   GW(18)  =  9.761865210411389e-02;
                GP(19)  = -7.401241915785544e-01;   GW(19)  =  8.619016153195328e-02;
                GP(20)  = -8.200019859739029e-01;   GW(20)  =  7.334648141108031e-02;
                GP(21)  = -8.864155270044010e-01;   GW(21)  =  5.929858491543678e-02;
                GP(22)  = -9.382745520027328e-01;   GW(22)  =  4.427743881741981e-02;
                GP(23)  = -9.747285559713095e-01;   GW(23)  =  2.853138862893366e-02;
                GP(24)  = -9.951872199970214e-01;   GW(24)  =  1.234122979998720e-02;

            elseif(n==25)
                GP( 1)  =  9.955569697904981e-01;   GW( 1)  =  1.139379850102629e-02;
                GP( 2)  =  9.766639214595175e-01;   GW( 2)  =  2.635498661503214e-02;
                GP( 3)  =  9.429745712289743e-01;   GW( 3)  =  4.093915670130631e-02;
                GP( 4)  =  8.949919978782754e-01;   GW( 4)  =  5.490469597583519e-02;
                GP( 5)  =  8.334426287608340e-01;   GW( 5)  =  6.803833381235692e-02;
                GP( 6)  =  7.592592630373576e-01;   GW( 6)  =  8.014070033500102e-02;
                GP( 7)  =  6.735663684734684e-01;   GW( 7)  =  9.102826198296365e-02;
                GP( 8)  =  5.776629302412230e-01;   GW( 8)  =  1.005359490670506e-01;
                GP( 9)  =  4.730027314457150e-01;   GW( 9)  =  1.085196244742637e-01;
                GP(10)  =  3.611723058093878e-01;   GW(10)  =  1.148582591457116e-01;
                GP(11)  =  2.438668837209884e-01;   GW(11)  =  1.194557635357848e-01;
                GP(12)  =  1.228646926107104e-01;   GW(12)  =  1.222424429903100e-01;
                GP(13)  = -0.000000000000000e-01;   GW(13)  =  1.231760537267155e-01;
                GP(14)  = -1.228646926107104e-01;   GW(14)  =  1.222424429903100e-01;
                GP(15)  = -2.438668837209884e-01;   GW(15)  =  1.194557635357848e-01;
                GP(16)  = -3.611723058093878e-01;   GW(16)  =  1.148582591457116e-01;
                GP(17)  = -4.730027314457150e-01;   GW(17)  =  1.085196244742637e-01;
                GP(18)  = -5.776629302412230e-01;   GW(18)  =  1.005359490670506e-01;
                GP(19)  = -6.735663684734684e-01;   GW(19)  =  9.102826198296365e-02;
                GP(20)  = -7.592592630373576e-01;   GW(20)  =  8.014070033500102e-02;
                GP(21)  = -8.334426287608340e-01;   GW(21)  =  6.803833381235692e-02;
                GP(22)  = -8.949919978782754e-01;   GW(22)  =  5.490469597583519e-02;
                GP(23)  = -9.429745712289743e-01;   GW(23)  =  4.093915670130631e-02;
                GP(24)  = -9.766639214595175e-01;   GW(24)  =  2.635498661503214e-02;
                GP(25)  = -9.955569697904981e-01;   GW(25)  =  1.139379850102629e-02;

            elseif(n==26)
                GP( 1)  =  9.958857011456169e-01;   GW( 1)  =  1.055137261734301e-02;
                GP( 2)  =  9.783854459564710e-01;   GW( 2)  =  2.441785109263191e-02;
                GP( 3)  =  9.471590666617143e-01;   GW( 3)  =  3.796238329436276e-02;
                GP( 4)  =  9.026378619843071e-01;   GW( 4)  =  5.097582529714781e-02;
                GP( 5)  =  8.454459427884980e-01;   GW( 5)  =  6.327404632957484e-02;
                GP( 6)  =  7.763859488206789e-01;   GW( 6)  =  7.468414976565975e-02;
                GP( 7)  =  6.964272604199573e-01;   GW( 7)  =  8.504589431348524e-02;
                GP( 8)  =  6.066922930176181e-01;   GW( 8)  =  9.421380035591415e-02;
                GP( 9)  =  5.084407148245057e-01;   GW( 9)  =  1.020591610944254e-01;
                GP(10)  =  4.030517551234863e-01;   GW(10)  =  1.084718405285766e-01;
                GP(11)  =  2.920048394859569e-01;   GW(11)  =  1.133618165463197e-01;
                GP(12)  =  1.768588203568902e-01;   GW(12)  =  1.166604434852966e-01;
                GP(13)  =  5.923009342931321e-02;   GW(13)  =  1.183214152792623e-01;
                GP(14)  = -5.923009342931321e-02;   GW(14)  =  1.183214152792623e-01;
                GP(15)  = -1.768588203568902e-01;   GW(15)  =  1.166604434852966e-01;
                GP(16)  = -2.920048394859569e-01;   GW(16)  =  1.133618165463197e-01;
                GP(17)  = -4.030517551234863e-01;   GW(17)  =  1.084718405285766e-01;
                GP(18)  = -5.084407148245057e-01;   GW(18)  =  1.020591610944254e-01;
                GP(19)  = -6.066922930176181e-01;   GW(19)  =  9.421380035591415e-02;
                GP(20)  = -6.964272604199573e-01;   GW(20)  =  8.504589431348524e-02;
                GP(21)  = -7.763859488206789e-01;   GW(21)  =  7.468414976565975e-02;
                GP(22)  = -8.454459427884980e-01;   GW(22)  =  6.327404632957484e-02;
                GP(23)  = -9.026378619843071e-01;   GW(23)  =  5.097582529714781e-02;
                GP(24)  = -9.471590666617143e-01;   GW(24)  =  3.796238329436276e-02;
                GP(25)  = -9.783854459564710e-01;   GW(25)  =  2.441785109263191e-02;
                GP(26)  = -9.958857011456169e-01;   GW(26)  =  1.055137261734301e-02;

            elseif(n==27)
                GP( 1)  =  9.961792628889886e-01;   GW( 1)  =  9.798996051294360e-03;
                GP( 2)  =  9.799234759615012e-01;   GW( 2)  =  2.268623159618062e-02;
                GP( 3)  =  9.509005578147050e-01;   GW( 3)  =  3.529705375741971e-02;
                GP( 4)  =  9.094823206774911e-01;   GW( 4)  =  4.744941252061506e-02;
                GP( 5)  =  8.562079080182945e-01;   GW( 5)  =  5.898353685983360e-02;
                GP( 6)  =  7.917716390705082e-01;   GW( 6)  =  6.974882376624559e-02;
                GP( 7)  =  7.170134737394237e-01;   GW( 7)  =  7.960486777305777e-02;
                GP( 8)  =  6.329079719464951e-01;   GW( 8)  =  8.842315854375695e-02;
                GP( 9)  =  5.405515645794569e-01;   GW( 9)  =  9.608872737002851e-02;
                GP(10)  =  4.411482517500269e-01;   GW(10)  =  1.025016378177458e-01;
                GP(11)  =  3.359939036385089e-01;   GW(11)  =  1.075782857885332e-01;
                GP(12)  =  2.264593654395369e-01;   GW(12)  =  1.112524883568452e-01;
                GP(13)  =  1.139725856095300e-01;   GW(13)  =  1.134763461089651e-01;
                GP(14)  = -0.000000000000000e-01;   GW(14)  =  1.142208673789570e-01;
                GP(15)  = -1.139725856095300e-01;   GW(15)  =  1.134763461089651e-01;
                GP(16)  = -2.264593654395369e-01;   GW(16)  =  1.112524883568452e-01;
                GP(17)  = -3.359939036385089e-01;   GW(17)  =  1.075782857885332e-01;
                GP(18)  = -4.411482517500269e-01;   GW(18)  =  1.025016378177458e-01;
                GP(19)  = -5.405515645794569e-01;   GW(19)  =  9.608872737002851e-02;
                GP(20)  = -6.329079719464951e-01;   GW(20)  =  8.842315854375695e-02;
                GP(21)  = -7.170134737394237e-01;   GW(21)  =  7.960486777305777e-02;
                GP(22)  = -7.917716390705082e-01;   GW(22)  =  6.974882376624559e-02;
                GP(23)  = -8.562079080182945e-01;   GW(23)  =  5.898353685983360e-02;
                GP(24)  = -9.094823206774911e-01;   GW(24)  =  4.744941252061506e-02;
                GP(25)  = -9.509005578147050e-01;   GW(25)  =  3.529705375741971e-02;
                GP(26)  = -9.799234759615012e-01;   GW(26)  =  2.268623159618062e-02;
                GP(27)  = -9.961792628889886e-01;   GW(27)  =  9.798996051294360e-03;

            elseif(n==28)
                GP( 1)  =  9.964424975739544e-01;   GW( 1)  =  9.124282593094518e-03;
                GP( 2)  =  9.813031653708728e-01;   GW( 2)  =  2.113211259277126e-02;
                GP( 3)  =  9.542592806289382e-01;   GW( 3)  =  3.290142778230438e-02;
                GP( 4)  =  9.156330263921321e-01;   GW( 4)  =  4.427293475900423e-02;
                GP( 5)  =  8.658925225743950e-01;   GW( 5)  =  5.510734567571675e-02;
                GP( 6)  =  8.056413709171792e-01;   GW( 6)  =  6.527292396699960e-02;
                GP( 7)  =  7.356108780136318e-01;   GW( 7)  =  7.464621423456878e-02;
                GP( 8)  =  6.566510940388650e-01;   GW( 8)  =  8.311341722890122e-02;
                GP( 9)  =  5.697204718114017e-01;   GW( 9)  =  9.057174439303284e-02;
                GP(10)  =  4.758742249551183e-01;   GW(10)  =  9.693065799792992e-02;
                GP(11)  =  3.762515160890787e-01;   GW(11)  =  1.021129675780608e-01;
                GP(12)  =  2.720616276351781e-01;   GW(12)  =  1.060557659228464e-01;
                GP(13)  =  1.645692821333808e-01;   GW(13)  =  1.087111922582941e-01;
                GP(14)  =  5.507928988403427e-02;   GW(14)  =  1.100470130164752e-01;
                GP(15)  = -5.507928988403427e-02;   GW(15)  =  1.100470130164752e-01;
                GP(16)  = -1.645692821333808e-01;   GW(16)  =  1.087111922582941e-01;
                GP(17)  = -2.720616276351781e-01;   GW(17)  =  1.060557659228464e-01;
                GP(18)  = -3.762515160890787e-01;   GW(18)  =  1.021129675780608e-01;
                GP(19)  = -4.758742249551183e-01;   GW(19)  =  9.693065799792992e-02;
                GP(20)  = -5.697204718114017e-01;   GW(20)  =  9.057174439303284e-02;
                GP(21)  = -6.566510940388650e-01;   GW(21)  =  8.311341722890122e-02;
                GP(22)  = -7.356108780136318e-01;   GW(22)  =  7.464621423456878e-02;
                GP(23)  = -8.056413709171792e-01;   GW(23)  =  6.527292396699960e-02;
                GP(24)  = -8.658925225743950e-01;   GW(24)  =  5.510734567571675e-02;
                GP(25)  = -9.156330263921321e-01;   GW(25)  =  4.427293475900423e-02;
                GP(26)  = -9.542592806289382e-01;   GW(26)  =  3.290142778230438e-02;
                GP(27)  = -9.813031653708728e-01;   GW(27)  =  2.113211259277126e-02;
                GP(28)  = -9.964424975739544e-01;   GW(28)  =  9.124282593094518e-03;

            elseif(n==29)
                GP( 1)  =  9.966794422605966e-01;   GW( 1)  =  8.516903878746410e-03;
                GP( 2)  =  9.825455052614132e-01;   GW( 2)  =  1.973208505612271e-02;
                GP( 3)  =  9.572855957780877e-01;   GW( 3)  =  3.074049220209362e-02;
                GP( 4)  =  9.211802329530588e-01;   GW( 4)  =  4.140206251868284e-02;
                GP( 5)  =  8.746378049201028e-01;   GW( 5)  =  5.159482690249792e-02;
                GP( 6)  =  8.181854876152524e-01;   GW( 6)  =  6.120309065707914e-02;
                GP( 7)  =  7.524628517344771e-01;   GW( 7)  =  7.011793325505128e-02;
                GP( 8)  =  6.782145376026865e-01;   GW( 8)  =  7.823832713576378e-02;
                GP( 9)  =  5.962817971382278e-01;   GW( 9)  =  8.547225736617253e-02;
                GP(10)  =  5.075929551242276e-01;   GW(10)  =  9.173775713925876e-02;
                GP(11)  =  4.131528881740087e-01;   GW(11)  =  9.696383409440861e-02;
                GP(12)  =  3.140316378676399e-01;   GW(12)  =  1.010912737599150e-01;
                GP(13)  =  2.113522861660011e-01;   GW(13)  =  1.040733100777294e-01;
                GP(14)  =  1.062782301326792e-01;   GW(14)  =  1.058761550973209e-01;
                GP(15)  = -0.000000000000000e-01;   GW(15)  =  1.064793817183142e-01;
                GP(16)  = -1.062782301326792e-01;   GW(16)  =  1.058761550973209e-01;
                GP(17)  = -2.113522861660011e-01;   GW(17)  =  1.040733100777294e-01;
                GP(18)  = -3.140316378676399e-01;   GW(18)  =  1.010912737599150e-01;
                GP(19)  = -4.131528881740087e-01;   GW(19)  =  9.696383409440861e-02;
                GP(20)  = -5.075929551242276e-01;   GW(20)  =  9.173775713925876e-02;
                GP(21)  = -5.962817971382278e-01;   GW(21)  =  8.547225736617253e-02;
                GP(22)  = -6.782145376026865e-01;   GW(22)  =  7.823832713576378e-02;
                GP(23)  = -7.524628517344771e-01;   GW(23)  =  7.011793325505128e-02;
                GP(24)  = -8.181854876152524e-01;   GW(24)  =  6.120309065707914e-02;
                GP(25)  = -8.746378049201028e-01;   GW(25)  =  5.159482690249792e-02;
                GP(26)  = -9.211802329530588e-01;   GW(26)  =  4.140206251868284e-02;
                GP(27)  = -9.572855957780877e-01;   GW(27)  =  3.074049220209362e-02;
                GP(28)  = -9.825455052614132e-01;   GW(28)  =  1.973208505612271e-02;
                GP(29)  = -9.966794422605966e-01;   GW(29)  =  8.516903878746410e-03;

            elseif(n==30)
                GP( 1)  =  9.968934840746495e-01;   GW( 1)  =  7.968192496166606e-03;
                GP( 2)  =  9.836681232797472e-01;   GW( 2)  =  1.846646831109096e-02;
                GP( 3)  =  9.600218649683075e-01;   GW( 3)  =  2.878470788332337e-02;
                GP( 4)  =  9.262000474292743e-01;   GW( 4)  =  3.879919256962705e-02;
                GP( 5)  =  8.825605357920527e-01;   GW( 5)  =  4.840267283059405e-02;
                GP( 6)  =  8.295657623827684e-01;   GW( 6)  =  5.749315621761907e-02;
                GP( 7)  =  7.677774321048262e-01;   GW( 7)  =  6.597422988218050e-02;
                GP( 8)  =  6.978504947933158e-01;   GW( 8)  =  7.375597473770521e-02;
                GP( 9)  =  6.205261829892429e-01;   GW( 9)  =  8.075589522942022e-02;
                GP(10)  =  5.366241481420199e-01;   GW(10)  =  8.689978720108298e-02;
                GP(11)  =  4.470337695380892e-01;   GW(11)  =  9.212252223778613e-02;
                GP(12)  =  3.527047255308781e-01;   GW(12)  =  9.636873717464426e-02;
                GP(13)  =  2.546369261678898e-01;   GW(13)  =  9.959342058679527e-02;
                GP(14)  =  1.538699136085835e-01;   GW(14)  =  1.017623897484055e-01;
                GP(15)  =  5.147184255531770e-02;   GW(15)  =  1.028526528935588e-01;
                GP(16)  = -5.147184255531770e-02;   GW(16)  =  1.028526528935588e-01;
                GP(17)  = -1.538699136085835e-01;   GW(17)  =  1.017623897484055e-01;
                GP(18)  = -2.546369261678898e-01;   GW(18)  =  9.959342058679527e-02;
                GP(19)  = -3.527047255308781e-01;   GW(19)  =  9.636873717464426e-02;
                GP(20)  = -4.470337695380892e-01;   GW(20)  =  9.212252223778613e-02;
                GP(21)  = -5.366241481420199e-01;   GW(21)  =  8.689978720108298e-02;
                GP(22)  = -6.205261829892429e-01;   GW(22)  =  8.075589522942022e-02;
                GP(23)  = -6.978504947933158e-01;   GW(23)  =  7.375597473770521e-02;
                GP(24)  = -7.677774321048262e-01;   GW(24)  =  6.597422988218050e-02;
                GP(25)  = -8.295657623827684e-01;   GW(25)  =  5.749315621761907e-02;
                GP(26)  = -8.825605357920527e-01;   GW(26)  =  4.840267283059405e-02;
                GP(27)  = -9.262000474292743e-01;   GW(27)  =  3.879919256962705e-02;
                GP(28)  = -9.600218649683075e-01;   GW(28)  =  2.878470788332337e-02;
                GP(29)  = -9.836681232797472e-01;   GW(29)  =  1.846646831109096e-02;
                GP(30)  = -9.968934840746495e-01;   GW(30)  =  7.968192496166606e-03;

            elseif(n==31)
                GP( 1)  =  9.970874818194771e-01;   GW( 1)  =  7.470831579248776e-03;
                GP( 2)  =  9.846859096651525e-01;   GW( 2)  =  1.731862079031058e-02;
                GP( 3)  =  9.625039250929497e-01;   GW( 3)  =  2.700901918497942e-02;
                GP( 4)  =  9.307569978966482e-01;   GW( 4)  =  3.643227391238546e-02;
                GP( 5)  =  8.897600299482710e-01;   GW( 5)  =  4.549370752720110e-02;
                GP( 6)  =  8.399203201462673e-01;   GW( 6)  =  5.410308242491685e-02;
                GP( 7)  =  7.817331484166249e-01;   GW( 7)  =  6.217478656102843e-02;
                GP( 8)  =  7.157767845868533e-01;   GW( 8)  =  6.962858323541037e-02;
                GP( 9)  =  6.427067229242603e-01;   GW( 9)  =  7.639038659877662e-02;
                GP(10)  =  5.632491614071493e-01;   GW(10)  =  8.239299176158926e-02;
                GP(11)  =  4.781937820449025e-01;   GW(11)  =  8.757674060847788e-02;
                GP(12)  =  3.883859016082329e-01;   GW(12)  =  9.189011389364148e-02;
                GP(13)  =  2.947180699817016e-01;   GW(13)  =  9.529024291231951e-02;
                GP(14)  =  1.981211993355706e-01;   GW(14)  =  9.774333538632873e-02;
                GP(15)  =  9.955531215234152e-02;   GW(15)  =  9.922501122667231e-02;
                GP(16)  = -0.000000000000000e-01;   GW(16)  =  9.972054479342645e-02;
                GP(17)  = -9.955531215234152e-02;   GW(17)  =  9.922501122667231e-02;
                GP(18)  = -1.981211993355706e-01;   GW(18)  =  9.774333538632873e-02;
                GP(19)  = -2.947180699817016e-01;   GW(19)  =  9.529024291231951e-02;
                GP(20)  = -3.883859016082329e-01;   GW(20)  =  9.189011389364148e-02;
                GP(21)  = -4.781937820449025e-01;   GW(21)  =  8.757674060847788e-02;
                GP(22)  = -5.632491614071493e-01;   GW(22)  =  8.239299176158926e-02;
                GP(23)  = -6.427067229242603e-01;   GW(23)  =  7.639038659877662e-02;
                GP(24)  = -7.157767845868533e-01;   GW(24)  =  6.962858323541037e-02;
                GP(25)  = -7.817331484166249e-01;   GW(25)  =  6.217478656102843e-02;
                GP(26)  = -8.399203201462673e-01;   GW(26)  =  5.410308242491685e-02;
                GP(27)  = -8.897600299482710e-01;   GW(27)  =  4.549370752720110e-02;
                GP(28)  = -9.307569978966482e-01;   GW(28)  =  3.643227391238546e-02;
                GP(29)  = -9.625039250929497e-01;   GW(29)  =  2.700901918497942e-02;
                GP(30)  = -9.846859096651525e-01;   GW(30)  =  1.731862079031058e-02;
                GP(31)  = -9.970874818194771e-01;   GW(31)  =  7.470831579248776e-03;

            elseif(n==32)
                GP( 1)  =  9.972638618494816e-01;   GW( 1)  =  7.018610009470097e-03;
                GP( 2)  =  9.856115115452683e-01;   GW( 2)  =  1.627439473090567e-02;
                GP( 3)  =  9.647622555875064e-01;   GW( 3)  =  2.539206530926206e-02;
                GP( 4)  =  9.349060759377397e-01;   GW( 4)  =  3.427386291302143e-02;
                GP( 5)  =  8.963211557660521e-01;   GW( 5)  =  4.283589802222668e-02;
                GP( 6)  =  8.493676137325700e-01;   GW( 6)  =  5.099805926237618e-02;
                GP( 7)  =  7.944837959679424e-01;   GW( 7)  =  5.868409347853555e-02;
                GP( 8)  =  7.321821187402897e-01;   GW( 8)  =  6.582222277636185e-02;
                GP( 9)  =  6.630442669302152e-01;   GW( 9)  =  7.234579410884851e-02;
                GP(10)  =  5.877157572407623e-01;   GW(10)  =  7.819389578707031e-02;
                GP(11)  =  5.068999089322294e-01;   GW(11)  =  8.331192422694676e-02;
                GP(12)  =  4.213512761306353e-01;   GW(12)  =  8.765209300440381e-02;
                GP(13)  =  3.318686022821276e-01;   GW(13)  =  9.117387869576388e-02;
                GP(14)  =  2.392873622521371e-01;   GW(14)  =  9.384439908080457e-02;
                GP(15)  =  1.444719615827965e-01;   GW(15)  =  9.563872007927486e-02;
                GP(16)  =  4.830766568773832e-02;   GW(16)  =  9.654008851472780e-02;
                GP(17)  = -4.830766568773832e-02;   GW(17)  =  9.654008851472780e-02;
                GP(18)  = -1.444719615827965e-01;   GW(18)  =  9.563872007927486e-02;
                GP(19)  = -2.392873622521371e-01;   GW(19)  =  9.384439908080457e-02;
                GP(20)  = -3.318686022821276e-01;   GW(20)  =  9.117387869576388e-02;
                GP(21)  = -4.213512761306353e-01;   GW(21)  =  8.765209300440381e-02;
                GP(22)  = -5.068999089322294e-01;   GW(22)  =  8.331192422694676e-02;
                GP(23)  = -5.877157572407623e-01;   GW(23)  =  7.819389578707031e-02;
                GP(24)  = -6.630442669302152e-01;   GW(24)  =  7.234579410884851e-02;
                GP(25)  = -7.321821187402897e-01;   GW(25)  =  6.582222277636185e-02;
                GP(26)  = -7.944837959679424e-01;   GW(26)  =  5.868409347853555e-02;
                GP(27)  = -8.493676137325700e-01;   GW(27)  =  5.099805926237618e-02;
                GP(28)  = -8.963211557660521e-01;   GW(28)  =  4.283589802222668e-02;
                GP(29)  = -9.349060759377397e-01;   GW(29)  =  3.427386291302143e-02;
                GP(30)  = -9.647622555875064e-01;   GW(30)  =  2.539206530926206e-02;
                GP(31)  = -9.856115115452683e-01;   GW(31)  =  1.627439473090567e-02;
                GP(32)  = -9.972638618494816e-01;   GW(32)  =  7.018610009470097e-03;

            elseif(n==33)
                GP( 1)  =  9.974246942464552e-01;   GW( 1)  =  6.606227847587378e-03;
                GP( 2)  =  9.864557262306425e-01;   GW( 2)  =  1.532170151293468e-02;
                GP( 3)  =  9.668229096899928e-01;   GW( 3)  =  2.391554810174948e-02;
                GP( 4)  =  9.386943726111684e-01;   GW( 4)  =  3.230035863232895e-02;
                GP( 5)  =  9.023167677434336e-01;   GW( 5)  =  4.040154133166959e-02;
                GP( 6)  =  8.580096526765041e-01;   GW( 6)  =  4.814774281871170e-02;
                GP( 7)  =  8.061623562741666e-01;   GW( 7)  =  5.547084663166356e-02;
                GP( 8)  =  7.472304964495622e-01;   GW( 8)  =  6.230648253031748e-02;
                GP( 9)  =  6.817319599697428e-01;   GW( 9)  =  6.859457281865671e-02;
                GP(10)  =  6.102423458363790e-01;   GW(10)  =  7.427985484395415e-02;
                GP(11)  =  5.333899047863476e-01;   GW(11)  =  7.931236479488674e-02;
                GP(12)  =  4.518500172724507e-01;   GW(12)  =  8.364787606703871e-02;
                GP(13)  =  3.663392577480733e-01;   GW(13)  =  8.724828761884434e-02;
                GP(14)  =  2.776090971524970e-01;   GW(14)  =  9.008195866063858e-02;
                GP(15)  =  1.864392988279916e-01;   GW(15)  =  9.212398664331685e-02;
                GP(16)  =  9.363106585473339e-02;   GW(16)  =  9.335642606559612e-02;
                GP(17)  = -0.000000000000000e-01;   GW(17)  =  9.376844616021000e-02;
                GP(18)  = -9.363106585473339e-02;   GW(18)  =  9.335642606559612e-02;
                GP(19)  = -1.864392988279916e-01;   GW(19)  =  9.212398664331685e-02;
                GP(20)  = -2.776090971524970e-01;   GW(20)  =  9.008195866063858e-02;
                GP(21)  = -3.663392577480733e-01;   GW(21)  =  8.724828761884434e-02;
                GP(22)  = -4.518500172724507e-01;   GW(22)  =  8.364787606703871e-02;
                GP(23)  = -5.333899047863476e-01;   GW(23)  =  7.931236479488674e-02;
                GP(24)  = -6.102423458363790e-01;   GW(24)  =  7.427985484395415e-02;
                GP(25)  = -6.817319599697428e-01;   GW(25)  =  6.859457281865671e-02;
                GP(26)  = -7.472304964495622e-01;   GW(26)  =  6.230648253031748e-02;
                GP(27)  = -8.061623562741666e-01;   GW(27)  =  5.547084663166356e-02;
                GP(28)  = -8.580096526765041e-01;   GW(28)  =  4.814774281871170e-02;
                GP(29)  = -9.023167677434336e-01;   GW(29)  =  4.040154133166959e-02;
                GP(30)  = -9.386943726111684e-01;   GW(30)  =  3.230035863232895e-02;
                GP(31)  = -9.668229096899928e-01;   GW(31)  =  2.391554810174948e-02;
                GP(32)  = -9.864557262306425e-01;   GW(32)  =  1.532170151293468e-02;
                GP(33)  = -9.974246942464552e-01;   GW(33)  =  6.606227847587378e-03;

            elseif(n==34)
                GP( 1)  =  9.975717537908419e-01;   GW( 1)  =  6.229140555908685e-03;
                GP( 2)  =  9.872278164063095e-01;   GW( 2)  =  1.445016274859504e-02;
                GP( 3)  =  9.687082625333443e-01;   GW( 3)  =  2.256372198549497e-02;
                GP( 4)  =  9.421623974051071e-01;   GW( 4)  =  3.049138063844613e-02;
                GP( 5)  =  9.078096777183245e-01;   GW( 5)  =  3.816659379638752e-02;
                GP( 6)  =  8.659346383345645e-01;   GW( 6)  =  4.552561152335327e-02;
                GP( 7)  =  8.168842279009337e-01;   GW( 7)  =  5.250741457267811e-02;
                GP( 8)  =  7.610648766298730e-01;   GW( 8)  =  5.905413582752449e-02;
                GP( 9)  =  6.989391132162629e-01;   GW( 9)  =  6.511152155407641e-02;
                GP(10)  =  6.310217270805285e-01;   GW(10)  =  7.062937581425572e-02;
                GP(11)  =  5.578755006697466e-01;   GW(11)  =  7.556197466003193e-02;
                GP(12)  =  4.801065451903270e-01;   GW(12)  =  7.986844433977184e-02;
                GP(13)  =  3.983592777586459e-01;   GW(13)  =  8.351309969984566e-02;
                GP(14)  =  3.133110813394632e-01;   GW(14)  =  8.646573974703575e-02;
                GP(15)  =  2.256666916164495e-01;   GW(15)  =  8.870189783569387e-02;
                GP(16)  =  1.361523572591830e-01;   GW(16)  =  9.020304437064073e-02;
                GP(17)  =  4.550982195310254e-02;   GW(17)  =  9.095674033025987e-02;
                GP(18)  = -4.550982195310254e-02;   GW(18)  =  9.095674033025987e-02;
                GP(19)  = -1.361523572591830e-01;   GW(19)  =  9.020304437064073e-02;
                GP(20)  = -2.256666916164495e-01;   GW(20)  =  8.870189783569387e-02;
                GP(21)  = -3.133110813394632e-01;   GW(21)  =  8.646573974703575e-02;
                GP(22)  = -3.983592777586459e-01;   GW(22)  =  8.351309969984566e-02;
                GP(23)  = -4.801065451903270e-01;   GW(23)  =  7.986844433977184e-02;
                GP(24)  = -5.578755006697466e-01;   GW(24)  =  7.556197466003193e-02;
                GP(25)  = -6.310217270805285e-01;   GW(25)  =  7.062937581425572e-02;
                GP(26)  = -6.989391132162629e-01;   GW(26)  =  6.511152155407641e-02;
                GP(27)  = -7.610648766298730e-01;   GW(27)  =  5.905413582752449e-02;
                GP(28)  = -8.168842279009337e-01;   GW(28)  =  5.250741457267811e-02;
                GP(29)  = -8.659346383345645e-01;   GW(29)  =  4.552561152335327e-02;
                GP(30)  = -9.078096777183245e-01;   GW(30)  =  3.816659379638752e-02;
                GP(31)  = -9.421623974051071e-01;   GW(31)  =  3.049138063844613e-02;
                GP(32)  = -9.687082625333443e-01;   GW(32)  =  2.256372198549497e-02;
                GP(33)  = -9.872278164063095e-01;   GW(33)  =  1.445016274859504e-02;
                GP(34)  = -9.975717537908419e-01;   GW(34)  =  6.229140555908685e-03;

            elseif(n==35)
                GP( 1)  =  9.977065690996003e-01;   GW( 1)  =  5.883433420443085e-03;
                GP( 2)  =  9.879357644438515e-01;   GW( 2)  =  1.365082834836149e-02;
                GP( 3)  =  9.704376160392298e-01;   GW( 3)  =  2.132297991148358e-02;
                GP( 4)  =  9.453451482078273e-01;   GW( 4)  =  2.882926010889425e-02;
                GP( 5)  =  9.128542613593176e-01;   GW( 5)  =  3.611011586346338e-02;
                GP( 6)  =  8.732191250252223e-01;   GW( 6)  =  4.310842232617022e-02;
                GP( 7)  =  8.267498990922254e-01;   GW( 7)  =  4.976937040135353e-02;
                GP( 8)  =  7.738102522869126e-01;   GW( 8)  =  5.604081621237013e-02;
                GP( 9)  =  7.148145015566288e-01;   GW( 9)  =  6.187367196608019e-02;
                GP(10)  =  6.502243646658904e-01;   GW(10)  =  6.722228526908690e-02;
                GP(11)  =  5.805453447497645e-01;   GW(11)  =  7.204479477256006e-02;
                GP(12)  =  5.063227732414886e-01;   GW(12)  =  7.630345715544205e-02;
                GP(13)  =  4.281375415178143e-01;   GW(13)  =  7.996494224232426e-02;
                GP(14)  =  3.466015544308139e-01;   GW(14)  =  8.300059372885659e-02;
                GP(15)  =  2.623529412092961e-01;   GW(15)  =  8.538665339209913e-02;
                GP(16)  =  1.760510611659896e-01;   GW(16)  =  8.710444699718353e-02;
                GP(17)  =  8.837134327565926e-02;   GW(17)  =  8.814053043027546e-02;
                GP(18)  = -0.000000000000000e-01;   GW(18)  =  8.848679490710429e-02;
                GP(19)  = -8.837134327565926e-02;   GW(19)  =  8.814053043027546e-02;
                GP(20)  = -1.760510611659896e-01;   GW(20)  =  8.710444699718353e-02;
                GP(21)  = -2.623529412092961e-01;   GW(21)  =  8.538665339209913e-02;
                GP(22)  = -3.466015544308139e-01;   GW(22)  =  8.300059372885659e-02;
                GP(23)  = -4.281375415178143e-01;   GW(23)  =  7.996494224232426e-02;
                GP(24)  = -5.063227732414886e-01;   GW(24)  =  7.630345715544205e-02;
                GP(25)  = -5.805453447497645e-01;   GW(25)  =  7.204479477256006e-02;
                GP(26)  = -6.502243646658904e-01;   GW(26)  =  6.722228526908690e-02;
                GP(27)  = -7.148145015566288e-01;   GW(27)  =  6.187367196608019e-02;
                GP(28)  = -7.738102522869126e-01;   GW(28)  =  5.604081621237013e-02;
                GP(29)  = -8.267498990922254e-01;   GW(29)  =  4.976937040135353e-02;
                GP(30)  = -8.732191250252223e-01;   GW(30)  =  4.310842232617022e-02;
                GP(31)  = -9.128542613593176e-01;   GW(31)  =  3.611011586346338e-02;
                GP(32)  = -9.453451482078273e-01;   GW(32)  =  2.882926010889425e-02;
                GP(33)  = -9.704376160392298e-01;   GW(33)  =  2.132297991148358e-02;
                GP(34)  = -9.879357644438515e-01;   GW(34)  =  1.365082834836149e-02;
                GP(35)  = -9.977065690996003e-01;   GW(35)  =  5.883433420443085e-03;

            elseif(n==36)
                GP( 1)  =  9.978304624840858e-01;   GW( 1)  =  5.565719664245045e-03;
                GP( 2)  =  9.885864789022122e-01;   GW( 2)  =  1.291594728406557e-02;
                GP( 3)  =  9.720276910496979e-01;   GW( 3)  =  2.018151529773547e-02;
                GP( 4)  =  9.482729843995075e-01;   GW( 4)  =  2.729862149856878e-02;
                GP( 5)  =  9.174977745156591e-01;   GW( 5)  =  3.421381077030723e-02;
                GP( 6)  =  8.799298008903971e-01;   GW( 6)  =  4.087575092364490e-02;
                GP( 7)  =  8.358471669924753e-01;   GW( 7)  =  4.723508349026598e-02;
                GP( 8)  =  7.855762301322065e-01;   GW( 8)  =  5.324471397775992e-02;
                GP( 9)  =  7.294891715935566e-01;   GW( 9)  =  5.886014424532482e-02;
                GP(10)  =  6.680012365855211e-01;   GW(10)  =  6.403979735501549e-02;
                GP(11)  =  6.015676581359805e-01;   GW(11)  =  6.874532383573644e-02;
                GP(12)  =  5.306802859262452e-01;   GW(12)  =  7.294188500565306e-02;
                GP(13)  =  4.558639444334203e-01;   GW(13)  =  7.659841064587067e-02;
                GP(14)  =  3.776725471196892e-01;   GW(14)  =  7.968782891207160e-02;
                GP(15)  =  2.966849953440283e-01;   GW(15)  =  8.218726670433971e-02;
                GP(16)  =  2.135008923168656e-01;   GW(16)  =  8.407821897966193e-02;
                GP(17)  =  1.287361038093848e-01;   GW(17)  =  8.534668573933863e-02;
                GP(18)  =  4.301819847370861e-02;   GW(18)  =  8.598327567039475e-02;
                GP(19)  = -4.301819847370861e-02;   GW(19)  =  8.598327567039475e-02;
                GP(20)  = -1.287361038093848e-01;   GW(20)  =  8.534668573933863e-02;
                GP(21)  = -2.135008923168656e-01;   GW(21)  =  8.407821897966193e-02;
                GP(22)  = -2.966849953440283e-01;   GW(22)  =  8.218726670433971e-02;
                GP(23)  = -3.776725471196892e-01;   GW(23)  =  7.968782891207160e-02;
                GP(24)  = -4.558639444334203e-01;   GW(24)  =  7.659841064587067e-02;
                GP(25)  = -5.306802859262452e-01;   GW(25)  =  7.294188500565306e-02;
                GP(26)  = -6.015676581359805e-01;   GW(26)  =  6.874532383573644e-02;
                GP(27)  = -6.680012365855211e-01;   GW(27)  =  6.403979735501549e-02;
                GP(28)  = -7.294891715935566e-01;   GW(28)  =  5.886014424532482e-02;
                GP(29)  = -7.855762301322065e-01;   GW(29)  =  5.324471397775992e-02;
                GP(30)  = -8.358471669924753e-01;   GW(30)  =  4.723508349026598e-02;
                GP(31)  = -8.799298008903971e-01;   GW(31)  =  4.087575092364490e-02;
                GP(32)  = -9.174977745156591e-01;   GW(32)  =  3.421381077030723e-02;
                GP(33)  = -9.482729843995075e-01;   GW(33)  =  2.729862149856878e-02;
                GP(34)  = -9.720276910496979e-01;   GW(34)  =  2.018151529773547e-02;
                GP(35)  = -9.885864789022122e-01;   GW(35)  =  1.291594728406557e-02;
                GP(36)  = -9.978304624840858e-01;   GW(36)  =  5.565719664245045e-03;

            elseif(n==37)
                GP( 1)  =  9.979445824779136e-01;   GW( 1)  =  5.273057279497939e-03;
                GP( 2)  =  9.891859632143192e-01;   GW( 2)  =  1.223878010030756e-02;
                GP( 3)  =  9.734930300564857e-01;   GW( 3)  =  1.912904448908397e-02;
                GP( 4)  =  9.509723432620948e-01;   GW( 4)  =  2.588603699055893e-02;
                GP( 5)  =  9.217814374124637e-01;   GW( 5)  =  3.246163984752148e-02;
                GP( 6)  =  8.861249621554861e-01;   GW( 6)  =  3.880960250193454e-02;
                GP( 7)  =  8.442529873405560e-01;   GW( 7)  =  4.488536466243717e-02;
                GP( 8)  =  7.964592005099023e-01;   GW( 8)  =  5.064629765482460e-02;
                GP( 9)  =  7.430788339819653e-01;   GW( 9)  =  5.605198799827492e-02;
                GP(10)  =  6.844863091309594e-01;   GW(10)  =  6.106451652322599e-02;
                GP(11)  =  6.210926084089245e-01;   GW(11)  =  6.564872287275125e-02;
                GP(12)  =  5.533423918615818e-01;   GW(12)  =  6.977245155570034e-02;
                GP(13)  =  4.817108778032056e-01;   GW(13)  =  7.340677724848817e-02;
                GP(14)  =  4.067005093183261e-01;   GW(14)  =  7.652620757052924e-02;
                GP(15)  =  3.288374298837070e-01;   GW(15)  =  7.910886183752938e-02;
                GP(16)  =  2.486677927913658e-01;   GW(16)  =  8.113662450846503e-02;
                GP(17)  =  1.667539302398520e-01;   GW(17)  =  8.259527223643725e-02;
                GP(18)  =  8.367040895476990e-02;   GW(18)  =  8.347457362586279e-02;
                GP(19)  = -0.000000000000000e-01;   GW(19)  =  8.376836099313890e-02;
                GP(20)  = -8.367040895476990e-02;   GW(20)  =  8.347457362586279e-02;
                GP(21)  = -1.667539302398520e-01;   GW(21)  =  8.259527223643725e-02;
                GP(22)  = -2.486677927913658e-01;   GW(22)  =  8.113662450846503e-02;
                GP(23)  = -3.288374298837070e-01;   GW(23)  =  7.910886183752938e-02;
                GP(24)  = -4.067005093183261e-01;   GW(24)  =  7.652620757052924e-02;
                GP(25)  = -4.817108778032056e-01;   GW(25)  =  7.340677724848817e-02;
                GP(26)  = -5.533423918615818e-01;   GW(26)  =  6.977245155570034e-02;
                GP(27)  = -6.210926084089245e-01;   GW(27)  =  6.564872287275125e-02;
                GP(28)  = -6.844863091309594e-01;   GW(28)  =  6.106451652322599e-02;
                GP(29)  = -7.430788339819653e-01;   GW(29)  =  5.605198799827492e-02;
                GP(30)  = -7.964592005099023e-01;   GW(30)  =  5.064629765482460e-02;
                GP(31)  = -8.442529873405560e-01;   GW(31)  =  4.488536466243717e-02;
                GP(32)  = -8.861249621554861e-01;   GW(32)  =  3.880960250193454e-02;
                GP(33)  = -9.217814374124637e-01;   GW(33)  =  3.246163984752148e-02;
                GP(34)  = -9.509723432620948e-01;   GW(34)  =  2.588603699055893e-02;
                GP(35)  = -9.734930300564857e-01;   GW(35)  =  1.912904448908397e-02;
                GP(36)  = -9.891859632143192e-01;   GW(36)  =  1.223878010030756e-02;
                GP(37)  = -9.979445824779136e-01;   GW(37)  =  5.273057279497939e-03;

            elseif(n==38)
                GP( 1)  =  9.980499305356876e-01;   GW( 1)  =  5.002880749639346e-03;
                GP( 2)  =  9.897394542663856e-01;   GW( 2)  =  1.161344471646867e-02;
                GP( 3)  =  9.748463285901535e-01;   GW( 3)  =  1.815657770961324e-02;
                GP( 4)  =  9.534663309335296e-01;   GW( 4)  =  2.457973973823238e-02;
                GP( 5)  =  9.257413320485844e-01;   GW( 5)  =  3.083950054517505e-02;
                GP( 6)  =  8.918557390046322e-01;   GW( 6)  =  3.689408159402474e-02;
                GP( 7)  =  8.520350219323622e-01;   GW( 7)  =  4.270315850467443e-02;
                GP( 8)  =  8.065441676053168e-01;   GW( 8)  =  4.822806186075868e-02;
                GP( 9)  =  7.556859037539707e-01;   GW( 9)  =  5.343201991033232e-02;
                GP(10)  =  6.997986803791844e-01;   GW(10)  =  5.828039914699721e-02;
                GP(11)  =  6.392544158296817e-01;   GW(11)  =  6.274093339213305e-02;
                GP(12)  =  5.744560210478071e-01;   GW(12)  =  6.678393797914041e-02;
                GP(13)  =  5.058347179279311e-01;   GW(13)  =  7.038250706689895e-02;
                GP(14)  =  4.338471694323765e-01;   GW(14)  =  7.351269258474346e-02;
                GP(15)  =  3.589724404794350e-01;   GW(15)  =  7.615366354844640e-02;
                GP(16)  =  2.817088097901653e-01;   GW(16)  =  7.828784465821095e-02;
                GP(17)  =  2.025704538921167e-01;   GW(17)  =  7.990103324352782e-02;
                GP(18)  =  1.220840253378674e-01;   GW(18)  =  8.098249377059710e-02;
                GP(19)  =  4.078514790457824e-02;   GW(19)  =  8.152502928038579e-02;
                GP(20)  = -4.078514790457824e-02;   GW(20)  =  8.152502928038579e-02;
                GP(21)  = -1.220840253378674e-01;   GW(21)  =  8.098249377059710e-02;
                GP(22)  = -2.025704538921167e-01;   GW(22)  =  7.990103324352782e-02;
                GP(23)  = -2.817088097901653e-01;   GW(23)  =  7.828784465821095e-02;
                GP(24)  = -3.589724404794350e-01;   GW(24)  =  7.615366354844640e-02;
                GP(25)  = -4.338471694323765e-01;   GW(25)  =  7.351269258474346e-02;
                GP(26)  = -5.058347179279311e-01;   GW(26)  =  7.038250706689895e-02;
                GP(27)  = -5.744560210478071e-01;   GW(27)  =  6.678393797914041e-02;
                GP(28)  = -6.392544158296817e-01;   GW(28)  =  6.274093339213305e-02;
                GP(29)  = -6.997986803791844e-01;   GW(29)  =  5.828039914699721e-02;
                GP(30)  = -7.556859037539707e-01;   GW(30)  =  5.343201991033232e-02;
                GP(31)  = -8.065441676053168e-01;   GW(31)  =  4.822806186075868e-02;
                GP(32)  = -8.520350219323622e-01;   GW(32)  =  4.270315850467443e-02;
                GP(33)  = -8.918557390046322e-01;   GW(33)  =  3.689408159402474e-02;
                GP(34)  = -9.257413320485844e-01;   GW(34)  =  3.083950054517505e-02;
                GP(35)  = -9.534663309335296e-01;   GW(35)  =  2.457973973823238e-02;
                GP(36)  = -9.748463285901535e-01;   GW(36)  =  1.815657770961324e-02;
                GP(37)  = -9.897394542663856e-01;   GW(37)  =  1.161344471646867e-02;
                GP(38)  = -9.980499305356876e-01;   GW(38)  =  5.002880749639346e-03;

            elseif(n==39)
                GP( 1)  =  9.981473830664329e-01;   GW( 1)  =  4.752944691635101e-03;
                GP( 2)  =  9.902515368546860e-01;   GW( 2)  =  1.103478893916459e-02;
                GP( 3)  =  9.760987093334711e-01;   GW( 3)  =  1.725622909372492e-02;
                GP( 4)  =  9.557752123246523e-01;   GW( 4)  =  2.336938483217816e-02;
                GP( 5)  =  9.294091484867382e-01;   GW( 5)  =  2.933495598390338e-02;
                GP( 6)  =  8.971671192929929e-01;   GW( 6)  =  3.511511149813133e-02;
                GP( 7)  =  8.592529379999062e-01;   GW( 7)  =  4.067327684793384e-02;
                GP( 8)  =  8.159062974301431e-01;   GW( 8)  =  4.597430110891663e-02;
                GP( 9)  =  7.674012429310635e-01;   GW( 9)  =  5.098466529212941e-02;
                GP(10)  =  7.140444358945347e-01;   GW(10)  =  5.567269034091630e-02;
                GP(11)  =  6.561732134320109e-01;   GW(11)  =  6.000873608859615e-02;
                GP(12)  =  5.941534549572780e-01;   GW(12)  =  6.396538813868239e-02;
                GP(13)  =  5.283772686604375e-01;   GW(13)  =  6.751763096623127e-02;
                GP(14)  =  4.592605123091360e-01;   GW(14)  =  7.064300597060876e-02;
                GP(15)  =  3.872401639715615e-01;   GW(15)  =  7.332175341426862e-02;
                GP(16)  =  3.127715592481859e-01;   GW(16)  =  7.553693732283606e-02;
                GP(17)  =  2.363255124618358e-01;   GW(17)  =  7.727455254468202e-02;
                GP(18)  =  1.583853399978378e-01;   GW(18)  =  7.852361328737118e-02;
                GP(19)  =  7.944380460875548e-02;   GW(19)  =  7.927622256836847e-02;
                GP(20)  = -0.000000000000000e-01;   GW(20)  =  7.952762213944285e-02;
                GP(21)  = -7.944380460875548e-02;   GW(21)  =  7.927622256836847e-02;
                GP(22)  = -1.583853399978378e-01;   GW(22)  =  7.852361328737118e-02;
                GP(23)  = -2.363255124618358e-01;   GW(23)  =  7.727455254468202e-02;
                GP(24)  = -3.127715592481859e-01;   GW(24)  =  7.553693732283606e-02;
                GP(25)  = -3.872401639715615e-01;   GW(25)  =  7.332175341426862e-02;
                GP(26)  = -4.592605123091360e-01;   GW(26)  =  7.064300597060876e-02;
                GP(27)  = -5.283772686604375e-01;   GW(27)  =  6.751763096623127e-02;
                GP(28)  = -5.941534549572780e-01;   GW(28)  =  6.396538813868239e-02;
                GP(29)  = -6.561732134320109e-01;   GW(29)  =  6.000873608859615e-02;
                GP(30)  = -7.140444358945347e-01;   GW(30)  =  5.567269034091630e-02;
                GP(31)  = -7.674012429310635e-01;   GW(31)  =  5.098466529212941e-02;
                GP(32)  = -8.159062974301431e-01;   GW(32)  =  4.597430110891663e-02;
                GP(33)  = -8.592529379999062e-01;   GW(33)  =  4.067327684793384e-02;
                GP(34)  = -8.971671192929929e-01;   GW(34)  =  3.511511149813133e-02;
                GP(35)  = -9.294091484867382e-01;   GW(35)  =  2.933495598390338e-02;
                GP(36)  = -9.557752123246523e-01;   GW(36)  =  2.336938483217816e-02;
                GP(37)  = -9.760987093334711e-01;   GW(37)  =  1.725622909372492e-02;
                GP(38)  = -9.902515368546860e-01;   GW(38)  =  1.103478893916459e-02;
                GP(39)  = -9.981473830664329e-01;   GW(39)  =  4.752944691635101e-03;

            elseif(n==40)
                GP( 1)  =  9.982377097105592e-01;   GW( 1)  =  4.521277098533191e-03;
                GP( 2)  =  9.907262386994570e-01;   GW( 2)  =  1.049828453115281e-02;
                GP( 3)  =  9.772599499837743e-01;   GW( 3)  =  1.642105838190789e-02;
                GP( 4)  =  9.579168192137917e-01;   GW( 4)  =  2.224584919416696e-02;
                GP( 5)  =  9.328128082786765e-01;   GW( 5)  =  2.793700698002340e-02;
                GP( 6)  =  9.020988069688743e-01;   GW( 6)  =  3.346019528254785e-02;
                GP( 7)  =  8.659595032122595e-01;   GW( 7)  =  3.878216797447202e-02;
                GP( 8)  =  8.246122308333117e-01;   GW( 8)  =  4.387090818567327e-02;
                GP( 9)  =  7.783056514265194e-01;   GW( 9)  =  4.869580763507223e-02;
                GP(10)  =  7.273182551899271e-01;   GW(10)  =  5.322784698393682e-02;
                GP(11)  =  6.719566846141795e-01;   GW(11)  =  5.743976909939155e-02;
                GP(12)  =  6.125538896679802e-01;   GW(12)  =  6.130624249292894e-02;
                GP(13)  =  5.494671250951282e-01;   GW(13)  =  6.480401345660104e-02;
                GP(14)  =  4.830758016861787e-01;   GW(14)  =  6.791204581523390e-02;
                GP(15)  =  4.137792043716050e-01;   GW(15)  =  7.061164739128678e-02;
                GP(16)  =  3.419940908257585e-01;   GW(16)  =  7.288658239580406e-02;
                GP(17)  =  2.681521850072537e-01;   GW(17)  =  7.472316905796826e-02;
                GP(18)  =  1.926975807013711e-01;   GW(18)  =  7.611036190062624e-02;
                GP(19)  =  1.160840706752552e-01;   GW(19)  =  7.703981816424797e-02;
                GP(20)  =  3.877241750605082e-02;   GW(20)  =  7.750594797842481e-02;
                GP(21)  = -3.877241750605082e-02;   GW(21)  =  7.750594797842481e-02;
                GP(22)  = -1.160840706752552e-01;   GW(22)  =  7.703981816424797e-02;
                GP(23)  = -1.926975807013711e-01;   GW(23)  =  7.611036190062624e-02;
                GP(24)  = -2.681521850072537e-01;   GW(24)  =  7.472316905796826e-02;
                GP(25)  = -3.419940908257585e-01;   GW(25)  =  7.288658239580406e-02;
                GP(26)  = -4.137792043716050e-01;   GW(26)  =  7.061164739128678e-02;
                GP(27)  = -4.830758016861787e-01;   GW(27)  =  6.791204581523390e-02;
                GP(28)  = -5.494671250951282e-01;   GW(28)  =  6.480401345660104e-02;
                GP(29)  = -6.125538896679802e-01;   GW(29)  =  6.130624249292894e-02;
                GP(30)  = -6.719566846141795e-01;   GW(30)  =  5.743976909939155e-02;
                GP(31)  = -7.273182551899271e-01;   GW(31)  =  5.322784698393682e-02;
                GP(32)  = -7.783056514265194e-01;   GW(32)  =  4.869580763507223e-02;
                GP(33)  = -8.246122308333117e-01;   GW(33)  =  4.387090818567327e-02;
                GP(34)  = -8.659595032122595e-01;   GW(34)  =  3.878216797447202e-02;
                GP(35)  = -9.020988069688743e-01;   GW(35)  =  3.346019528254785e-02;
                GP(36)  = -9.328128082786765e-01;   GW(36)  =  2.793700698002340e-02;
                GP(37)  = -9.579168192137917e-01;   GW(37)  =  2.224584919416696e-02;
                GP(38)  = -9.772599499837743e-01;   GW(38)  =  1.642105838190789e-02;
                GP(39)  = -9.907262386994570e-01;   GW(39)  =  1.049828453115281e-02;
                GP(40)  = -9.982377097105592e-01;   GW(40)  =  4.521277098533191e-03;

            elseif(n==41)
                GP( 1)  =  9.983215885747714e-01;   GW( 1)  =  4.306140358164888e-03;
                GP( 2)  =  9.911671096990163e-01;   GW( 2)  =  9.999938773905945e-03;
                GP( 3)  =  9.783386735610834e-01;   GW( 3)  =  1.564493840781859e-02;
                GP( 4)  =  9.599068917303462e-01;   GW( 4)  =  2.120106336877955e-02;
                GP( 5)  =  9.359769874978538e-01;   GW( 5)  =  2.663589920711045e-02;
                GP( 6)  =  9.066859447581012e-01;   GW( 6)  =  3.191821173169928e-02;
                GP( 7)  =  8.722015116924414e-01;   GW( 7)  =  3.701771670350799e-02;
                GP( 8)  =  8.327212004013613e-01;   GW( 8)  =  4.190519519590969e-02;
                GP( 9)  =  7.884711450474094e-01;   GW( 9)  =  4.655264836901434e-02;
                GP(10)  =  7.397048030699262e-01;   GW(10)  =  5.093345429461749e-02;
                GP(11)  =  6.867015020349513e-01;   GW(11)  =  5.502251924257874e-02;
                GP(12)  =  6.297648390721963e-01;   GW(12)  =  5.879642094987194e-02;
                GP(13)  =  5.692209416102159e-01;   GW(13)  =  6.223354258096632e-02;
                GP(14)  =  5.054165991994060e-01;   GW(14)  =  6.531419645352741e-02;
                GP(15)  =  4.387172770514071e-01;   GW(15)  =  6.802073676087677e-02;
                GP(16)  =  3.695050226404814e-01;   GW(16)  =  7.033766062081750e-02;
                GP(17)  =  2.981762773418249e-01;   GW(17)  =  7.225169686102307e-02;
                GP(18)  =  2.251396056334228e-01;   GW(18)  =  7.375188202722347e-02;
                GP(19)  =  1.508133548639922e-01;   GW(19)  =  7.482962317622155e-02;
                GP(20)  =  7.562325898916300e-02;   GW(20)  =  7.547874709271582e-02;
                GP(21)  = -0.000000000000000e-01;   GW(21)  =  7.569553564729837e-02;
                GP(22)  = -7.562325898916300e-02;   GW(22)  =  7.547874709271582e-02;
                GP(23)  = -1.508133548639922e-01;   GW(23)  =  7.482962317622155e-02;
                GP(24)  = -2.251396056334228e-01;   GW(24)  =  7.375188202722347e-02;
                GP(25)  = -2.981762773418249e-01;   GW(25)  =  7.225169686102307e-02;
                GP(26)  = -3.695050226404814e-01;   GW(26)  =  7.033766062081750e-02;
                GP(27)  = -4.387172770514071e-01;   GW(27)  =  6.802073676087677e-02;
                GP(28)  = -5.054165991994060e-01;   GW(28)  =  6.531419645352741e-02;
                GP(29)  = -5.692209416102159e-01;   GW(29)  =  6.223354258096632e-02;
                GP(30)  = -6.297648390721963e-01;   GW(30)  =  5.879642094987194e-02;
                GP(31)  = -6.867015020349513e-01;   GW(31)  =  5.502251924257874e-02;
                GP(32)  = -7.397048030699262e-01;   GW(32)  =  5.093345429461749e-02;
                GP(33)  = -7.884711450474094e-01;   GW(33)  =  4.655264836901434e-02;
                GP(34)  = -8.327212004013613e-01;   GW(34)  =  4.190519519590969e-02;
                GP(35)  = -8.722015116924414e-01;   GW(35)  =  3.701771670350799e-02;
                GP(36)  = -9.066859447581012e-01;   GW(36)  =  3.191821173169928e-02;
                GP(37)  = -9.359769874978538e-01;   GW(37)  =  2.663589920711045e-02;
                GP(38)  = -9.599068917303462e-01;   GW(38)  =  2.120106336877955e-02;
                GP(39)  = -9.783386735610834e-01;   GW(39)  =  1.564493840781859e-02;
                GP(40)  = -9.911671096990163e-01;   GW(40)  =  9.999938773905945e-03;
                GP(41)  = -9.983215885747714e-01;   GW(41)  =  4.306140358164888e-03;

            elseif(n==42)
                GP( 1)  =  9.983996189900624e-01;   GW( 1)  =  4.105998604649085e-03;
                GP( 2)  =  9.915772883408609e-01;   GW( 2)  =  9.536220301748502e-03;
                GP( 3)  =  9.793425080637482e-01;   GW( 3)  =  1.492244369735749e-02;
                GP( 4)  =  9.617593653382045e-01;   GW( 4)  =  2.022786956905264e-02;
                GP( 5)  =  9.389235573549882e-01;   GW( 5)  =  2.542295952611305e-02;
                GP( 6)  =  9.109597249041275e-01;   GW( 6)  =  3.047924069960347e-02;
                GP( 7)  =  8.780205698121727e-01;   GW( 7)  =  3.536907109759211e-02;
                GP( 8)  =  8.402859832618169e-01;   GW( 8)  =  4.006573518069226e-02;
                GP( 9)  =  7.979620532554874e-01;   GW( 9)  =  4.454357777196588e-02;
                GP(10)  =  7.512799356894805e-01;   GW(10)  =  4.877814079280325e-02;
                GP(11)  =  7.004945905561712e-01;   GW(11)  =  5.274629569917407e-02;
                GP(12)  =  6.458833888692478e-01;   GW(12)  =  5.642636935801838e-02;
                GP(13)  =  5.877445974851093e-01;   GW(13)  =  5.979826222758665e-02;
                GP(14)  =  5.263957499311923e-01;   GW(14)  =  6.284355804500258e-02;
                GP(15)  =  4.621719120704219e-01;   GW(15)  =  6.554562436490898e-02;
                GP(16)  =  3.954238520429751e-01;   GW(16)  =  6.788970337652194e-02;
                GP(17)  =  3.265161244654115e-01;   GW(17)  =  6.986299249259416e-02;
                GP(18)  =  2.558250793428791e-01;   GW(18)  =  7.145471426517098e-02;
                GP(19)  =  1.837368065648546e-01;   GW(19)  =  7.265617524380410e-02;
                GP(20)  =  1.106450272085199e-01;   GW(20)  =  7.346081345346753e-02;
                GP(21)  =  3.694894316535178e-02;   GW(21)  =  7.386423423217288e-02;
                GP(22)  = -3.694894316535178e-02;   GW(22)  =  7.386423423217288e-02;
                GP(23)  = -1.106450272085199e-01;   GW(23)  =  7.346081345346753e-02;
                GP(24)  = -1.837368065648546e-01;   GW(24)  =  7.265617524380410e-02;
                GP(25)  = -2.558250793428791e-01;   GW(25)  =  7.145471426517098e-02;
                GP(26)  = -3.265161244654115e-01;   GW(26)  =  6.986299249259416e-02;
                GP(27)  = -3.954238520429751e-01;   GW(27)  =  6.788970337652194e-02;
                GP(28)  = -4.621719120704219e-01;   GW(28)  =  6.554562436490898e-02;
                GP(29)  = -5.263957499311923e-01;   GW(29)  =  6.284355804500258e-02;
                GP(30)  = -5.877445974851093e-01;   GW(30)  =  5.979826222758665e-02;
                GP(31)  = -6.458833888692478e-01;   GW(31)  =  5.642636935801838e-02;
                GP(32)  = -7.004945905561712e-01;   GW(32)  =  5.274629569917407e-02;
                GP(33)  = -7.512799356894805e-01;   GW(33)  =  4.877814079280325e-02;
                GP(34)  = -7.979620532554874e-01;   GW(34)  =  4.454357777196588e-02;
                GP(35)  = -8.402859832618169e-01;   GW(35)  =  4.006573518069226e-02;
                GP(36)  = -8.780205698121727e-01;   GW(36)  =  3.536907109759211e-02;
                GP(37)  = -9.109597249041275e-01;   GW(37)  =  3.047924069960347e-02;
                GP(38)  = -9.389235573549882e-01;   GW(38)  =  2.542295952611305e-02;
                GP(39)  = -9.617593653382045e-01;   GW(39)  =  2.022786956905264e-02;
                GP(40)  = -9.793425080637482e-01;   GW(40)  =  1.492244369735749e-02;
                GP(41)  = -9.915772883408609e-01;   GW(41)  =  9.536220301748502e-03;
                GP(42)  = -9.983996189900624e-01;   GW(42)  =  4.105998604649085e-03;

            elseif(n==43)
                GP( 1)  =  9.984723322425077e-01;   GW( 1)  =  3.919490253844127e-03;
                GP( 2)  =  9.919595575932441e-01;   GW( 2)  =  9.103996637401403e-03;
                GP( 3)  =  9.802782209802553e-01;   GW( 3)  =  1.424875643157649e-02;
                GP( 4)  =  9.634866130140800e-01;   GW( 4)  =  1.931990142368390e-02;
                GP( 5)  =  9.416719568476379e-01;   GW( 5)  =  2.429045661383882e-02;
                GP( 6)  =  9.149479072061387e-01;   GW( 6)  =  2.913441326149849e-02;
                GP( 7)  =  8.834537652186169e-01;   GW( 7)  =  3.382649208686029e-02;
                GP( 8)  =  8.473537162093150e-01;   GW( 8)  =  3.834222219413266e-02;
                GP( 9)  =  8.068359641369386e-01;   GW( 9)  =  4.265805719798208e-02;
                GP(10)  =  7.621117471949551e-01;   GW(10)  =  4.675149475434658e-02;
                GP(11)  =  7.134142352689571e-01;   GW(11)  =  5.060119278439016e-02;
                GP(12)  =  6.609973137514981e-01;   GW(12)  =  5.418708031888179e-02;
                GP(13)  =  6.051342596396009e-01;   GW(13)  =  5.749046195691052e-02;
                GP(14)  =  5.461163166600847e-01;   GW(14)  =  6.049411524999129e-02;
                GP(15)  =  4.842511767857347e-01;   GW(15)  =  6.318238044939611e-02;
                GP(16)  =  4.198613760292693e-01;   GW(16)  =  6.554124212632280e-02;
                GP(17)  =  3.532826128643038e-01;   GW(17)  =  6.755840222936517e-02;
                GP(18)  =  2.848619980329136e-01;   GW(18)  =  6.922334419365668e-02;
                GP(19)  =  2.149562448605182e-01;   GW(19)  =  7.052738776508503e-02;
                GP(20)  =  1.439298095107133e-01;   GW(20)  =  7.146373425251414e-02;
                GP(21)  =  7.215299087458624e-02;   GW(21)  =  7.202750197142197e-02;
                GP(22)  = -0.000000000000000e-01;   GW(22)  =  7.221575169379899e-02;
                GP(23)  = -7.215299087458624e-02;   GW(23)  =  7.202750197142197e-02;
                GP(24)  = -1.439298095107133e-01;   GW(24)  =  7.146373425251414e-02;
                GP(25)  = -2.149562448605182e-01;   GW(25)  =  7.052738776508503e-02;
                GP(26)  = -2.848619980329136e-01;   GW(26)  =  6.922334419365668e-02;
                GP(27)  = -3.532826128643038e-01;   GW(27)  =  6.755840222936517e-02;
                GP(28)  = -4.198613760292693e-01;   GW(28)  =  6.554124212632280e-02;
                GP(29)  = -4.842511767857347e-01;   GW(29)  =  6.318238044939611e-02;
                GP(30)  = -5.461163166600847e-01;   GW(30)  =  6.049411524999129e-02;
                GP(31)  = -6.051342596396009e-01;   GW(31)  =  5.749046195691052e-02;
                GP(32)  = -6.609973137514981e-01;   GW(32)  =  5.418708031888179e-02;
                GP(33)  = -7.134142352689571e-01;   GW(33)  =  5.060119278439016e-02;
                GP(34)  = -7.621117471949551e-01;   GW(34)  =  4.675149475434658e-02;
                GP(35)  = -8.068359641369386e-01;   GW(35)  =  4.265805719798208e-02;
                GP(36)  = -8.473537162093150e-01;   GW(36)  =  3.834222219413266e-02;
                GP(37)  = -8.834537652186169e-01;   GW(37)  =  3.382649208686029e-02;
                GP(38)  = -9.149479072061387e-01;   GW(38)  =  2.913441326149849e-02;
                GP(39)  = -9.416719568476379e-01;   GW(39)  =  2.429045661383882e-02;
                GP(40)  = -9.634866130140800e-01;   GW(40)  =  1.931990142368390e-02;
                GP(41)  = -9.802782209802553e-01;   GW(41)  =  1.424875643157649e-02;
                GP(42)  = -9.919595575932441e-01;   GW(42)  =  9.103996637401403e-03;
                GP(43)  = -9.984723322425077e-01;   GW(43)  =  3.919490253844127e-03;

            elseif(n==44)
                GP( 1)  =  9.985402006367742e-01;   GW( 1)  =  3.745404803112778e-03;
                GP( 2)  =  9.923163921385158e-01;   GW( 2)  =  8.700481367524844e-03;
                GP( 3)  =  9.811518330779140e-01;   GW( 3)  =  1.361958675557999e-02;
                GP( 4)  =  9.650996504224931e-01;   GW( 4)  =  1.847148173681475e-02;
                GP( 5)  =  9.442395091181941e-01;   GW( 5)  =  2.323148190201921e-02;
                GP( 6)  =  9.186752599841758e-01;   GW( 6)  =  2.787578282128101e-02;
                GP( 7)  =  8.885342382860432e-01;   GW( 7)  =  3.238122281206982e-02;
                GP( 8)  =  8.539665950047104e-01;   GW( 8)  =  3.672534781380887e-02;
                GP( 9)  =  8.151445396451350e-01;   GW( 9)  =  4.088651231034622e-02;
                GP(10)  =  7.722614792487559e-01;   GW(10)  =  4.484398408197003e-02;
                GP(11)  =  7.255310536607170e-01;   GW(11)  =  4.857804644835204e-02;
                GP(12)  =  6.751860706661224e-01;   GW(12)  =  5.207009609170446e-02;
                GP(13)  =  6.214773459035758e-01;   GW(13)  =  5.530273556372805e-02;
                GP(14)  =  5.646724531854708e-01;   GW(14)  =  5.825985987759550e-02;
                GP(15)  =  5.050543913882023e-01;   GW(15)  =  6.092673670156197e-02;
                GP(16)  =  4.429201745254115e-01;   GW(16)  =  6.329007973320385e-02;
                GP(17)  =  3.785793520147071e-01;   GW(17)  =  6.533811487918143e-02;
                GP(18)  =  3.123524665027858e-01;   GW(18)  =  6.706063890629365e-02;
                GP(19)  =  2.445694569282013e-01;   GW(19)  =  6.844907026936666e-02;
                GP(20)  =  1.755680147755168e-01;   GW(20)  =  6.949649186157258e-02;
                GP(21)  =  1.056919017086532e-01;   GW(21)  =  7.019768547355821e-02;
                GP(22)  =  3.528923696413536e-02;   GW(22)  =  7.054915778935407e-02;
                GP(23)  = -3.528923696413536e-02;   GW(23)  =  7.054915778935407e-02;
                GP(24)  = -1.056919017086532e-01;   GW(24)  =  7.019768547355821e-02;
                GP(25)  = -1.755680147755168e-01;   GW(25)  =  6.949649186157258e-02;
                GP(26)  = -2.445694569282013e-01;   GW(26)  =  6.844907026936666e-02;
                GP(27)  = -3.123524665027858e-01;   GW(27)  =  6.706063890629365e-02;
                GP(28)  = -3.785793520147071e-01;   GW(28)  =  6.533811487918143e-02;
                GP(29)  = -4.429201745254115e-01;   GW(29)  =  6.329007973320385e-02;
                GP(30)  = -5.050543913882023e-01;   GW(30)  =  6.092673670156197e-02;
                GP(31)  = -5.646724531854708e-01;   GW(31)  =  5.825985987759550e-02;
                GP(32)  = -6.214773459035758e-01;   GW(32)  =  5.530273556372805e-02;
                GP(33)  = -6.751860706661224e-01;   GW(33)  =  5.207009609170446e-02;
                GP(34)  = -7.255310536607170e-01;   GW(34)  =  4.857804644835204e-02;
                GP(35)  = -7.722614792487559e-01;   GW(35)  =  4.484398408197003e-02;
                GP(36)  = -8.151445396451350e-01;   GW(36)  =  4.088651231034622e-02;
                GP(37)  = -8.539665950047104e-01;   GW(37)  =  3.672534781380887e-02;
                GP(38)  = -8.885342382860432e-01;   GW(38)  =  3.238122281206982e-02;
                GP(39)  = -9.186752599841758e-01;   GW(39)  =  2.787578282128101e-02;
                GP(40)  = -9.442395091181941e-01;   GW(40)  =  2.323148190201921e-02;
                GP(41)  = -9.650996504224931e-01;   GW(41)  =  1.847148173681475e-02;
                GP(42)  = -9.811518330779140e-01;   GW(42)  =  1.361958675557999e-02;
                GP(43)  = -9.923163921385158e-01;   GW(43)  =  8.700481367524844e-03;
                GP(44)  = -9.985402006367742e-01;   GW(44)  =  3.745404803112778e-03;

            elseif(n==45)
                GP( 1)  =  9.986036451819366e-01;   GW( 1)  =  3.582663155283559e-03;
                GP( 2)  =  9.926499984472037e-01;   GW( 2)  =  8.323189296218242e-03;
                GP( 3)  =  9.819687150345406e-01;   GW( 3)  =  1.303110499158278e-02;
                GP( 4)  =  9.666083103968946e-01;   GW( 4)  =  1.767753525793759e-02;
                GP( 5)  =  9.466416909956291e-01;   GW( 5)  =  2.223984755057873e-02;
                GP( 6)  =  9.221639367190004e-01;   GW( 6)  =  2.669621396757766e-02;
                GP( 7)  =  8.932916717532417e-01;   GW( 7)  =  3.102537493451547e-02;
                GP( 8)  =  8.601624759606642e-01;   GW( 8)  =  3.520669220160902e-02;
                GP( 9)  =  8.229342205020863e-01;   GW( 9)  =  3.922023672930245e-02;
                GP(10)  =  7.817843125939063e-01;   GW(10)  =  4.304688070916497e-02;
                GP(11)  =  7.369088489454904e-01;   GW(11)  =  4.666838771837337e-02;
                GP(12)  =  6.885216807712005e-01;   GW(12)  =  5.006749923795203e-02;
                GP(13)  =  6.368533944532234e-01;   GW(13)  =  5.322801673126895e-02;
                GP(14)  =  5.821502125693532e-01;   GW(14)  =  5.613487875978648e-02;
                GP(15)  =  5.246728204629161e-01;   GW(15)  =  5.877423271884174e-02;
                GP(16)  =  4.646951239196351e-01;   GW(16)  =  6.113350083106652e-02;
                GP(17)  =  4.025029438585419e-01;   GW(17)  =  6.320144007381994e-02;
                GP(18)  =  3.383926542506022e-01;   GW(18)  =  6.496819575072343e-02;
                GP(19)  =  2.726697697523776e-01;   GW(19)  =  6.642534844984253e-02;
                GP(20)  =  2.056474897832637e-01;   GW(20)  =  6.756595416360754e-02;
                GP(21)  =  1.376452059832530e-01;   GW(21)  =  6.838457737866967e-02;
                GP(22)  =  6.898698016314417e-02;   GW(22)  =  6.887731697766132e-02;
                GP(23)  = -0.000000000000000e-01;   GW(23)  =  6.904182482923202e-02;
                GP(24)  = -6.898698016314417e-02;   GW(24)  =  6.887731697766132e-02;
                GP(25)  = -1.376452059832530e-01;   GW(25)  =  6.838457737866967e-02;
                GP(26)  = -2.056474897832637e-01;   GW(26)  =  6.756595416360754e-02;
                GP(27)  = -2.726697697523776e-01;   GW(27)  =  6.642534844984253e-02;
                GP(28)  = -3.383926542506022e-01;   GW(28)  =  6.496819575072343e-02;
                GP(29)  = -4.025029438585419e-01;   GW(29)  =  6.320144007381994e-02;
                GP(30)  = -4.646951239196351e-01;   GW(30)  =  6.113350083106652e-02;
                GP(31)  = -5.246728204629161e-01;   GW(31)  =  5.877423271884174e-02;
                GP(32)  = -5.821502125693532e-01;   GW(32)  =  5.613487875978648e-02;
                GP(33)  = -6.368533944532234e-01;   GW(33)  =  5.322801673126895e-02;
                GP(34)  = -6.885216807712005e-01;   GW(34)  =  5.006749923795203e-02;
                GP(35)  = -7.369088489454904e-01;   GW(35)  =  4.666838771837337e-02;
                GP(36)  = -7.817843125939063e-01;   GW(36)  =  4.304688070916497e-02;
                GP(37)  = -8.229342205020863e-01;   GW(37)  =  3.922023672930245e-02;
                GP(38)  = -8.601624759606642e-01;   GW(38)  =  3.520669220160902e-02;
                GP(39)  = -8.932916717532417e-01;   GW(39)  =  3.102537493451547e-02;
                GP(40)  = -9.221639367190004e-01;   GW(40)  =  2.669621396757766e-02;
                GP(41)  = -9.466416909956291e-01;   GW(41)  =  2.223984755057873e-02;
                GP(42)  = -9.666083103968946e-01;   GW(42)  =  1.767753525793759e-02;
                GP(43)  = -9.819687150345406e-01;   GW(43)  =  1.303110499158278e-02;
                GP(44)  = -9.926499984472037e-01;   GW(44)  =  8.323189296218242e-03;
                GP(45)  = -9.986036451819366e-01;   GW(45)  =  3.582663155283559e-03;

            elseif(n==46)
                GP( 1)  =  9.986630421338180e-01;   GW( 1)  =  3.430300868107048e-03;
                GP( 2)  =  9.929623489061744e-01;   GW( 2)  =  7.969898229724622e-03;
                GP( 3)  =  9.827336698041669e-01;   GW( 3)  =  1.247988377098868e-02;
                GP( 4)  =  9.680213918539919e-01;   GW( 4)  =  1.693351400783624e-02;
                GP( 5)  =  9.488923634460898e-01;   GW( 5)  =  2.130999875413650e-02;
                GP( 6)  =  9.254337988067540e-01;   GW( 6)  =  2.558928639713001e-02;
                GP( 7)  =  8.977527115339420e-01;   GW( 7)  =  2.975182955220276e-02;
                GP( 8)  =  8.659753948668581e-01;   GW( 8)  =  3.377862799910690e-02;
                GP( 9)  =  8.302468370660661e-01;   GW( 9)  =  3.765130535738607e-02;
                GP(10)  =  7.907300570752743e-01;   GW(10)  =  4.135219010967873e-02;
                GP(11)  =  7.476053596156661e-01;   GW(11)  =  4.486439527731813e-02;
                GP(12)  =  7.010695120204057e-01;   GW(12)  =  4.817189510171220e-02;
                GP(13)  =  6.513348462019977e-01;   GW(13)  =  5.125959800714302e-02;
                GP(14)  =  5.986282897127152e-01;   GW(14)  =  5.411341538585675e-02;
                GP(15)  =  5.431903302618026e-01;   GW(15)  =  5.672032584399124e-02;
                GP(16)  =  4.852739183881647e-01;   GW(16)  =  5.906843459554631e-02;
                GP(17)  =  4.251433132828284e-01;   GW(17)  =  6.114702772465048e-02;
                GP(18)  =  3.630728770209957e-01;   GW(18)  =  6.294662106439451e-02;
                GP(19)  =  2.993458227018700e-01;   GW(19)  =  6.445900346713907e-02;
                GP(20)  =  2.342529222062698e-01;   GW(20)  =  6.567727426778121e-02;
                GP(21)  =  1.680911794671035e-01;   GW(21)  =  6.659587476845489e-02;
                GP(22)  =  1.011624753055842e-01;   GW(22)  =  6.721061360067818e-02;
                GP(23)  =  3.377219001605204e-02;   GW(23)  =  6.751868584903646e-02;
                GP(24)  = -3.377219001605204e-02;   GW(24)  =  6.751868584903646e-02;
                GP(25)  = -1.011624753055842e-01;   GW(25)  =  6.721061360067818e-02;
                GP(26)  = -1.680911794671035e-01;   GW(26)  =  6.659587476845489e-02;
                GP(27)  = -2.342529222062698e-01;   GW(27)  =  6.567727426778121e-02;
                GP(28)  = -2.993458227018700e-01;   GW(28)  =  6.445900346713907e-02;
                GP(29)  = -3.630728770209957e-01;   GW(29)  =  6.294662106439451e-02;
                GP(30)  = -4.251433132828284e-01;   GW(30)  =  6.114702772465048e-02;
                GP(31)  = -4.852739183881647e-01;   GW(31)  =  5.906843459554631e-02;
                GP(32)  = -5.431903302618026e-01;   GW(32)  =  5.672032584399124e-02;
                GP(33)  = -5.986282897127152e-01;   GW(33)  =  5.411341538585675e-02;
                GP(34)  = -6.513348462019977e-01;   GW(34)  =  5.125959800714302e-02;
                GP(35)  = -7.010695120204057e-01;   GW(35)  =  4.817189510171220e-02;
                GP(36)  = -7.476053596156661e-01;   GW(36)  =  4.486439527731813e-02;
                GP(37)  = -7.907300570752743e-01;   GW(37)  =  4.135219010967873e-02;
                GP(38)  = -8.302468370660661e-01;   GW(38)  =  3.765130535738607e-02;
                GP(39)  = -8.659753948668581e-01;   GW(39)  =  3.377862799910690e-02;
                GP(40)  = -8.977527115339420e-01;   GW(40)  =  2.975182955220276e-02;
                GP(41)  = -9.254337988067540e-01;   GW(41)  =  2.558928639713001e-02;
                GP(42)  = -9.488923634460898e-01;   GW(42)  =  2.130999875413650e-02;
                GP(43)  = -9.680213918539919e-01;   GW(43)  =  1.693351400783624e-02;
                GP(44)  = -9.827336698041669e-01;   GW(44)  =  1.247988377098868e-02;
                GP(45)  = -9.929623489061744e-01;   GW(45)  =  7.969898229724622e-03;
                GP(46)  = -9.986630421338180e-01;   GW(46)  =  3.430300868107048e-03;

            elseif(n==47)
                GP( 1)  =  9.987187285842121e-01;   GW( 1)  =  3.287453842528015e-03;
                GP( 2)  =  9.932552109877686e-01;   GW( 2)  =  7.638616295848834e-03;
                GP( 3)  =  9.834510030716237e-01;   GW( 3)  =  1.196284846431232e-02;
                GP( 4)  =  9.693467873265645e-01;   GW( 4)  =  1.623533314643306e-02;
                GP( 5)  =  9.510039692577084e-01;   GW( 5)  =  2.043693814766843e-02;
                GP( 6)  =  9.285026930123606e-01;   GW( 6)  =  2.454921165965882e-02;
                GP( 7)  =  9.019413294385254e-01;   GW( 7)  =  2.855415070064339e-02;
                GP( 8)  =  8.714360157968963e-01;   GW( 8)  =  3.243423551518476e-02;
                GP( 9)  =  8.371201398999021e-01;   GW( 9)  =  3.617249658417495e-02;
                GP(10)  =  7.991437541677419e-01;   GW(10)  =  3.975258612253100e-02;
                GP(11)  =  7.576729184454386e-01;   GW(11)  =  4.315884864847954e-02;
                GP(12)  =  7.128889734090643e-01;   GW(12)  =  4.637638908650591e-02;
                GP(13)  =  6.649877473903327e-01;   GW(13)  =  4.939113774736117e-02;
                GP(14)  =  6.141786999563736e-01;   GW(14)  =  5.218991178005714e-02;
                GP(15)  =  5.606840059346642e-01;   GW(15)  =  5.476047278153023e-02;
                GP(16)  =  5.047375838635779e-01;   GW(16)  =  5.709158029323154e-02;
                GP(17)  =  4.465840731048557e-01;   GW(17)  =  5.917304094233888e-02;
                GP(18)  =  3.864777640846671e-01;   GW(18)  =  6.099575300873965e-02;
                GP(19)  =  3.246814863377359e-01;   GW(19)  =  6.255174622092166e-02;
                GP(20)  =  2.614654592149746e-01;   GW(20)  =  6.383421660571703e-02;
                GP(21)  =  1.971061102791118e-01;   GW(21)  =  6.483755623894573e-02;
                GP(22)  =  1.318848665545149e-01;   GW(22)  =  6.555737776654974e-02;
                GP(23)  =  6.608692391635568e-02;   GW(23)  =  6.599053358881047e-02;
                GP(24)  = -0.000000000000000e-01;   GW(24)  =  6.613512962365548e-02;
                GP(25)  = -6.608692391635568e-02;   GW(25)  =  6.599053358881047e-02;
                GP(26)  = -1.318848665545149e-01;   GW(26)  =  6.555737776654974e-02;
                GP(27)  = -1.971061102791118e-01;   GW(27)  =  6.483755623894573e-02;
                GP(28)  = -2.614654592149746e-01;   GW(28)  =  6.383421660571703e-02;
                GP(29)  = -3.246814863377359e-01;   GW(29)  =  6.255174622092166e-02;
                GP(30)  = -3.864777640846671e-01;   GW(30)  =  6.099575300873965e-02;
                GP(31)  = -4.465840731048557e-01;   GW(31)  =  5.917304094233888e-02;
                GP(32)  = -5.047375838635779e-01;   GW(32)  =  5.709158029323154e-02;
                GP(33)  = -5.606840059346642e-01;   GW(33)  =  5.476047278153023e-02;
                GP(34)  = -6.141786999563736e-01;   GW(34)  =  5.218991178005714e-02;
                GP(35)  = -6.649877473903327e-01;   GW(35)  =  4.939113774736117e-02;
                GP(36)  = -7.128889734090643e-01;   GW(36)  =  4.637638908650591e-02;
                GP(37)  = -7.576729184454386e-01;   GW(37)  =  4.315884864847954e-02;
                GP(38)  = -7.991437541677419e-01;   GW(38)  =  3.975258612253100e-02;
                GP(39)  = -8.371201398999021e-01;   GW(39)  =  3.617249658417495e-02;
                GP(40)  = -8.714360157968963e-01;   GW(40)  =  3.243423551518476e-02;
                GP(41)  = -9.019413294385254e-01;   GW(41)  =  2.855415070064339e-02;
                GP(42)  = -9.285026930123606e-01;   GW(42)  =  2.454921165965882e-02;
                GP(43)  = -9.510039692577084e-01;   GW(43)  =  2.043693814766843e-02;
                GP(44)  = -9.693467873265645e-01;   GW(44)  =  1.623533314643306e-02;
                GP(45)  = -9.834510030716237e-01;   GW(45)  =  1.196284846431232e-02;
                GP(46)  = -9.932552109877686e-01;   GW(46)  =  7.638616295848834e-03;
                GP(47)  = -9.987187285842121e-01;   GW(47)  =  3.287453842528015e-03;

            elseif(n==48)
                GP( 1)  =  9.987710072524261e-01;   GW( 1)  =  3.153346052305839e-03;
                GP( 2)  =  9.935301722663508e-01;   GW( 2)  =  7.327553901276262e-03;
                GP( 3)  =  9.841245837228269e-01;   GW( 3)  =  1.147723457923454e-02;
                GP( 4)  =  9.705915925462473e-01;   GW( 4)  =  1.557931572294385e-02;
                GP( 5)  =  9.529877031604309e-01;   GW( 5)  =  1.961616045735553e-02;
                GP( 6)  =  9.313866907065543e-01;   GW( 6)  =  2.357076083932438e-02;
                GP( 7)  =  9.058791367155697e-01;   GW( 7)  =  2.742650970835695e-02;
                GP( 8)  =  8.765720202742479e-01;   GW( 8)  =  3.116722783279809e-02;
                GP( 9)  =  8.435882616243935e-01;   GW( 9)  =  3.477722256477044e-02;
                GP(10)  =  8.070662040294426e-01;   GW(10)  =  3.824135106583071e-02;
                GP(11)  =  7.671590325157403e-01;   GW(11)  =  4.154508294346475e-02;
                GP(12)  =  7.240341309238147e-01;   GW(12)  =  4.467456085669428e-02;
                GP(13)  =  6.778723796326639e-01;   GW(13)  =  4.761665849249047e-02;
                GP(14)  =  6.288673967765136e-01;   GW(14)  =  5.035903555385447e-02;
                GP(15)  =  5.772247260839727e-01;   GW(15)  =  5.289018948519367e-02;
                GP(16)  =  5.231609747222330e-01;   GW(16)  =  5.519950369998416e-02;
                GP(17)  =  4.669029047509584e-01;   GW(17)  =  5.727729210040322e-02;
                GP(18)  =  4.086864819907167e-01;   GW(18)  =  5.911483969839564e-02;
                GP(19)  =  3.487558862921607e-01;   GW(19)  =  6.070443916589388e-02;
                GP(20)  =  2.873624873554556e-01;   GW(20)  =  6.203942315989266e-02;
                GP(21)  =  2.247637903946891e-01;   GW(21)  =  6.311419228625403e-02;
                GP(22)  =  1.612223560688917e-01;   GW(22)  =  6.392423858464819e-02;
                GP(23)  =  9.700469920946270e-02;   GW(23)  =  6.446616443595008e-02;
                GP(24)  =  3.238017096286936e-02;   GW(24)  =  6.473769681268392e-02;
                GP(25)  = -3.238017096286936e-02;   GW(25)  =  6.473769681268392e-02;
                GP(26)  = -9.700469920946270e-02;   GW(26)  =  6.446616443595008e-02;
                GP(27)  = -1.612223560688917e-01;   GW(27)  =  6.392423858464819e-02;
                GP(28)  = -2.247637903946891e-01;   GW(28)  =  6.311419228625403e-02;
                GP(29)  = -2.873624873554556e-01;   GW(29)  =  6.203942315989266e-02;
                GP(30)  = -3.487558862921607e-01;   GW(30)  =  6.070443916589388e-02;
                GP(31)  = -4.086864819907167e-01;   GW(31)  =  5.911483969839564e-02;
                GP(32)  = -4.669029047509584e-01;   GW(32)  =  5.727729210040322e-02;
                GP(33)  = -5.231609747222330e-01;   GW(33)  =  5.519950369998416e-02;
                GP(34)  = -5.772247260839727e-01;   GW(34)  =  5.289018948519367e-02;
                GP(35)  = -6.288673967765136e-01;   GW(35)  =  5.035903555385447e-02;
                GP(36)  = -6.778723796326639e-01;   GW(36)  =  4.761665849249047e-02;
                GP(37)  = -7.240341309238147e-01;   GW(37)  =  4.467456085669428e-02;
                GP(38)  = -7.671590325157403e-01;   GW(38)  =  4.154508294346475e-02;
                GP(39)  = -8.070662040294426e-01;   GW(39)  =  3.824135106583071e-02;
                GP(40)  = -8.435882616243935e-01;   GW(40)  =  3.477722256477044e-02;
                GP(41)  = -8.765720202742479e-01;   GW(41)  =  3.116722783279809e-02;
                GP(42)  = -9.058791367155697e-01;   GW(42)  =  2.742650970835695e-02;
                GP(43)  = -9.313866907065543e-01;   GW(43)  =  2.357076083932438e-02;
                GP(44)  = -9.529877031604309e-01;   GW(44)  =  1.961616045735553e-02;
                GP(45)  = -9.705915925462473e-01;   GW(45)  =  1.557931572294385e-02;
                GP(46)  = -9.841245837228269e-01;   GW(46)  =  1.147723457923454e-02;
                GP(47)  = -9.935301722663508e-01;   GW(47)  =  7.327553901276262e-03;
                GP(48)  = -9.987710072524261e-01;   GW(48)  =  3.153346052305839e-03;

            elseif(n==49)
                GP( 1)  =  9.988201506066354e-01;   GW( 1)  =  3.027278988922905e-03;
                GP( 2)  =  9.937886619441678e-01;   GW( 2)  =  7.035099590086451e-03;
                GP( 3)  =  9.847578959142130e-01;   GW( 3)  =  1.102055103159358e-02;
                GP( 4)  =  9.717622009015554e-01;   GW( 4)  =  1.496214493562465e-02;
                GP( 5)  =  9.548536586741372e-01;   GW( 5)  =  1.884359585308946e-02;
                GP( 6)  =  9.341002947558101e-01;   GW( 6)  =  2.264920158744668e-02;
                GP( 7)  =  9.095856558280733e-01;   GW( 7)  =  2.636361892706602e-02;
                GP( 8)  =  8.814084455730089e-01;   GW( 8)  =  2.997188462058383e-02;
                GP( 9)  =  8.496821198441657e-01;   GW( 9)  =  3.345946679162217e-02;
                GP(10)  =  8.145344273598554e-01;   GW(10)  =  3.681232096300069e-02;
                GP(11)  =  7.761068943454466e-01;   GW(11)  =  4.001694576637302e-02;
                GP(12)  =  7.345542542374027e-01;   GW(12)  =  4.306043698125960e-02;
                GP(13)  =  6.900438244251321e-01;   GW(13)  =  4.593053935559585e-02;
                GP(14)  =  6.427548324192377e-01;   GW(14)  =  4.861569588782824e-02;
                GP(15)  =  5.928776941089007e-01;   GW(15)  =  5.110509433014459e-02;
                GP(16)  =  5.406132469917261e-01;   GW(16)  =  5.338871070825897e-02;
                GP(17)  =  4.861719414524920e-01;   GW(17)  =  5.545734967480359e-02;
                GP(18)  =  4.297729933415765e-01;   GW(18)  =  5.730268153018748e-02;
                GP(19)  =  3.716435012622849e-01;   GW(19)  =  5.891727576002727e-02;
                GP(20)  =  3.120175321197488e-01;   GW(20)  =  6.029463095315202e-02;
                GP(21)  =  2.511351786125773e-01;   GW(21)  =  6.142920097919294e-02;
                GP(22)  =  1.892415924618136e-01;   GW(22)  =  6.231641732005727e-02;
                GP(23)  =  1.265859972696721e-01;   GW(23)  =  6.295270746519570e-02;
                GP(24)  =  6.342068498268679e-02;   GW(24)  =  6.333550929649175e-02;
                GP(25)  = -0.000000000000000e-01;   GW(25)  =  6.346328140479060e-02;
                GP(26)  = -6.342068498268679e-02;   GW(26)  =  6.333550929649175e-02;
                GP(27)  = -1.265859972696721e-01;   GW(27)  =  6.295270746519570e-02;
                GP(28)  = -1.892415924618136e-01;   GW(28)  =  6.231641732005727e-02;
                GP(29)  = -2.511351786125773e-01;   GW(29)  =  6.142920097919294e-02;
                GP(30)  = -3.120175321197488e-01;   GW(30)  =  6.029463095315202e-02;
                GP(31)  = -3.716435012622849e-01;   GW(31)  =  5.891727576002727e-02;
                GP(32)  = -4.297729933415765e-01;   GW(32)  =  5.730268153018748e-02;
                GP(33)  = -4.861719414524920e-01;   GW(33)  =  5.545734967480359e-02;
                GP(34)  = -5.406132469917261e-01;   GW(34)  =  5.338871070825897e-02;
                GP(35)  = -5.928776941089007e-01;   GW(35)  =  5.110509433014459e-02;
                GP(36)  = -6.427548324192377e-01;   GW(36)  =  4.861569588782824e-02;
                GP(37)  = -6.900438244251321e-01;   GW(37)  =  4.593053935559585e-02;
                GP(38)  = -7.345542542374027e-01;   GW(38)  =  4.306043698125960e-02;
                GP(39)  = -7.761068943454466e-01;   GW(39)  =  4.001694576637302e-02;
                GP(40)  = -8.145344273598554e-01;   GW(40)  =  3.681232096300069e-02;
                GP(41)  = -8.496821198441657e-01;   GW(41)  =  3.345946679162217e-02;
                GP(42)  = -8.814084455730089e-01;   GW(42)  =  2.997188462058383e-02;
                GP(43)  = -9.095856558280733e-01;   GW(43)  =  2.636361892706602e-02;
                GP(44)  = -9.341002947558101e-01;   GW(44)  =  2.264920158744668e-02;
                GP(45)  = -9.548536586741372e-01;   GW(45)  =  1.884359585308946e-02;
                GP(46)  = -9.717622009015554e-01;   GW(46)  =  1.496214493562465e-02;
                GP(47)  = -9.847578959142130e-01;   GW(47)  =  1.102055103159358e-02;
                GP(48)  = -9.937886619441678e-01;   GW(48)  =  7.035099590086451e-03;
                GP(49)  = -9.988201506066354e-01;   GW(49)  =  3.027278988922905e-03;

            elseif(n==50)
                GP( 1)  =  9.988664044200711e-01;   GW( 1)  =  2.908622553155141e-03;
                GP( 2)  =  9.940319694320907e-01;   GW( 2)  =  6.759799195745402e-03;
                GP( 3)  =  9.853540840480059e-01;   GW( 3)  =  1.059054838365097e-02;
                GP( 4)  =  9.728643851066921e-01;   GW( 4)  =  1.438082276148557e-02;
                GP( 5)  =  9.566109552428079e-01;   GW( 5)  =  1.811556071348939e-02;
                GP( 6)  =  9.366566189448779e-01;   GW( 6)  =  2.178024317012479e-02;
                GP( 7)  =  9.130785566557919e-01;   GW( 7)  =  2.536067357001239e-02;
                GP( 8)  =  8.859679795236130e-01;   GW( 8)  =  2.884299358053520e-02;
                GP( 9)  =  8.554297694299461e-01;   GW( 9)  =  3.221372822357802e-02;
                GP(10)  =  8.215820708593359e-01;   GW(10)  =  3.545983561514615e-02;
                GP(11)  =  7.845558329003993e-01;   GW(11)  =  3.856875661258768e-02;
                GP(12)  =  7.444943022260685e-01;   GW(12)  =  4.152846309014770e-02;
                GP(13)  =  7.015524687068223e-01;   GW(13)  =  4.432750433880328e-02;
                GP(14)  =  6.558964656854394e-01;   GW(14)  =  4.695505130394843e-02;
                GP(15)  =  6.077029271849502e-01;   GW(15)  =  4.940093844946631e-02;
                GP(16)  =  5.571583045146501e-01;   GW(16)  =  5.165570306958114e-02;
                GP(17)  =  5.044581449074642e-01;   GW(17)  =  5.371062188899625e-02;
                GP(18)  =  4.498063349740388e-01;   GW(18)  =  5.555774480621252e-02;
                GP(19)  =  3.934143118975651e-01;   GW(19)  =  5.718992564772838e-02;
                GP(20)  =  3.355002454194374e-01;   GW(20)  =  5.860084981322245e-02;
                GP(21)  =  2.762881937795320e-01;   GW(21)  =  5.978505870426546e-02;
                GP(22)  =  2.160072368760418e-01;   GW(22)  =  6.073797084177022e-02;
                GP(23)  =  1.548905899981459e-01;   GW(23)  =  6.145589959031666e-02;
                GP(24)  =  9.317470156008614e-02;   GW(24)  =  6.193606742068324e-02;
                GP(25)  =  3.109833832718888e-02;   GW(25)  =  6.217661665534726e-02;
                GP(26)  = -3.109833832718888e-02;   GW(26)  =  6.217661665534726e-02;
                GP(27)  = -9.317470156008614e-02;   GW(27)  =  6.193606742068324e-02;
                GP(28)  = -1.548905899981459e-01;   GW(28)  =  6.145589959031666e-02;
                GP(29)  = -2.160072368760418e-01;   GW(29)  =  6.073797084177022e-02;
                GP(30)  = -2.762881937795320e-01;   GW(30)  =  5.978505870426546e-02;
                GP(31)  = -3.355002454194374e-01;   GW(31)  =  5.860084981322245e-02;
                GP(32)  = -3.934143118975651e-01;   GW(32)  =  5.718992564772838e-02;
                GP(33)  = -4.498063349740388e-01;   GW(33)  =  5.555774480621252e-02;
                GP(34)  = -5.044581449074642e-01;   GW(34)  =  5.371062188899625e-02;
                GP(35)  = -5.571583045146501e-01;   GW(35)  =  5.165570306958114e-02;
                GP(36)  = -6.077029271849502e-01;   GW(36)  =  4.940093844946631e-02;
                GP(37)  = -6.558964656854394e-01;   GW(37)  =  4.695505130394843e-02;
                GP(38)  = -7.015524687068223e-01;   GW(38)  =  4.432750433880328e-02;
                GP(39)  = -7.444943022260685e-01;   GW(39)  =  4.152846309014770e-02;
                GP(40)  = -7.845558329003993e-01;   GW(40)  =  3.856875661258768e-02;
                GP(41)  = -8.215820708593359e-01;   GW(41)  =  3.545983561514615e-02;
                GP(42)  = -8.554297694299461e-01;   GW(42)  =  3.221372822357802e-02;
                GP(43)  = -8.859679795236130e-01;   GW(43)  =  2.884299358053520e-02;
                GP(44)  = -9.130785566557919e-01;   GW(44)  =  2.536067357001239e-02;
                GP(45)  = -9.366566189448779e-01;   GW(45)  =  2.178024317012479e-02;
                GP(46)  = -9.566109552428079e-01;   GW(46)  =  1.811556071348939e-02;
                GP(47)  = -9.728643851066921e-01;   GW(47)  =  1.438082276148557e-02;
                GP(48)  = -9.853540840480059e-01;   GW(48)  =  1.059054838365097e-02;
                GP(49)  = -9.940319694320907e-01;   GW(49)  =  6.759799195745402e-03;
                GP(50)  = -9.988664044200711e-01;   GW(50)  =  2.908622553155141e-03;
            end

        end


        function [G1, G2] = computeBaseVectorAndNormalToNURBSCurve2D ...
                (app,knotSpan, p, CP, dR)


            %% Function main body

            %% 1. Compute the base vector G1
            G1 = zeros(2,1);
            for b = 0:p
                for a = 1:2
                    G1(a,1) = dR(b+1,2)*CP(knotSpan-p+b,a) + G1(a,1);
                end
            end

            %% 2. Compute the not normalized normal to the 2D curve vector G2
            G2 = zeros(2,1) ;
            G2(1) = - G1(2) ;
            G2(2) = G1(1) ;

        end


        function [F, tanMtxLoad] = ...
                computeLoadVctLinePressureVectorForIGATimoshenkoBeam2D ...
                (app,Fold, BSplinePatch, xib, etab, loadFnc, loadDir, isFollower, t, ...
                int, outMsg)

            arguments
                app
                Fold
                BSplinePatch
                xib
                etab
                loadFnc
                loadDir
                isFollower
                t
                int
                outMsg

            end
            % Get the computational information from the patch
            p = BSplinePatch.p;
            Xi = BSplinePatch.Xi;
            CP = BSplinePatch.CP;
            isNURBS = BSplinePatch.isNURBS;

            size(CP)
            disp('In load pressure Timoshenko')
            fprintf('the knot vector here is : %s \n', num2str(Xi))

            % Initialize the load vector
            F = zeros(size(CP, 1)*3, 1);

            % Initialize dummy output
            tanMtxLoad = 'undefined';

            % Number of Control Points
            nxi = length(CP(:, 1));

            % choose number of gauss points
            if strcmp(int.type, 'default')
                ngauss = ceil (0.5*(p + 1));
            elseif strcmp(int.type, 'user')
                ngauss = int.ngaussLoad;
            end

            % Get start end end point for th integration
            j1 = app.findKnotSpan(xib(1), Xi, nxi);
            j2 = app.findKnotSpan(xib(2), Xi, nxi);

            % Get the Gauss Points and their coordinates
            [GP, GW] = app.getGaussPointsAndWeightsOverUnitDomain(ngauss);

            %% 1. Loop over all elements
            for i = j1:j2
                % check if we are in a non-zero element
                if Xi(i + 1) - Xi(i) ~= 0
                    %% 1i. Loop over all the quadrature points
                    for j = 1:length(GP)
                        %% 1i.1. Transformation from unit interval to the knot span
                        xi = ((1 - GP(j))*Xi(i) + (1 + GP(j))*Xi(i + 1))/2;
                        xiSpan = app.findKnotSpan(xi, Xi, nxi);

                        %% 1i.2. Compute the NURBS basis functions and their derivatives
                        numDrv = 2;
                        dR = app.computeNURBSBasisFunctions1d...
                            (Xi,p, xi, Xi, transpose(CP(:,4)),numDrv);

                        %% 1i.3. Compute the base vectors on the curve
                        [A1, A2] = app.computeBaseVectorAndNormalToNURBSCurve2D ...
                            (xiSpan, p, CP, dR);

                        %% 1i.4. Compute the length of the normal vector and the normal to the 2D curve vector
                        L = norm(A1);
                        n = A2/norm(A2);
                        t = A1/L;

                        %% 1i.5. Compute and assemble the components of the global load vector
                        for v = 1:p + 1
                            index1 = 1 + (xiSpan - p - 1)*2 + (v - 1)*2;
                            index2 = 2 + (xiSpan - p - 1)*2 + (v - 1)*2;
                            indexTim1 = index1 + (xiSpan - p - 1) + v - 1;
                            indexTim2 = index2 + (xiSpan - p - 1) + v - 1;
                            if loadDir == 1
                                F(index1, 1) = F(index1,1) + ...
                                    loadFnc*dR(v, 1)*t(1, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                                F(index2, 1) = F(index2, 1) + ...
                                    loadFnc*dR(v, 1)*t(2, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                            elseif loadDir == 2
                                F(indexTim1, 1) = F(indexTim1, 1) + ...
                                    loadFnc*dR(v, 1)*n(1, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                                F(indexTim2, 1) = F(indexTim2, 1) + ...
                                    loadFnc*dR(v, 1)*n(2, 1)*(Xi(i + 1) - Xi(i))/2.0*L*GW(j);
                            else
                                error('Direction %d is not implemented for the loading of a 2D isogeometric Timoshenko beam', loadDir);
                            end
                        end
                    end
                end
            end

            if isvector(Fold)
                F = F + Fold;
            end


        end


        function index = plot_referenceConfigurationIGABeams ...
                (app,p, Xi, CP, isNURBS, homDOFs, F, analysis, graph, outMsg)

            nEval = 49;
            % Assign an index to the figure
            %figure(graph.index)

            %% 1. Create the Cartesian coordinates of the points on the curve
            [Xp,Yp,Zp] = app.createBSplineCurveOnCartesianSpace(p,Xi,CP,isNURBS,nEval);

            %% 2. Create the supports and the force arrows
            if strcmp(analysis.type,'Bernoulli')
                [xs,ys,zs] = app.createSupportsForIGABernoulliBeam2D(CP,homDOFs);
                [xf,yf,zf] = app.createForceArrowsForIGABernoulliBeam2D(CP,F);
            elseif strcmp(analysis.type,'Timoshenko')
                [xs,ys,zs] = app.createSupportsForIGATimoshenkoBeam2D(CP,homDOFs);
                [xf,yf,zf] = app.createForceArrowsForIGATimoshenkoBeam2D(CP,F);
            end
            %% 3. Plot the reference geometry
            plot3(app.UIAxes2_3,Xp,Yp,Zp,'Color','black');
            hold(app.UIAxes2_3,'on');

            %% 4. Plot the knots on the geometry
            app.plot_knotsForBSplineCurveOnCartesianSpace(app.UIAxes2_3,p,Xi,CP,isNURBS);
            %% 5. Plot the supports on the geometry
            for k =1:length(xs(:,1))
                plot3(app.UIAxes2_3,xs(k,:),ys(k,:),zs(k,:),'Linewidth',2,'Color','black');
            end
            %% 6. Plot the load arrows on the geometry
            for k =1:length(xf(:,1))
                plot3(app.UIAxes2_3,xf(k,:),yf(k,:),zf(k,:),'Color','blue','Linewidth',5);
                plot3(app.UIAxes2_3,xf(k,1),yf(k,1),zf(k,1),'Marker','d','MarkerFaceColor','blue','MarkerSize',5);
            end
            %% 7. Plot the Control Points and the Control Polygon
            app.plot_ControlPolygonBSplineCurve(app.UIAxes2_3,CP)

            % Title of the figure
            if strcmp(analysis.type,'Bernoulli')
                title(app.UIAxes2_3,'Reference configuration-Bernoulli beam');
            elseif strcmp(analysis.type,'Timoshenko')
                title(app.UIAxes2_3,'Reference configuration-Timoshenko beam');
            end

            %% 8. Define plotting properties
            axis(app.UIAxes2_3, 'equal');
            view(app.UIAxes2_3, 2);
            % Set lighting properties
            camlight(app.UIAxes2_3, 'left');
            lighting(app.UIAxes2_3, 'phong');
            % Set labels
            xlabel(app.UIAxes2_3, 'X', 'FontSize', 14);
            ylabel(app.UIAxes2_3, 'Y', 'FontSize', 14);
            zlabel(app.UIAxes2_3, 'Z', 'FontSize', 14);
            %ylim(app.UIAxes2_3,[-50,500])
            %xlim(app.UIAxes2_3,[-50,1100])
            hold(app.UIAxes2_3, 'off');

            index=2;

        end


        function [X, Y, Z] = createBSplineCurveOnCartesianSpace ...
                (app,p, Xi, CP, isNURBS, numEval)

            mxi = length(Xi);
            % The number of base vectors
            nxi = length(CP(:,1));

            % Compute an increment
            dxi = (Xi(length(Xi)) - Xi(1))/(numEval-1);

            % Initialize output array
            XYZ = zeros(numEval,3);

            % points in u-direction
            xi = Xi(p+1);

            %% 1. Loop over all the parametric locations
            for j=1:numEval
                %% 1i. Find the span index where xi is located
                spandIndex = app.findKnotSpan(xi,Xi,nxi);

                %% 1ii. Compute the basis functions at xi
                nDeriv = 0;
                R = app.computeNURBSBasisFunctions1d...
                    (Xi,p,xi,transpose(CP(:,4)),nDeriv);
                %% 1iii. Compute the Cartesian coordinates of xi
                XYZ(j,1:3) = app.computeCartesianCoordinatesOfAPointOnBSplineCurve...
                    (p,spandIndex,xi,Xi,CP,transpose(R));

                %% 1iv. Update the parametric location of the point
                xi = xi + dxi;
            end

            %% 2. Write the output
            X = XYZ(:,1);
            Y = XYZ(:,2);
            Z = XYZ(:,3);

        end


        function X = computeCartesianCoordinatesOfAPointOnBSplineCurve ...
                (app,p, knotSpanIndex, xi, Xi, CP, R)

            % Find the correct knot span
            if knotSpanIndex == 0
                knotSpanIndex = app.findspan(xi,Xi,length(CP(:,1)));
            end

            % Initialize the point coordinates
            X(1) = 0;
            X(2) = 0;
            X(3) = 0;

            % Compute the point coordinates iteratively
            for b = 0:p
                X(1) = R(b+1,1)*CP(knotSpanIndex-p+b,1) + X(1);
                X(2) = R(b+1,1)*CP(knotSpanIndex-p+b,2) + X(2);
                X(3) = R(b+1,1)*CP(knotSpanIndex-p+b,3) + X(3);
            end

        end


        function [xs, ys, zs] = createSupportsForIGABernoulliBeam2D ...
                (app,CP, rb)

            nu = length(CP(:,1));

            % scaling factors for the support triangles
            maximum = max(max(max(max(CP))));
            minimum = min(min(min(min(CP))));

            % Average the factor with respect to the maximum and minimum values
            factor = (maximum-minimum)/5;

            % Initialize the output arrays
            xs = zeros(length(rb),4);
            ys = zeros(length(rb),4);
            zs = zeros(length(rb),4);

            for k = 1:length(rb)
                % Get the corresponding Control Point number p and indices CP(i,j)
                h=rb(k)/2;
                p=ceil(h);
                j=ceil(p/nu);
                i=p-(j-1)*nu;

                %(rb is odd -> horizontal support)
                if (p~=h)
                    xs(k,1)=CP(i,1);
                    xs(k,2)=CP(i,1)-0.1732*factor;
                    xs(k,3)=CP(i,1)-0.1732*factor;
                    xs(k,4)=xs(k,1);
                    ys(k,1)=CP(i,2);
                    ys(k,2)=CP(i,2)+0.1*factor;
                    ys(k,3)=CP(i,2)-0.1*factor;
                    ys(k,4)=ys(k,1);
                    zs(k,1:4)=CP(i,3);
                    %(rb is even -> vertical support)
                else
                    xs(k,1)=CP(i,1);
                    xs(k,2)=CP(i,1)-0.1*factor;
                    xs(k,3)=CP(i,1)+0.1*factor;
                    xs(k,4)=xs(k,1);
                    ys(k,1)=CP(i,2);
                    ys(k,2)=CP(i,2)-0.1732*factor;
                    ys(k,3)=CP(i,2)-0.1732*factor;
                    ys(k,4)=ys(k,1);
                    zs(k,1:4)=CP(i,3);
                end
            end

        end


        function [xf, yf, zf] = createForceArrowsForIGABernoulliBeam2D ...
                (app,CP, F)
            nf = sum(F~=0);
            xf = zeros(nf,2);
            yf = zeros(nf,2);
            zf = zeros(nf,2);

            % Initialize counters
            k = 1;
            l = 1;

            % Loop over all the Control Points
            for i = 1:length(CP(:,1,1))
                if F(k)~=0
                    xf(l,1) = CP(i,1);
                    xf(l,2) = CP(i,1)-F(k)/max(abs(F));
                    yf(l,1) = CP(i,2);
                    yf(l,2) = CP(i,2);
                    zf(l,1) = CP(i,3);
                    zf(l,2) = CP(i,3);

                    % Update internal counter
                    l = l + 1;
                end
                if F(k+1)~=0
                    xf(l,1) = CP(i,1);
                    xf(l,2) = CP(i,1);
                    yf(l,1) = CP(i,2);
                    yf(l,2) = CP(i,2)-F(k+1)/max(abs(F));
                    zf(l,1) = CP(i,3);
                    zf(l,2) = CP(i,3);

                    l = l + 1;
                end

                k = k + 2;
            end

        end


        function [xs, ys, zs] = createSupportsForIGATimoshenkoBeam2D ...
                (app,CP, homDOFs)

            nu = length(CP(:,1));

            % scaling factors for the support triangles
            maximum = max(max(max(max(CP))));
            minimum = min(min(min(min(CP))));

            % Average the factor with respect to the maximum and minimum values
            factor = (maximum-minimum)/5;

            % Sampling points for the rotational supports
            numEval = 3;

            % Get a step size in the 0 to 2pi interval
            noOfCircularSegments = 4;
            step = 2*pi/noOfCircularSegments/numEval;

            % Initialize a counter for the supports
            counterSupports = 1;

            for l = 1:length(homDOFs)
                % get the corresponding Control Point number p and indices CP(i,j)
                p=ceil(homDOFs(l)/3);
                j=ceil(p/nu);
                i=p-(j-1)*nu;
                dir=homDOFs(l)-((j-1)*nu+i-1)*3;

                if (dir==1)              % (x-support)
                    xs(counterSupports,1)=CP(i,1);
                    xs(counterSupports,2)=CP(i,1) - 0.1732*factor;
                    xs(counterSupports,3)=CP(i,1) - 0.1732*factor;
                    xs(counterSupports,4)=xs(counterSupports,1);
                    ys(counterSupports,1)=CP(i,2);
                    ys(counterSupports,2)=CP(i,2) + 0.1*factor;
                    ys(counterSupports,3)=CP(i,2) - 0.1*factor;
                    ys(counterSupports,4)=ys(counterSupports,1);
                    zs(counterSupports,1:4)=CP(i,3);

                    % Update the support counter
                    counterSupports = counterSupports + 1;
                elseif (dir==2)          % (y-support)
                    xs(counterSupports,1)=CP(i,1);
                    xs(counterSupports,2)=CP(i,1) - 0.1*factor;
                    xs(counterSupports,3)=CP(i,1) + 0.1*factor;
                    xs(counterSupports,4)=xs(counterSupports,1);
                    ys(counterSupports,1)=CP(i,2);
                    ys(counterSupports,2)=CP(i,2) - 0.1732*factor;
                    ys(counterSupports,3)=CP(i,2) - 0.1732*factor;
                    ys(counterSupports,4)=ys(counterSupports,1);
                    zs(counterSupports,1:4)=CP(i,3);

                    % Update the support counter
                    counterSupports = counterSupports + 1;
                elseif (dir==3)          % (rotational-support)
                    % Initialize the coordinate on the circle
                    uOnCircle = 0;

                    % Create the circular segments
                    for g=1:noOfCircularSegments
                        for r=1:numEval+1
                            xs(counterSupports,r) = CP(i,1) + 0.1232*factor*sin(uOnCircle);
                            ys(counterSupports,r) = CP(i,2) + 0.1232*factor*cos(uOnCircle);
                            zs(counterSupports,r) = CP(i,3);

                            % Update coordinate on circle
                            if r~=numEval+1
                                uOnCircle = uOnCircle + step;
                            end
                        end

                        % Update the support counter
                        counterSupports = counterSupports + noOfCircularSegments;
                    end
                end
            end

        end


        function [xF, yF, zF] = createForceArrowsForIGATimoshenkoBeam2D ...
                (app,CP, F)


            % Find the number of the force locations
            nF = sum(F~=0);
            fprintf('num of force locations %d \n', nF);
            % Initialize output arrays
            xF = zeros(nF,1);
            yF = zeros(nF,1);
            zF = zeros(nF,1);

            % Initialize counters
            k = 1;
            l = 1;
            factor = 1;

            % Loop over all the Control Points
            for i = 1:length(CP(:,1,1))
                if F(k)~=0
                    xF(l,1) = CP(i,1);
                    xF(l,2) = factor*(CP(i,1)-F(k))/max(abs(F));
                    yF(l,1) = CP(i,2);
                    yF(l,2) = CP(i,2);
                    zF(l,1) = CP(i,3);
                    zF(l,2) = CP(i,3);
                    % Update internal counter
                    l=l+1;
                end
                if F(k+1)~=0
                    xF(l,1) = CP(i,1);
                    xF(l,2) = factor*CP(i,1);
                    yF(l,1) = CP(i,2);
                    yF(l,2) = (CP(i,2)-F(k+1))/max(abs(F));
                    zF(l,1) = CP(i,3);
                    zF(l,2) = CP(i,3);
                    % Update internal counter
                    l=l+1;
                end
                if (F(k+2)~=0)
                    % This would correspond to directly applying a moment, but if this
                    % functions is needed to be extended there are more things to be
                    % considered

                    %         xf(l,1) = CP(i,1);
                    %         xf(l,2) = CP(i,1);
                    %         yf(l,1) = CP(i,2);
                    %         yf(l,2) = CP(i,2);
                    %         zf(l,1) = CP(i,3);
                    %         zf(l,2) = scal*(CP(i,3)-f(k+2)/max(abs(f)));
                    %
                    %         % Update counter
                    %         l=l+1;
                end

                % Update external counter
                k = k + 3;
            end

        end


        function plot_knotsForBSplineCurveOnCartesianSpace(app,ax,p, Xi, CP, isNURBS)


            % Number of Control Points in xi,eta-direction
            nxi = length(CP(:, 1, 1));
            Point = zeros(length(Xi),3);
            for j = 1:length(Xi)
                xi = Xi(j);
                knotSpan = app.findKnotSpan(xi, Xi, nxi);
                numDeriv = 0;
                R = app.computeNURBSBasisFunctions1d ...
                    (Xi, p, xi, transpose(CP(:,4)), numDeriv);

                Point(j, 1:3) = app.computeCartesianCoordinatesOfAPointOnBSplineCurve ...
                    (p, knotSpan, xi, Xi, CP, transpose(R));
            end

            %% 2. Plot all the element edges
            plot3(ax,Point(:, 1), Point(:, 2), Point(:, 3), '.', 'Markersize', 10, 'Color', 'green');
            axis(ax, 'equal');
            % Turn on grid
            grid(app.UIAxes2_3, 'on');
            % Set labels
            xlabel(ax, 'x', 'FontSize', 18);
            ylabel(ax, 'y', 'FontSize', 18);
            zlabel(ax, 'z', 'FontSize', 18);

        end


        function plot_ControlPolygonBSplineCurve(app,ax,CP)

            for l=1:length(CP(:,1))-1
                plot3(ax,CP(l,1),CP(l,2),CP(l,3),'--or');
                plot3(ax,CP(l:l+1,1),CP(l:l+1,2),CP(l:l+1,3),'--or');
            end

        end


        function [dHat, FComplete, minElSize] = ...
                solve_IGABeamLinear2D ...
                (app,analysis, p, Xi, CP, homDOFs, NBC, parameters, isNURBS, ...
                solve_LinearSystem, int, outMsg)


            % Create the B-Spline patch
            BSplinePatch.p = p;
            BSplinePatch.Xi = Xi;
            BSplinePatch.CP = CP;
            BSplinePatch.isNURBS = isNURBS;
            BSplinePatch.int = int;
            BSplinePatch.parameters = parameters;
            BSplinePatch.NBC = NBC;

            % On the output messaging
            tab = '\t';

            % Compute the number of Control Points
            numCPs_xi = length(CP(:, 1));

            % Compute the number of DOFs
            if strcmp(analysis.type, 'Bernoulli')
                numDOFs = 2*numCPs_xi;
            elseif strcmp(analysis.type, 'Timoshenko')
                numDOFs = 3*numCPs_xi;
            end
            BSplinePatch.noDOFs = numDOFs;

            % Find the free DOFs of the system
            freeDOFs = 1:numDOFs;
            freeDOFs(ismember(freeDOFs, homDOFs)) = [];

            % Create the dummy variables
            dHatSaved = 'undefined';
            dHatDotSaved = 'undefined';
            dHatDDotSaved = 'undefined';
            dHatDot = 'undefined';
            dHatDDot = 'undefined';
            propNLinearAnalysis = 'undefined';
            connections = 'undefined';
            propCoupling = 'undefined';
            KConstant = 'undefined';
            masterDOFS = 'undefined';
            slaveDOFs = 'undefined';
            computeUpdatedGeometry = 'undefined';
            massMtx = 'undefined';
            dampMtx = 'undefined';
            updateDirichletBCs = 'undefined';
            propIDBC = 'undefined';
            plot_IGANLinear = 'undefined';
            isReferenceUpdated = 'undefined';
            isCosimulationWithEmpire = 'undefined';
            propGraph = 'undefined';

            % Static linear analysis
            propTransientAnalysis.timeDependence = 'steadyState';
            propTransientAnalysis.computeProblemMtrcsTransient = 'undefined';
            propTransientAnalysis.isStaticStep = true;
            t = 0;

            % On the inhomogeneous Dirichlet boundary conditions
            inhomDOFs = [];
            valuesInhomDOFs = [];

            % Get the rule for computating the master stiffness matrix according to the
            % analysis type
            if strcmp(analysis.type, 'Bernoulli')
                computeLinearMatricesSteadyState = @computeStiffMtxIGABernoulliBeamLinear;
            elseif strcmp(analysis.type, 'Timoshenko')
                computeLinearMatricesSteadyState = @computeStiffMtxIGATimoshenkoBeamLinear;
            end

            % Initialize the output array
            dHat = zeros(numDOFs, 1);

            %% 1. Assign DoF numbering as CP: CP1->dof1,dof2,dof3 CP2->dof4,dof5,dof6

            % Initialize the array of the degrees of freedom
            if strcmp(analysis.type, 'Bernoulli')
                DOFNumbering = zeros(numCPs_xi, 2);
            elseif strcmp(analysis.type, 'Timoshenko')
                DOFNumbering = zeros(numCPs_xi, 3);
            end

            % Initialize counter
            k = 1;

            % Loop over all the Control points
            if strcmp(analysis.type, 'Bernoulli')
                for cpi = 1:numCPs_xi
                    DOFNumbering(cpi, 1) = k;
                    DOFNumbering(cpi, 2) = k + 1;

                    % Update counter
                    k = k + 2;
                end
            elseif strcmp(analysis.type, 'Timoshenko')
                for cpi = 1:numCPs_xi
                    DOFNumbering(cpi, 1) = k;
                    DOFNumbering(cpi, 2) = k + 1;
                    DOFNumbering(cpi, 3) = k + 2;

                    % Update counter
                    k = k + 3;
                end
            end

            % Attribute the DOF numbering to the B-Spline patches themselves
            BSplinePatch.DOFNumbering = DOFNumbering;

            % Collect all the B-Spline patches into an array
            BSplinePatches = {BSplinePatch};

            %% 2. Solve the linear equation system
            [dHat, ~, ~, ~, FComplete, rankD, condK, minEig, ~, ~, minElSize] = ...
                app.solve_IGALinearSystem ...
                (analysis, dHatSaved, dHatDotSaved, dHatDDotSaved, BSplinePatches, ...
                connections, dHat, dHatDot, dHatDDot, KConstant, massMtx, dampMtx, ...
                computeLinearMatricesSteadyState, computeUpdatedGeometry, freeDOFs, ...
                homDOFs, inhomDOFs, valuesInhomDOFs, updateDirichletBCs, ...
                masterDOFS, slaveDOFs, solve_LinearSystem, t, propCoupling, ...
                propTransientAnalysis, propNLinearAnalysis, propIDBC, ...
                plot_IGANLinear, isReferenceUpdated, isCosimulationWithEmpire, ...
                tab, propGraph, outMsg);


            disp('beam solved successsfully')
        end


        function [KGlobal, F, BSplinePatch, propCoupling, minElSize] = ...
                computeStiffMtxIGABernoulliBeamLinear ...
                (app,KConstant, tanMtxLoad, dHat, dHatSaved, dHatDot, dHatDotSaved, ...
                BSplinePatch, connections, propCoupling, loadFactor, noPatch, ...
                noTimeStep, iNLinearIter, noWeakDBCCnd, t, propTransientAnalysis, ...
                isReferenceUpdated, tab, outMsg)

            % Check the NURBS geometry input
            isBSplinePatchCell = false;
            if iscell(BSplinePatch)
                if length(BSplinePatch) > 1
                    error('Multipatch NURBS surface is given as input to the computation of the stiffness matrix for a single patch NURBS surface');
                else
                    BSplinePatch = BSplinePatch{1};
                end
                isBSplinePatchCell = true;
            end

            % Get the B-Spline patch parameters
            p = BSplinePatch.p;
            Xi = BSplinePatch.Xi;
            CP = BSplinePatch.CP;
            isNURBS = BSplinePatch.isNURBS;
            parameters = BSplinePatch.parameters;
            int = BSplinePatch.int;
            NBC = BSplinePatch.NBC;

            % Number of Control Points
            numCPs_xi = length(CP(:, 1));

            % Initialization of the Global Stiffness matrix
            numDOFs = 2*numCPs_xi;

            % Number of Control Points in the element level
            numCPsEl = p + 1;

            % Number of Degrees of Freedom in the element level
            numDOFsEl = 2*numCPsEl;

            % Initialize minimum element area size
            minElementSize = norm(CP(1, 1:3) - CP(numCPs_xi, 1:3));
            minElSize = minElementSize;

            % Initialize output array
            KGlobal = zeros(numDOFs);

            %% 1. Choose the integration scheme

            % choose the number of the Gauss points accordingly
            if strcmp(int.type, 'default')
                noGP = ceil(0.5*(2*p - 1));
            elseif strcmp(int.type, 'user')
                noGP = int.noGP;
            end

            % Get Gauss Points and weights
            [GP, GW] = app.getGaussPointsAndWeightsOverUnitDomain(noGP);

            %% 2. Loop over all the elements
            for i = p + 1:length(Xi) - p - 1
                if Xi(i+1) - Xi(i) ~= 0
                    %% 2i. Initialize the element area size

                    % Initialize the new one
                    minElementSize = 0;

                    %% 2ii. Compute the determinant of the Jacobian to the transformation from the parameter to the integration space
                    detJxiu = (Xi(i + 1) - Xi(i))/2.0;

                    %% 2iii. Create an element freedom table

                    % Initialize element freedom table
                    EFT = zeros(1, numDOFsEl);

                    % Initialize counter
                    k = 1;

                    % Relation global-local DoFs
                    for cpi = i-BSplinePatch.p:i
                        EFT(k) = BSplinePatch.DOFNumbering(cpi, 1);
                        EFT(k + 1) = BSplinePatch.DOFNumbering(cpi, 2);

                        % update counter
                        k = k + 2;
                    end

                    for j = 1:length(GW)
                        %% 2iv.1. Linear transformation from the quadrature domain to the knot span
                        xi = ((1 - GP(j))*Xi(i) + (1 + GP(j))*Xi(i + 1))/2;

                        %% 2iv.2. Find the correct knot span where xi lives
                        kontSpan = app.findKnotSpan(xi, Xi, numCPs_xi);

                        %% 2iv.3. Compute the NURBS basis functions and their first and second derivatives on GP
                        dR = app.computeNURBSBasisFunctions1d ...
                            (Xi, p, xi, transpse(CP(:,4)), 2);

                        %% 2iv.4. Compute the base vectors and their derivatives on GP
                        [G, dG] = app.computeBaseVectorNormalToNURBSCurveAndDeivativesForCurve2D ...
                            (kontSpan, p, CP, transpose(dR));

                        %% 2iv.5. Compute the determinant of the Jacobian to the transformation from the NURBS parameter to the physical space
                        detJxxi = norm(G(:, 1));

                        %% 2iv.6. Compute the element size on the Gauss Point and sum up the contribution to it
                        minElementSizeOnGP = abs(detJxxi)*abs(detJxiu)*GW(j);
                        minElementSize = minElementSize + minElementSizeOnGP;

                        %% 2iv.7. Compute the Timoshenko element stiffness matrix on GP
                        KelementOnGP = app.computeElStiffMtxIGABernoulliBeamLinear ...
                            (p, G(:, 1), dG(:, 1), dR, parameters);

                        %% 2iv.8. Add the contribution from the Gauss point and assemble to the global stiffness matrix
                        KGlobal(EFT, EFT) = KGlobal(EFT, EFT) + KelementOnGP*minElementSizeOnGP;
                    end
                end

                %% 2v. Check if the current minimum element area size is smaller than for the previous element
                if minElementSize <= minElSize
                    minElSize = minElementSize;
                end
            end

            %% 3. Get the exernally applied load vector
            if isfield(BSplinePatch, 'FGamma')
                F = BSplinePatch.FGamma;
            else
                F = 'undefined';
            end

            %% 4. Check output
            if isBSplinePatchCell
                BSplinePatch = {BSplinePatch};
            end

        end


        function Ke = computeElStiffMtxIGABernoulliBeamLinear ...
                (app,p, G, dG, dR, parameters)


            % length of the normal vector
            L = norm(G);

            % Initializations
            epsilon = zeros(2*(p + 1), 1);
            kappa = zeros(2*(p + 1), 1);

            %% 1. Compute normal strain epsilon

            % Loop over all the knot span contributions
            for j = 1:p + 1
                epsilon(1 + (j - 1)*2, 1) = dR(j, 2)*G(1, 1);
                epsilon(2 + (j - 1)*2, 1) = dR(j, 2)*G(2, 1);
            end

            %% 2. Compute change in the curvature kappa

            % Necessary variables for the curvature
            d1 = G(1, 1)*G(2, 1)/L^2;
            d2 = G(2, 1)^2/L^2 - 1;
            d3 = 1 - G(1, 1)^2/L^2;
            alpha1 = (dG(1, 1)*d1 + dG(2, 1)*d3)/L;
            alpha2 = (dG(1, 1)*d2 - dG(2, 1)*d1)/L;

            % Loop over all the knot span contributions
            for j = 1:p + 1
                kappa(1 + (j - 1)*2, 1) = - alpha1*dR(j, 2) + G(2, 1) * dR(j, 3)/L;
                kappa(2 + (j - 1)*2, 1) = - alpha2*dR(j, 2) - G(1, 1) * dR(j, 3)/L;
            end

            %% 3. Transform the material tensors in the local Cartesian basis

            % Axial contribution
            EAxial = parameters.EYoung*parameters.A/L^4;

            % Bending contribution
            EBending = parameters.EYoung*parameters.I/L^4;

            %% 4. Compute the virtual work contributions

            % virtual work done by the normal force n
            workEpsilon = EAxial*(epsilon*epsilon');

            % virtual work done by the bending moment m
            workKappa = EBending*(kappa*kappa');

            %% 5. Compute the element stiffness matrix on the Gauss Point
            Ke = workEpsilon + workKappa;

        end


        function [KGlobal, F, BSplinePatch, propCoupling, minElSize] = ...
                computeStiffMtxIGATimoshenkoBeamLinear ...
                (app,KConstant, tanMtxLoad, dHat, dHatSaved, dHatDot, dHatDotSaved, ...
                BSplinePatch, connections, propCoupling, loadFactor, noPatch, ...
                noTimeStep, iNLinearIter, noWeakDBCCnd, t, propTransientAnalysis, ...
                isReferenceUpdated, tab, outMsg)


            % Check the NURBS geometry input
            isBSplinePatchCell = false;
            if iscell(BSplinePatch)
                if length(BSplinePatch) > 1
                    error('Multipatch NURBS surface is given as input to the computation of the stiffness matrix for a single patch NURBS surface');
                else
                    BSplinePatch = BSplinePatch{1};
                end
                isBSplinePatchCell = true;
            end

            % Get the B-Spline patch parameters
            p = BSplinePatch.p;
            Xi = BSplinePatch.Xi;
            CP = BSplinePatch.CP;
            isNURBS = BSplinePatch.isNURBS;
            parameters = BSplinePatch.parameters;
            int = BSplinePatch.int;
            NBC = BSplinePatch.NBC;

            % Number of Control Points
            numCPs_xi = length(CP(:, 1));

            % Initialization of the Global Stiffness matrix
            numDOFs = 3*numCPs_xi;

            % Number of Control Points in the element level
            numNodesEl = p + 1;

            % Number of Degrees of Freedom in the element level
            numDOFsEl = 3*numNodesEl;

            % Initialize minimum element area size
            minElementSize = norm(CP(1, 1:3) - CP(numCPs_xi, 1:3));
            minElSize = minElementSize;

            % Initialize output array
            KGlobal = zeros(numDOFs);

            %% 1. Choose the integration scheme

            % choose the number of the Gauss points accordingly
            if strcmp(int.type, 'default')
                numGP = ceil(0.5*(2*p + 1)) ;
            elseif strcmp(int.type, 'user')
                numGP = int.noGP;
            else
                error('Gauss integration rule unspecified');
            end

            % Get Gauss Points and weights
            [GP,GW] = app.getGaussPointsAndWeightsOverUnitDomain(numGP);

            %% 2. Loop over all the elements
            for i = p + 1:length(Xi) - p - 1
                % Check if we are in a non zero span
                if Xi(i + 1) - Xi(i)~=0
                    %% 2i. Initialize the element area size

                    % Initialize the new one
                    minElementSize = 0;

                    %% 2ii. Compute the determinant of the Jacobian to the transformation from the parameter to the integration space
                    detJxiu = (Xi(i + 1) - Xi(i))/2.0;

                    %% 2iii. Create an element freedom table

                    % Initialize element freedom table
                    EFT = zeros(1, numDOFsEl);

                    % Initialize counter
                    k = 1;

                    % Relation global-local DoFs
                    for cpi = i-BSplinePatch.p:i
                        EFT(k) = BSplinePatch.DOFNumbering(cpi, 1);
                        EFT(k + 1) = BSplinePatch.DOFNumbering(cpi, 2);
                        EFT(k + 2) = BSplinePatch.DOFNumbering(cpi, 3);

                        % update counter
                        k = k + 3;
                    end

                    %% 2iv. Loop over all the Gauss points
                    for j = 1:length(GW)
                        %% 2iv.1. Linear transformation from the quadrature domain to the knot span
                        xi = ((1 - GP(j))*Xi(i) + (1 + GP(j))*Xi(i + 1))/2;

                        %% 2iv.2. Find the correct knot span where xi lives
                        knotSpan = app.findKnotSpan(xi, Xi, numCPs_xi);

                        %% 2iv.3. Compute the NURBS basis functions and their first and second derivatives on GP
                        dR = app.computeNURBSBasisFunctions1d ...
                            (Xi, p, xi, transpse(CP(:,4)), 2);

                        %% 2iv.4. Compute the base vectors and their derivatives on GP
                        [G, dG] = ...
                            app.computeBaseVectorNormalToNURBSCurveAndDeivativesForCurve2D ...
                            (knotSpan, p, CP, dR);

                        %% 2iv.5. Compute the determinant of the Jacobian to the transformation from the NURBS parameter to the physical space
                        detJxxi = norm(G(:, 1));

                        %% 2iv.6. Compute the element size on the Gauss Point and sum up the contribution to it
                        minElementSizeOnGP = abs(detJxxi)*abs(detJxiu)*GW(j);
                        minElementSize = minElementSize + minElementSizeOnGP;

                        %% 2iv.7. Compute the Timoshenko element stiffness matrix on GP
                        KelementOnGP = computeElStiffMtxIGATimoshenkoBeamLinear ...
                            (p, dR, G(:, 1), dG(:, 1), parameters);

                        %% 2iv.8. Add the contribution from the Gauss point and assemble to the global stiffness matrix
                        KGlobal(EFT, EFT) = KGlobal(EFT, EFT) + KelementOnGP*minElementSizeOnGP;
                    end
                end
                %% 2v. Check if the current minimum element area size is smaller than for the previous element
                if minElementSize <= minElSize
                    minElSize = minElementSize;
                end
            end

            %% 3. Get the exernally applied load vector
            if isfield(BSplinePatch, 'FGamma')
                F = BSplinePatch.FGamma;
            else
                F = 'undefined';
            end

            %% 4. Check output
            if isBSplinePatchCell
                BSplinePatch = {BSplinePatch};
            end

        end


        function [G, dG] = ...
                computeBaseVectorNormalToNURBSCurveAndDeivativesForCurve2D ...
                (app,knotSpanIndex, p, CP, dR)


            % Tangent vector
            G = zeros(3,2);

            % Acceleration vector
            dG = zeros(3,2);

            %% 1. Compute the base vector and the accelration vector
            for b = 0:p
                for a = 1:3
                    % Compute the index
                    index = knotSpanIndex - p + b;

                    % Compute the tangent base vector to the curve
                    G(a,1) = G(a,1) + dR(b+1,2)*CP(index,a);

                    % Compute the accelaration vector to the curve
                    dG(a,1) = dG(a,1) + dR(b+1,3)*CP(index,a);
                end
            end

            %% 2. Compute the perpedicular to the base and accelration vector

            % Normal vector with respect to xy-axis only
            G(1,2) = - G(2,1) ;
            G(2,2) = G(1,2) ;
            G(3,2) = G(3,1) ;

            % Normal acceleration vector
            dG(1,2) = - dG(2,1) ;
            dG(2,2) = dG(1,2) ;
            dG(3,2) = dG(3,1) ;

        end


        function Ke = computeElStiffMtxIGATimoshenkoBeamLinear ...
                (app,p, R, G, dG, parameters)

            % The length of the beam in the undeformed configuration
            L = norm(G) ;

            % Initializations
            epsilon = zeros(3*(p + 1), 1);
            kappa = zeros(3*(p + 1), 1);
            gamma = zeros(3*(p + 1), 1);

            %% 1. Compute normal strain epsilon

            % Loop over all the knot span contributions
            for j = 1:p + 1
                epsilon(1 + (j - 1)*3, 1) =  G(1, 1) * R(j, 2);
                epsilon(2 + (j - 1)*3, 1) =  G(2, 1) * R(j, 2);
                epsilon(3 + (j - 1)*3, 1) = 0 ;
            end

            %% 2. Compute change in the curvature kappa

            % Variable alpha
            alpha = (dG(1, 1)*G(1, 1) + dG(2, 1)*G(2, 1))/L^3;

            %Differentiation of A2 with respect to theta^1
            A2xKomma1 = - dG(2, 1)/L + alpha*G(2, 1);
            A2yKomma1 = dG(1, 1)/L - alpha*G(1, 1);

            % Variable beta
            beta = -G(1, 1)*A2yKomma1 + G(2, 1)*A2xKomma1;

            %Computation of the curvature change kappa
            for j = 1:p+1
                kappa(1 + (j - 1)*3, 1) = A2xKomma1*R(j, 2);
                kappa(2 + (j - 1)*3, 1) = A2yKomma1*R(j, 2);
                kappa(3 + (j - 1)*3, 1) = -L*R(j, 2) + beta * R(j, 2);
            end

            %% 3. Compute shear strain gamma

            %Computation of the shear strain gamma
            for j = 1:p + 1
                gamma(1 + (j - 1)*3, 1) = -G(2, 1)*R(j, 2)/L ;
                gamma(2 + (j - 1)*3, 1) = G(1,1) * R(j,2)/L ;
                gamma(3 + (j - 1)*3, 1) = - L*R(j,1) ;
            end

            %% 4. Compute the geometrical and technical prefactors

            % Axial contribution
            EAxial = parameters.EYoung * parameters.A/L^4;

            % Bending contribution
            EBending = parameters.EYoung * parameters.I/L^4;

            % Shear contribution
            EShear = parameters.GShear * parameters.A/L^2;

            %% 5. Compute the virtual work contributions

            % Virtual work done by the normal force n
            epsilonWork = EAxial*(epsilon*epsilon');

            % Virtual work done by the bending moment m
            kappaWork = EBending*(kappa*kappa');

            % Virtual work done by the shear force q
            gammaWork = parameters.alpha*EShear*(gamma*gamma');

            %% 6. Compute the element stiffness matrix on the Gauss Point
            Ke = epsilonWork + kappaWork + gammaWork;

        end


        function [u, CPHistory, residual, isConverged, FComplete, rankD, ...
                condK, minEig, BSplinePatches, propCoupling, minElAreaSize] = ...
                solve_IGALinearSystem ...
                (app,propAnalysis, uSaved, uDotSaved, uDDotSaved, BSplinePatches, connections, ...
                u, uDot, uDDot, constMtx, massMtx, dampMtx, computeLinearMtrcsSteadyState, ...
                computeUpdatedGeometry, freeDOFs, homDOFs, inhomDOFs, valuesInhomDOFs, ...
                updateDirichletBCs, masterDOFs, slaveDOFs, solve_LinearSystem, t, ...
                propCoupling, propTransientAnalysis, propNLinearAnalysis, propIDBC, ...
                plot_IGANLinear, isReferenceUpdated, isCosimulationWithEmpire, tab, ...
                propGraph, outMsg)


            % Dummy variables
            CPHistory = 'undefined';
            loadFactor = 'undefined';
            tanMtxLoad = 'undefined';
            noPatch = 'undefined';
            noTimeStep = 'undefined';
            iNLinearIter = 'undefined';
            noWeakDBCCnd = 'undefined';
            isReferenceUpdated = 'undefined';
            isConverged = true;

            % Get the number of patches
            if ~iscell(BSplinePatches)
                error('BSplinePatches must be a cell array representing the computational multipatch geometry');
            else
                numPatches = length(BSplinePatches);
            end

            % The residual for a linear analysis is zero
            residual = 0;

            % Check if the linear solver is called from a transient analysis
            isTransient = false;
            if ~ischar(propTransientAnalysis)
                if isfield(propTransientAnalysis, 'timeDependence')
                    if strcmp(propTransientAnalysis.timeDependence, 'transient')
                        isTransient = true;
                    end
                end
            end

            %% 1. Update the time-dependent inhomogeneous Dirichlet boundary conditions
            if ~ischar(propIDBC)
                if isa(updateDirichletBCs, 'function_handle')
                    [homDOFs, inhomDOFs, valuesInhomDOFs] = ...
                        updateDirichletBCs(BSplinePatches, homDOFs, propIDBC, t);
                else
                    error('Variable updateInhomDOFs should define a function handle');
                end
            end

            %% 2. Compute the load vector of the multipatch structure
            for iPatches = 1:numPatches
                %% 2i. Initialize the load vector of the patch
                BSplinePatches{iPatches}.FGamma = zeros(BSplinePatches{iPatches}.noDOFs, 1);

                %% 2ii. Get the Neumann boundary conditions of the current patch
                NBC = BSplinePatches{iPatches}.NBC;

                %% 2iii. Loop over the Neumann boundary conditions of the current patch
                for iNBC = 1:NBC.noCnd
                    %% 2iii.1. Initialize the load vector for the current condition
                    FGamma = zeros(BSplinePatches{iPatches}.noDOFs, 1);

                    %% 2iii.2. Get the function handle for the load vector computation
                    funcHandle = str2func(NBC.computeLoadVct{iNBC});

                    %% 2iii.3. Compute the load vector and the tangent matrix resulting from the application of follower loads
                    if ~(propTransientAnalysis.isStaticStep && NBC.isTimeDependent(iNBC, 1))
                        [FGamma, ~] = funcHandle ...
                            (FGamma, BSplinePatches{iPatches}, ...
                            NBC.xiLoadExtension{iNBC}, NBC.etaLoadExtension{iNBC}, ...
                            NBC.loadAmplitude{iNBC}, NBC.loadDirection{iNBC}, ...
                            NBC.isFollower(iNBC, 1), t, BSplinePatches{iPatches}.int, ...
                            '');
                    end

                    %% 2iii.4. Add The compute external load vector into the B-Spline array
                    BSplinePatches{iPatches}.FGamma = BSplinePatches{iPatches}.FGamma + ...
                        FGamma;
                end
            end

            %% 3. Compute the linear matrices of the system

            [stiffMtx, RHS, BSplinePatches, propCoupling, minElAreaSize] = ...
                computeLinearMtrcsSteadyState ...
                (constMtx, tanMtxLoad, u, uSaved, uDot, uDotSaved, BSplinePatches, ...
                connections, propCoupling, loadFactor, noPatch, noTimeStep, ...
                iNLinearIter, noWeakDBCCnd, t, propTransientAnalysis, ...
                isReferenceUpdated, strcat(tab, '\t'), outMsg);
            if isa(propTransientAnalysis.computeProblemMtrcsTransient, 'function_handle') && ...
                    ~propTransientAnalysis.isStaticStep
                [stiffMtx, RHS] = propTransientAnalysis.computeProblemMtrcsTransient ...
                    (u, uSaved, uDot, uDotSaved, uDDot, uDDotSaved, massMtx, dampMtx, ...
                    stiffMtx, RHS, propTransientAnalysis);
            end
            if strcmp(outMsg, 'outputEnabled') && ~isTransient
                %     rankD = length(RHS(freeDOFs)) - rank(stiffMtx(freeDOFs, freeDOFs));
                %     if rankD ~= 0
                %         fprintf(strcat(tab,'>> The system has rank deficiency equal to %d\n'), rankD);
                %     end
                if issparse(stiffMtx)
                    computeConditionNumber = @condest;
                else
                    computeConditionNumber = @cond;
                end
                condK = computeConditionNumber(stiffMtx(freeDOFs, freeDOFs));
                %     fprintf(strcat(tab, '>> The condition number of the system is %d\n'), condK);
                %     [~, eigVal] = eig(stiffMtx(freeDOFs, freeDOFs));
                %     eigVal(~eigVal) = Inf;
                %     minEig = min(min(eigVal));
                %     fprintf(strcat(tab, '>> The minimum eigenvalue of the system is %d\n'), minEig);
                rankD = 'undefined';
                minEig = 'undefined';
            else
                rankD = 'undefined';
                condK = 'undefined';
                minEig = 'undefined';
            end

            %% 4. Update the right-hand side vector if inhomogeneous Dirichlet boundary conditions are encountered
            if norm(valuesInhomDOFs) ~= 0
                RHS = RHS - stiffMtx(:, inhomDOFs)*valuesInhomDOFs';
            end


            [uRed, hasLinearSystemConverged] = solve_LinearSystem ...
                (stiffMtx(freeDOFs, freeDOFs), RHS(freeDOFs), u(freeDOFs));
            if ~hasLinearSystemConverged
                error('Linear equation solver has not converged');
            end

            %% 6. Re-assemble to the complete vector of unknowns
            u(freeDOFs) = uRed;
            u(homDOFs) = 0;
            u(inhomDOFs) = valuesInhomDOFs;

            %% 7. Compute the complete force vector
            FComplete = stiffMtx*u;


        end

        function index = plot_currentConfigurationIGABeamsLinear...
                (app,analysis, p, Xi, CP, isNURBS, dHat, rb, parameters, graph, outMsg)

            %figure(graph.index);

            % Assign the number of sampling points to be used for the postprocessing
            gridPoints = 49;

            % Number of evaluation points for the postprocessing resultants
            numEval = 500;

            % Initialize the array of the resultants
            resultant = zeros(numEval+1,1);

            %% 1. Create the first window (Deformed and/or undeformed geometry)

            % Initialize subfigure 1
            %ax1= subplot(1,2,1, 'Parent', app.UIAxes3_3);
            ax1=app.UIAxes3_3;
            % Compute the displaced Control Points
            CPd = app.computeDisplacedControlPointsIGABeams2D(CP,dHat,analysis);

            % Create arrays needed for the plotting of the surface, the supports as
            % well as the force arrows
            if strcmp(graph.postProcVisType,'undeformedShape')||strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                [Xp,Yp,Zp] = app.createBSplineCurveOnCartesianSpace(p,Xi,CP,isNURBS,gridPoints);
                if strcmp(analysis.type,'Bernoulli')
                    [xs,ys,zs] = app.createSupportsForIGABernoulliBeam2D(CP,rb);
                elseif strcmp(analysis.type,'Timoshenko')
                    [xs,ys,zs] = app.createSupportsForIGATimoshenkoBeam2D(CP,rb);
                end
            end
            if strcmp(graph.postProcVisType,'deformedShape')||strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                [XpDef,YpDef,ZpDef] = app.createBSplineCurveOnCartesianSpace(p,Xi,CPd,isNURBS,gridPoints);
                if ~strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                    if strcmp(analysis.type,'Bernoulli')
                        [xsDef,ysDef,zsDef] = app.createSupportsForIGABernoulliBeam2D(CPd,rb);
                    elseif strcmp(analysis.type,'Timoshenko')
                        [xsDef,ysDef,zsDef] = app.createSupportsForIGATimoshenkoBeam2D(CPd,rb);
                    end
                end
            end

            % Plot the geometry, the knots on the geometry, the supports and the Control
            % Polygon
            if strcmp(graph.postProcVisType,'undeformedShape')||strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                % Plot geometry
                plot3(ax1,Xp,Yp,Zp,'Color','black');
                hold on;

                % Plot knots on the geometry
                app.plot_knotsForBSplineCurveOnCartesianSpace(ax1,p,Xi,CP,isNURBS);

                % Plot supports
                for k =1:length(xs(:,1))
                    plot3(ax1,xs(k,:),ys(k,:),zs(k,:),'Linewidth',2,'Color','black');
                end

                % Plot the Control Points and the Control Polygon
                app.plot_ControlPolygonBSplineCurve(ax1,CP);

                if strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                    hold (ax1, 'on');
                end
            end
            if strcmp(graph.postProcVisType,'deformedShape')||strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                % Plot geometry
                plot3(ax1,XpDef,YpDef,ZpDef,'Color','black');
                hold (ax1, 'on');

                % Plot knots on the geometry
                app.plot_knotsForBSplineCurveOnCartesianSpace(ax1,p,Xi,CPd,isNURBS);

                % Plot supports
                if ~strcmp(graph.postProcVisType,'deformedAndUndeformedShape')
                    for k =1:length(xsDef(:,1))
                        plot3(ax1,xsDef(k,:),ysDef(k,:),zsDef(k,:),'Linewidth',2,'Color','black');
                    end
                end

                % Plot the Control Points and the Control Polygon
                app.plot_ControlPolygonBSplineCurve(ax1,CPd);
            end

            axis(ax1, 'equal');
            view(ax1, 2);
            camlight(ax1, 'left');
            lighting(ax1, 'phong');
            xlabel(ax1, 'x', 'FontSize', 14);
            ylabel(ax1, 'y', 'FontSize', 14);
            zlabel(ax1, 'z', 'FontSize', 14);
            %ylim(app.UIAxes2_3,[-50,500])
            %xlim(app.UIAxes2_3,[-50,1100])
            hold(ax1, 'off');

            % Choose a title for the subfigure
            if strcmp(analysis.type,'Bernoulli')
                title(app.UIAxes3_3,'Deformed configuration-Bernoulli beam');
            elseif strcmp(analysis.type,'Timoshenko')
                title(app.UIAxes3_3,'Deformed configuration-Timoshenko beam');
            end


            %% 2. Create the second window (Resultant visualization)

            % Initialize subfigure 2
            %ax2= subplot(1,2,1, 'Parent', app.UIAxes3_3);
            ax2=app.UIAxes4;
            % Compute a step
            dxi = (Xi(length(Xi))-Xi(1))/numEval;

            % Initialize the array of the coordinates on the parameter space
            xi = zeros(numEval+1,1);

            % Get the first coordinate on the NURBS parameter space
            xi(1) = Xi(1);

            % Loop over all sampling points
            for j=1:numEval+1

                % Initializations
                dhatX = 0;
                dhatY = 0;
                crossSectionalRotation = 0;

                % Get a coordinate on the NURBS parameter space
                if j ~= 1
                    xi(j) = xi(j-1) + dxi;
                end

                % Find the correct knot span
                knotSpan = app.findKnotSpan(xi(j),Xi,length(CP(:,1)));

                % Compute the NURBS basis functions and their derivatives
                if strcmp(graph.resultant,'displacement')
                    noDeriv = 1;
                else
                    noDeriv = 2;
                end
                dR = computeIGABasisFunctionsAndDerivativesForCurve...
                    (knotSpan,p,xi(j),Xi,CP,isNURBS,noDeriv);

                if strcmp(graph.resultant,'displacement')
                    % Compute iteratively the displacement field
                    for b = 0:p
                        if strcmp(analysis.type,'Bernoulli')
                            dhatX = dR(b+1,1)*dHat(2*(knotSpan-p+b)-1,1) + dhatX;
                            dhatY = dR(b+1,1)*dHat(2*(knotSpan-p+b),1) + dhatY;
                        elseif strcmp(analysis.type,'Timoshenko')
                            dhatX = dR(b+1,1)*dHat(3*(knotSpan-p+b)-2,1) + dhatX;
                            dhatY = dR(b+1,1)*dHat(3*(knotSpan-p+b)-1,1) + dhatY;
                        end
                    end
                    if strcmp(graph.component,'x')
                        resultant(j) = dhatX;
                    elseif strcmp(graph.component,'y')
                        resultant(j) = dhatY;
                    elseif strcmp(graph.component,'2norm')
                        resultant(j) = sqrt(dhatX^2+dhatY^2);
                    end
                elseif strcmp(graph.resultant,'crossSectionalRotation')
                    if strcmp(analysis.type,'Bernoulli')
                        error('The Bernoulli beam theory assumes no cross sectional deformation\n');
                    elseif strcmp(analysis.type,'Timoshenko')
                        for b = 0:p
                            crossSectionalRotation = crossSectionalRotation + dR(b+1,1)*dHat(3*(knotSpan-p+b),1);
                        end
                        resultant(j) = crossSectionalRotation;
                    end
                elseif strcmp(graph.resultant,'force')
                    % Compute the base vector of the reference configuration
                    [G,~] = app.computeBaseVectorAndNormalToNURBSCurve2D(knotSpan,p,xi,Xi,CP,dR);

                    % Compute the normal strain at the given parametric location
                    resultant(j) = computeNormalForceForIGABeams2D(knotSpan,p,dHat,parameters,analysis,G,dR);
                elseif strcmp(graph.resultant,'moment')
                    % Compute the base vector, its derivative and the normal to the
                    % curve and the acceleration vectors
                    [G,dG] = app.computeBaseVectorNormalToNURBSCurveAndDeivativesForCurve2D(knotSpan,p,CP,dR);

                    % Compute the bending moment at the parametric location
                    resultant(j) = computeBendingMomentForIGABeams2D(knotSpan,p,dHat,parameters,analysis,G,dG,dR);
                elseif strcmp(graph.resultant,'shearForce')
                    % Compute the base vector of the curve
                    [G,~] = app.computeBaseVectorAndNormalToNURBSCurve2D(knotSpan,p,CPd,dR);

                    % Compute the shear force at the parametric location
                    resultant(j) = computeShearForceForIGABeams2D(knotSpan,p,dHat,parameters,analysis,G,dR);
                end
            end

            % Plot the resultant array over the parameter space
            plot(ax2,xi,resultant);

            % Choose graphic options
            grid on;

            % Adjust the title of the subfigure
            if strcmp(graph.resultant,'displacement') && strcmp(graph.component,'x')
                title(ax2,'Vertical displacement u_x');
            elseif strcmp(graph.resultant,'displacement') && strcmp(graph.component,'y')
                title(ax2,'Vertical displacement u_y');
            elseif strcmp(graph.resultant,'displacement') && strcmp(graph.component,'2norm')
                title(ax2,'Displacement magnitude ||u||_2');
            elseif strcmp(graph.resultant,'crossSectionalRotation')
                title(ax2,'Cross sectional rotation omega');
            elseif strcmp(graph.resultant,'force')
                title(ax2,'Normal force n');
            elseif strcmp(graph.resultant,'moment')
                title(ax2,'Bending moment m');
            elseif strcmp(graph.resultant,'shearForce')
                title(ax2,'Shear force q');
            end

            % Update the index counter
            index = graph.index + 1;


        end


        function CPd = computeDisplacedControlPointsIGABeams2D ...
                (app,CP, dhat, analysis)


            % Initialize the deformed Control Point locations
            CPd = zeros(size(CP,1),4) ;

            if strcmp(analysis.type,'Bernoulli')
                for i = 1 : size(CP,1)
                    CPd(i,1) = CP(i,1) + dhat(2*i-1);
                    CPd(i,2) = CP(i,2) + dhat(2*i);
                    CPd(i,3) = CP(i,3);
                    CPd(i,4) = CP(i,4);
                end
            elseif strcmp(analysis.type,'Timoshenko')
                for i = 1 : size(CP,1)
                    CPd(i,1) = CP(i,1) + dhat(3*i-2);
                    CPd(i,2) = CP(i,2) + dhat(3*i-1);
                    CPd(i,3) = CP(i,3);
                    CPd(i,4) = CP(i,4);
                end
            end

        end


        function plotNURBSSurface(app,ax,propGeom, numPtsXi, numPtsEta, numBFs,label)
            if app.num_patch==1
                cla(ax);
            end
            ordDrv=0;
            minGeo = min(propGeom.controlPoints, [], "all");
            maxGeo = max(propGeom.controlPoints, [], "all");
            charLen = (maxGeo - minGeo)/20;
            numPtsX = numPtsXi;
            numPtsY = numPtsEta;
            xi = linspace(propGeom.knotVct{1}(1), propGeom.knotVct{1}(end), numPtsX);
            eta = linspace(propGeom.knotVct{2}(1), propGeom.knotVct{2}(end), numPtsY);
            Xsph = zeros(numel(xi), numel(eta), 3);

            for jj = 1:numel(eta)
                for ii = 1:numel(xi)
                    bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xi(ii); eta(jj)], ...
                        propGeom.weights, ordDrv);
                    dX = bfs*transpose(propGeom.controlPoints);
                    if app.dict_list{label}.isTrim==1
                        trim_polyline=transpose(app.dict_list{label}.TrimPolygon);
                        if inpolygon(eta(jj),xi(ii),trim_polyline(:,1),trim_polyline(:,2))
                            Xsph(ii, jj, 1:3) = NaN;
                        else
                            Xsph(ii, jj, 1:3) = dX(1, :);
                        end
                    else
                        Xsph(ii, jj, 1:3) = dX(1, :);
                    end

                end
            end
            axis(ax,'equal');
            ax.DataAspectRatio = [1 1 1];
            
            hf = surf(ax,Xsph(:, :, 1), Xsph(:, :, 2), Xsph(:, :, 3), FaceColor=[0,0,1], FaceAlpha=0.6, EdgeColor="none");
            if app.PatchLabelsCheckBox.Value==1
                text(ax, mean(Xsph(:, :, 1), 'all'), mean(Xsph(:, :, 2), 'all'), mean(Xsph(:, :, 3), 'all'), num2str(label), 'FontSize', 30, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'Color', 'red');
            end
            hold(ax,'on');

            if app.ControlPolygonCheckBox.Value==1
                ControlPolygon = zeros(numBFs(2), numBFs(1), 3);
                ControlPolygon(:, :, 1) = transpose(reshape(propGeom.controlPoints(1, :), numBFs(1), []));
                ControlPolygon(:, :, 2) = transpose(reshape(propGeom.controlPoints(2, :), numBFs(1), []));
                ControlPolygon(:, :, 3) = transpose(reshape(propGeom.controlPoints(3, :), numBFs(1), []));
                if app.Loop==2
                    surf(ax,ControlPolygon(:, :, 1), ControlPolygon(:, :, 2), ControlPolygon(:, :, 3), FaceColor="none", ...
                        LineStyle="-.", EdgeColor="red", LineWidth=1, Marker="o", MarkerFaceColor="red",MarkerSize=4)
                else
                    surf(ax,ControlPolygon(:, :, 1), ControlPolygon(:, :, 2), ControlPolygon(:, :, 3), FaceColor="none", ...
                        LineStyle="-.", EdgeColor="magenta", LineWidth=1, Marker="o", MarkerFaceColor="magenta",MarkerSize=4)
                end


                if app.NumandIDsCheckBox.Value==1
                    counter = 1;
                    for jj = 1:numBFs(1)
                        for ii = 1:numBFs(2)
                            text(ax,ControlPolygon(ii, jj, 1) + charLen, ControlPolygon(ii, jj, 2) + charLen, ...
                                ControlPolygon(ii, jj, 3) + charLen, sprintf("CP$_{%d}$", counter), Interpreter="latex");
                            counter = counter + 1;
                        end
                    end
                end
            end
            if app.ElementsCheckBox.Value==1
                knotXiVctUnique = unique(propGeom.knotVct{1});
                knotEtaVctUnique = unique(propGeom.knotVct{2});

                for ii = 1:numel(knotXiVctUnique)
                    xiKnot = knotXiVctUnique(ii);
                    XsphXiKnots = zeros(numel(eta), 3);
                    for jj = 1:numel(eta)
                        bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xiKnot; eta(jj)], ...
                            propGeom.weights,ordDrv);
                        XsphXiKnots(jj, :) = bfs*transpose(propGeom.controlPoints);
                    end
                    plot3(ax,XsphXiKnots(:, 1), XsphXiKnots(:, 2), XsphXiKnots(:, 3), 'k-', LineWidth=1);
                    if app.NumandIDsCheckBox.Value==1
                        bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xiKnot; mean([eta(1), eta(end)])], ...
                            propGeom.weights,ordDrv);
                        XsphXiMidKnot = bfs*transpose(propGeom.controlPoints);
                        text(ax,XsphXiMidKnot(1) + charLen, XsphXiMidKnot(2) + charLen, ...
                            XsphXiMidKnot(3) + charLen, sprintf("$\\xi$=%1.1f", xiKnot), Interpreter="latex");
                    end
                end
                for ii = 1:numel(knotEtaVctUnique)
                    etaKnot = knotEtaVctUnique(ii);
                    XsphEtaKnots = zeros(numel(xi), 3);
                    for jj = 1:numel(xi)
                        bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xi(jj); etaKnot], ...
                            propGeom.weights,ordDrv);
                        XsphEtaKnots(jj, :) = bfs*transpose(propGeom.controlPoints);
                    end
                    plot3(ax,XsphEtaKnots(:, 1), XsphEtaKnots(:, 2), XsphEtaKnots(:, 3), 'k-', LineWidth=1);
                    if app.NumandIDsCheckBox.Value==1
                        bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [mean([xi(1), xi(end)]); etaKnot], ...
                            propGeom.weights,ordDrv);
                        XsphEtaMidKnot = bfs*transpose(propGeom.controlPoints);
                        text(ax,XsphEtaMidKnot(1) + charLen, XsphEtaMidKnot(2) + charLen, ...
                            XsphEtaMidKnot(3) + charLen, sprintf("$\\eta$=%1.1f", etaKnot), Interpreter="latex");
                    end
                end
            end

            if app.NumandIDsCheckBox.Value==1 && app.ElementsCheckBox.Value==1
                counter = 1;
                for jj = 1:length(knotEtaVctUnique) - 1
                    for ii = 1:length(knotXiVctUnique) - 1
                        bfs = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, ...
                            [mean(knotXiVctUnique(ii:ii + 1)); mean(knotEtaVctUnique(jj:jj + 1))], ...
                            propGeom.weights,ordDrv);
                        XsphMidKnotSpan = bfs*transpose(propGeom.controlPoints);
                        text(ax,XsphMidKnotSpan(1) + charLen/2, XsphMidKnotSpan(2) + charLen/2, ...
                            XsphMidKnotSpan(3) + charLen/2, sprintf("e$_{%d}$", counter), Interpreter="latex", FontSize=12);
                        counter = counter + 1;
                    end
                end
            end


            xlabel(ax,"$X$", Interpreter="latex")
            ylabel(ax,"$Y$", Interpreter="latex")
            zlabel(ax,"$Z$", Interpreter="latex")
            title(ax,"NURBS surface")
            camlight(ax,"right")


            if ~any(diff(propGeom.controlPoints(3, :)))
                xSzMin = min(propGeom.controlPoints(1, :));
                xSzMax = max(propGeom.controlPoints(1, :));
                xSz = abs(xSzMax - xSzMin);
                xlim(ax,[xSzMin-xSz/10 xSzMax+xSz/10]);
                ySzMin = min(propGeom.controlPoints(2, :));
                ySzMax = max(propGeom.controlPoints(2, :));
                ySz = abs(ySzMax - ySzMin);
                ylim(ax,[ySzMin-ySz/10 ySzMax+ySz/10]);
                zSz = propGeom.controlPoints(3, 1);
                if zSz
                    zlim(ax,[zSz-zSz/10 zSz+zSz/10]);
                else
                    zlim(ax,[zSz-1/10 zSz+1/10]);
                end
            end
            hold (ax,'off');

        end


        function colmatw = computeNURBSBasisFunctions2d(app,knotVcts, polOrds, xis, wmat, ordDervs)

            polOrds=double(polOrds);
            bfsXi = app.computeBSplineBasisFunctions1d(knotVcts{1}, polOrds(1), brk2knt(xis(1), ordDervs + 1));
            bfsEta = app.computeBSplineBasisFunctions1d(knotVcts{2}, polOrds(2), brk2knt(xis(2), ordDervs + 1));
            colmat = reshape(kron(bfsEta, bfsXi), [], size(bfsXi, 2)*size(bfsEta, 2));
            numCPs=[ numel(app.changeProp.knotVct{1})-app.changeProp.polOrd(1)-1;
                numel(app.changeProp.knotVct{2})-app.changeProp.polOrd(2)-1];
            numCPs=double(numCPs);
            knotSpanIds = [app.findKnotSpan(xis(1), knotVcts{1}, numCPs(1))
                app.findKnotSpan(xis(2), knotVcts{2}, numCPs(2))];
            dF = sum(colmat.*wmat, 2);
            colmatw = zeros(size(colmat));
            for jj = 1:polOrds(2) + 1
                for ii = 1:polOrds(1) + 1
                    for ll = 0:ordDervs
                        for kk = 0:ordDervs
                            indexBFXi = knotSpanIds(1) - polOrds(1) + ii - 1;
                            indexBFEta = knotSpanIds(2) - polOrds(2) + jj - 1;
                            indexBF = (indexBFEta - 1)*numCPs(1) + indexBFXi;
                            indexBSplineDervs = ll*(ordDervs + 1) + kk + 1;
                            eta = colmat(indexBSplineDervs, indexBF)*wmat(1, indexBF);
                            for mm = 1:kk
                                indexDerv = ll*(ordDervs + 1) + kk - mm + 1;
                                indexDervF = 0*(ordDervs + 1) + mm + 1;
                                eta = eta - nchoosek(kk, mm)*dF(indexDervF, 1)*colmatw(indexDerv, indexBF);
                            end
                            for nn = 1:ll
                                indexDerv = (ll - nn)*(ordDervs + 1) + kk + 1;
                                indexDervF = nn*(ordDervs + 1) + 1;
                                eta = eta - nchoosek(ll, nn)*dF(indexDervF, 1)*colmatw(indexDerv, indexBF);
                                v2 = 0;
                                for oo = 1:kk
                                    indexDerv = (ll - nn)*(ordDervs + 1) + kk - oo + 1;
                                    indexDervF = nn*(ordDervs + 1) + oo + 1;
                                    v2 = v2 + nchoosek(kk, oo)*dF(indexDervF, 1)*colmatw(indexDerv, indexBF);
                                end
                                eta = eta - nchoosek(ll, nn)*v2;
                            end
                            colmatw(indexBSplineDervs, indexBF) = eta/dF(1,1);
                        end
                    end
                end
            end

        end


        function performOrderElevationForNURBSSurface(app, dp, dq)
                
            if strcmp(app.PatchDropDown.Value,"all patches")==1
                patch_counter=app.num_patch;
            else
                patch_counter=1;
            end

            for i=1:patch_counter
                if patch_counter==1
                    patch=round(str2double(app.PatchDropDown.Value));
                else
                    patch=i;
                end

                p = double(app.dict_list{patch}.vDeg);
                q = double(app.dict_list{patch}.uDeg);
                Xi = app.dict_list{patch}.vknotVector;
                Eta = app.dict_list{patch}.uknotVector;

                % Number of knots of the unrefined geometry
                numKnotsXi = numel(Xi);
                numKnotsEta = numel(Eta);

                % Number of Control Points in xi- and in eta-directions
                numCPsXi = numKnotsXi - p - 1;
                numCPsEta = numKnotsEta - q - 1;

                % Arrange the control points in a three-dimensional array including the
                % weights
                CP = zeros(numCPsXi, numCPsEta, 4);
                CP(:, :, 1:3) = reshape(transpose(app.dict_list{patch}.controlPoints), numCPsXi, numCPsEta, 3);
                CP(:, :, 4) = reshape(transpose(app.dict_list{patch}.weights), numCPsXi, numCPsEta);

                % Polynomial degree of the curve after refinement
                pPRef = p + dp;
                qPRef = q + dq;

                % Initializations
                Pw = zeros(numCPsXi, numCPsEta, 4);
                Qw = zeros(numCPsXi, numCPsEta, 4);

                %% 1.Compute the projective control points Pw
                for i = 1:numCPsXi
                    for j = 1:numCPsEta
                        Pw(i, j, 1:3) = CP(i, j, 1:3)*CP(i, j, 4);
                        Pw(i, j, 4) = CP(i, j, 4);
                    end
                end

                %% 2. Perform degree elevation in xi-direction

                % Initializations
                bezalfs = zeros(pPRef + 1, p + 1);
                bpts = zeros(p + 1, length(CP(1, :, 1)), length(CP(1, 1, :)));
                ebpts = zeros(pPRef + 1, length(CP(1, :, 1)), length(CP(1, 1, :)));
                Nextbpts = zeros(p - 1, length(CP(1, :, 1)), length(CP(1, 1, :)));

                % If the given polynomial degree is a positive integer
                if dp > 0
                    % preallocate Ur
                    ku = 1;
                    for i = 1:numKnotsXi - 1
                        if Xi(i) ~= Xi(i+1)
                            ku = ku + 1;
                        end
                    end

                    Xir = zeros(1, numKnotsXi + dp*ku);

                    % compute Bezier degree elevation coefficients
                    bezalfs(1, 1) = 1.0;
                    bezalfs(pPRef + 1, p + 1) = 1.0;

                    for i = 1:fix(pPRef/2)   % bezalfs are symmetric to row pr/2
                        inv = 1/nchoosek(pPRef, i);
                        mpi = min(p, i);

                        % alfas for degree elevation
                        for j = max(0, i - dp):mpi
                            bezalfs(i + 1, j + 1) = inv*nchoosek(p, j)*nchoosek(dp, i - j);

                            % Apply symmetry
                            bezalfs(pPRef - i + 1, p - j + 1) = bezalfs(i + 1, j + 1);
                        end
                    end

                    mh = pPRef;
                    kind = pPRef + 1;
                    r= -1;
                    a = p + 1;
                    b = p + 2;
                    cind = 1;
                    ua = Xi(a);
                    Qw(1, :, :) = Pw(1, :, :);

                    % left end of Ur
                    for i = 1:pPRef + 1
                        Xir(i) = ua;
                    end

                    % initialize first Bezier seg
                    for i = 1:p + 1
                        bpts(i, :, :) = Pw(i, :, :);
                    end

                    % big loop through knot vector
                    while b < numKnotsXi
                        i = b;

                        while b < numKnotsXi && Xi(b) == Xi(b + 1)
                            % make b the rightmost ocurrence of ub
                            b = b + 1;
                        end

                        % multiplicity of ub
                        mult = b - i + 1;
                        mh = mh + mult + dp;
                        ub = Xi(b);

                        % r from last segment
                        oldr = r;

                        % ub to be inserted r times
                        r = p - mult;

                        if oldr > 0
                            lbz = fix((oldr + 2)/2);
                        else
                            lbz = 1;
                        end

                        if r > 0
                            rbz = pPRef - fix((r + 1)/2);
                        else
                            rbz = pPRef;
                        end

                        % insert knots to get Bezier segment
                        alphas = zeros(1, p - mult);
                        if (r > 0)
                            numer = ub - ua;

                            for k = p:-1:mult + 1
                                % alpha for knot insertion
                                alphas(k - mult) = numer/(Xi(a + k) - ua);
                            end

                            % r times knot insertion
                            for j = 1:r
                                save = r - j + 1;

                                s = mult + j;

                                % new CP due to knot insertion
                                for k = p + 1:-1:s + 1
                                    bpts(k, :, :) = alphas(k - s)*bpts(k, :, :) + ...
                                        (1 - alphas(k - s))*bpts(k - 1, :, :);
                                end

                                Nextbpts(save, :, :) = bpts(p + 1, :, :);
                            end
                        end

                        % degree elevate Bezier
                        for i = lbz:pPRef
                            % only points lbz,..,pr are used below
                            ebpts(i + 1, 1:numCPsEta, 1:4) = 0;
                            mpi = min(p, i);

                            % new CP due to degree elevation
                            for j = max(0, i - dp):mpi
                                ebpts(i + 1, :, :) = ebpts(i + 1, :, :) + ...
                                    bezalfs(i + 1,j + 1)*bpts(j + 1, :, :);
                            end
                        end

                        % knot removal ua oldr times
                        if oldr > 1
                            first = kind - 2;
                            last = kind;
                            den = ub - ua;
                            beta = (ub - Xir(kind))/den;
                            for tr = 1:oldr-1
                                i = first;
                                j = last;
                                kj = j - kind + 1;

                                while j - i > tr
                                    % loop and compute the new CP for one removal step
                                    if i < cind
                                        alpha = (ub - Xir(i + 1))/(ua - Xir(i + 1));
                                        Qw(i + 1, :, :) = (alpha*Qw(i + 1, :, :) + ...
                                            (1.0 - alpha)*Qw(i, :, :));
                                    end

                                    if j >= lbz
                                        if j - tr <= kind - pPRef + oldr
                                            gamma = (ub - Xir(j - tr + 1))/den;
                                            ebpts(kj + 1, :, :) = gamma*ebpts(kj + 1, :, :) + ...
                                                (1.0 - gamma)*ebpts(kj + 2, :, :);
                                        else
                                            ebpts(kj + 1, :, :) = beta*ebpts(kj + 1, :, :) + ...
                                                (1.0 - beta)*ebpts(kj + 2, :, :);
                                        end
                                    end
                                    i = i + 1;
                                    j = j - 1;
                                    kj = kj - 1;
                                end
                                first = first - 1;
                                last = last + 1;
                            end
                        end
                        % end of removing knot, u=U(a)

                        if (a ~= p + 1)
                            % load the knot ua
                            for i = 0:(pPRef - oldr - 1)
                                Xir(kind + 1) = ua;
                                kind = kind + 1;
                            end
                        end

                        for j = lbz:rbz
                            % load CPs into Qw
                            Qw(cind + 1, :, :) =  ebpts(j + 1, :, :);
                            cind = cind + 1;
                        end

                        % setup for the next pass through loop
                        if b < numKnotsXi
                            for j = 0:r - 1
                                bpts(j + 1, :, :) = Nextbpts(j + 1, :, :);
                            end
                            for j = r:p
                                bpts(j + 1, :, :) = Pw(b - p + j, :, :);
                            end
                            a = b;
                            b = b + 1;
                            ua = ub;
                        else   % end knots
                            for i = 0:pPRef
                                Xir(kind + i + 1) = ub;
                            end
                        end
                    end
                    % end of big loop through knot vector
                elseif dp == 0
                    Xir = Xi;
                    pPRef = p;
                    Qw = Pw;
                end

                %% 3. Perform degree elevation in eta-direction

                % Initializations
                bezalfs = zeros(qPRef + 1, q + 1);
                bpts = zeros(q + 1, length(CP(1, :, 1)));
                ebpts = zeros(qPRef + 1, length(CP(1, :, 1)));
                Nextbpts = zeros(q - 1, length(CP(1, :, 1)));

                % If the given polynomial degree is a positive integer
                if dq > 0
                    clear ('Pw');
                    Pw(:, :, :) = Qw(:, :, :);
                    numCPsXi = length(Pw(:, 1, 1));
                    clear ('Qw');
                    clear ('bpts');
                    clear ('Nextbpts');
                    clear ('ebpts');
                    clear ('bezalfs');

                    % preallocate Vr
                    kv = 1;
                    for i = 1:numKnotsEta - 1
                        if Eta(i) ~= Eta(i + 1)
                            kv = kv + 1;
                        end
                    end
                    Etar = zeros(1, numKnotsEta + dq*kv);

                    % compute Bezier degree elevation coefficients
                    bezalfs(1, 1) = 1.0;
                    bezalfs(qPRef + 1, q + 1) = 1.0;

                    % bezalfs are symmetric to row qr/2
                    for i = 1:fix(qPRef/2)
                        inv = 1/nchoosek(qPRef, i);
                        mpi = min(q, i);

                        % alfas for degree elevation
                        for j = max(0, i - dq):mpi
                            bezalfs(i + 1, j + 1) = inv*nchoosek(q, j)*nchoosek(dq, i - j);

                            % Apply the symmetry
                            bezalfs(qPRef - i + 1, q - j + 1) = bezalfs(i + 1, j + 1);
                        end
                    end

                    mh = qPRef;
                    kind = qPRef + 1;
                    r = -1;
                    a = q + 1;
                    b = q + 2;
                    cind = 1;
                    ua = Eta(a);
                    Qw(:, 1, :) = Pw(:, 1, :);

                    % left end of Vr
                    for i = 1:qPRef + 1
                        Etar(i) = ua;
                    end

                    % initialize first Bezier seg
                    for i = 1:q + 1
                        bpts(:, i, :) = Pw(:, i, :);
                    end

                    % big loop through knot vector
                    while b < numKnotsEta
                        i = b;
                        while b < numKnotsEta && Eta(b) == Eta(b+1)
                            % make b the rightmost ocurrence of ub
                            b = b + 1;
                        end

                        % multiplicity of ub
                        mult = b - i + 1;
                        mh = mh + mult + dq;
                        ub = Eta(b);

                        % r from last segment
                        oldr = r;

                        % ub to be inserted r times
                        r = q - mult;

                        if oldr > 0
                            lbz = fix((oldr + 2)/2);
                        else
                            lbz = 1;
                        end

                        if r > 0
                            rbz = qPRef - fix((r + 1)/2);
                        else
                            rbz = qPRef;
                        end

                        % insert knots to get Bezier segment
                        if r > 0
                            numer = ub - ua;
                            for k = q:-1:mult + 1
                                % alfa for knot insertion
                                alphas(k - mult) = numer/(Eta(a + k) - ua);
                            end

                            % r times knot insertion
                            for j = 1:r
                                save = r - j + 1;
                                s = mult + j;

                                % new CP due to knot insertion
                                for k = q + 1:-1:s + 1
                                    bpts(:, k, :) = alphas(k - s)*bpts(:, k, :) + ...
                                        (1 - alphas(k - s))*bpts(:,k - 1,:);
                                end
                                Nextbpts(:, save, :) = bpts(:, q + 1, :);
                            end
                        end

                        % degree elevate Bezier
                        for i = lbz:qPRef
                            % only points lbz,..,qr are used below
                            ebpts(1:numCPsXi, i + 1, 1:4) = 0;
                            mpi = min(q, i);

                            % new CP due to degree elevation
                            for j = max(0, i - dq):mpi
                                ebpts(:, i + 1, :) = ebpts(:, i + 1, :) + ...
                                    bezalfs(i + 1, j + 1)*bpts(:, j + 1, :);
                            end
                        end

                        % knot removal ua oldr times
                        if oldr > 1
                            first = kind - 2;
                            last = kind;
                            den = ub - ua;
                            beta = (ub - Etar(kind))/den;
                            for tr = 1:oldr-1
                                i = first;
                                j = last;
                                kj = j - kind + 1;

                                % loop and compute the new CP for one removal step
                                while j - i > tr
                                    if i < cind
                                        alpha = (ub - Etar(i+1))/(ua - Etar(i+1));
                                        Qw(:, i + 1, :) = (alpha*Qw(:, i + 1, :) + ...
                                            (1.0 - alpha)*Qw(:, i, :));
                                    end
                                    if j >= lbz
                                        if ((j - tr) <= (kind - qPRef + oldr))
                                            gamma = (ub - Etar(j - tr + 1))/den;
                                            ebpts(:, kj + 1, :) = gamma*ebpts(:, kj + 1, :) + ....
                                                (1.0 - gamma)*ebpts(:, kj + 2, :);
                                        else
                                            ebpts(:, kj + 1, :) = beta*ebpts(:, kj + 1, :) + ...
                                                (1.0 - beta)*ebpts(:, kj + 2, :);
                                        end
                                    end
                                    i = i + 1;
                                    j = j - 1;
                                    kj = kj - 1;
                                end
                                first = first-1;
                                last = last+1;
                            end
                        end
                        % end of removing knot, u = V(a)

                        % load the knot ua
                        if a ~= q + 1
                            for i = 0:qPRef - oldr - 1
                                Etar(kind + 1) = ua;
                                kind = kind + 1;
                            end
                        end

                        % load CPs into Qw
                        for j = lbz:rbz
                            Qw(:, cind + 1, :) =  ebpts(:, j + 1, :);
                            cind = cind +1;
                        end

                        % setup for the next pass through loop
                        if b < numKnotsEta
                            for j = 0:r - 1
                                bpts(:, j + 1, :) = Nextbpts(:, j + 1, :);
                            end
                            for j = r:q
                                bpts(:, j + 1, :) = Pw(:, b - q + j, :);
                            end
                            a = b;
                            b = b + 1;
                            ua = ub;

                            % end knots
                        else
                            for i = 0:qPRef
                                Etar(kind + i + 1) = ub;
                            end
                        end
                    end
                    % end of big loop through knot vector

                elseif dq == 0
                    Etar = Eta;
                    qPRef = q;
                end

                %% 4. Retransform from 4th dimensional to the 3D space
                app.change_dict_list{patch}.vknotVector=Xir;
                app.change_dict_list{patch}.uknotVector=Etar;
                app.change_dict_list{patch}.vDeg=pPRef;
                app.change_dict_list{patch}.uDeg=qPRef;
                app.change_dict_list{patch}.weights = transpose(reshape(Qw(:, :, 4), [], 1));
                app.change_dict_list{patch}.controlPoints = transpose(reshape(Qw(:, :, 1:3), [], 3))./ ...
                    app.change_dict_list{patch}.weights;


            end
        end


        function performKnotRefinementNURBSSurface(app, numKnotsXi, numKnotsEta)
            
            if strcmp(app.PatchDropDown.Value,"all patches")==1
                patch_counter=app.num_patch;
            else
                patch_counter=1;
            end

            for i=1:patch_counter
                if patch_counter==1
                    patch=round(str2double(app.PatchDropDown.Value));
                else
                    patch=i;
                end
                propGeomRef.controlPoints=app.deg_dict_list{patch}.controlPoints;
                propGeomRef.weights=app.deg_dict_list{patch}.weights;
                propGeomRef.knotVct={app.deg_dict_list{patch}.vknotVector,app.deg_dict_list{patch}.uknotVector };
                propGeomRef.dim=app.dim;
                propGeomRef.polOrd=[app.deg_dict_list{patch}.vDeg; app.deg_dict_list{patch}.uDeg];
                knotsXiRef = linspace(app.deg_dict_list{patch}.vknotVector(1), app.deg_dict_list{patch}.vknotVector(end), numKnotsXi + 2);
                knotsXiRef([1 end]) = [];
                knotsEtaRef = linspace(app.deg_dict_list{patch}.uknotVector(1), app.deg_dict_list{patch}.uknotVector(end), numKnotsEta + 2);
                knotsEtaRef([1 end]) = [];


                numBFs=[ numel(propGeomRef.knotVct{1})-propGeomRef.polOrd(1)-1;
                    numel(propGeomRef.knotVct{2})-propGeomRef.polOrd(2)-1];
                num=double(numBFs);
                num1 = num(1);
                num2 = num(2);
                if knotsXiRef~=0
                    propGeomEtaCurves.dim = 1;
                    propGeomEtaCurves.knotVct = propGeomRef.knotVct{1};
                    propGeomEtaCurves.polOrd = propGeomRef.polOrd(1);
                    propGeomEtaCurves.controlPoints = reshape(permute(reshape(propGeomRef.controlPoints, [3, num1, num2]), [1, 3, 2]), [3*num2, num1]);
                    propGeomEtaCurves.weights = reshape(permute(reshape(repmat(propGeomRef.weights, [3, 1]), [3, num1, num2]), [1, 3, 2]), [3*num2, num1]);
                    propGeomEtaCurves = app.performUniformKnotRefinementNURBSCurve(propGeomEtaCurves, size(knotsXiRef,2));
                    num1 = size(propGeomEtaCurves.controlPoints, 2);
                    propGeomRef.knotVct{1} = propGeomEtaCurves.knotVct;
                    propGeomRef.controlPoints = reshape(permute(reshape(propGeomEtaCurves.controlPoints, [3, num2, num1]), [1, 3, 2]), [3, num1*num2]);
                    propGeomRef.weights = reshape(permute(reshape(propGeomEtaCurves.weights(1:3:end, :), [1, num2, num1]), [1, 3, 2]), [1, num1*num2]);
                end

                if knotsEtaRef~=0
                    propGeomXiCurves.dim = 1;
                    propGeomXiCurves.knotVct = propGeomRef.knotVct{2};
                    propGeomXiCurves.polOrd = propGeomRef.polOrd(2);
                    propGeomXiCurves.controlPoints = reshape(propGeomRef.controlPoints, [3*num1, num2]);
                    propGeomXiCurves.weights = reshape(repmat(propGeomRef.weights, 3, 1), [3*num1, num2]);
                    propGeomXiCurves = app.performUniformKnotRefinementNURBSCurve(propGeomXiCurves, size(knotsEtaRef,2));
                    num2 = size(propGeomXiCurves.controlPoints, 2);
                    propGeomRef.knotVct{2} = propGeomXiCurves.knotVct;
                    propGeomRef.controlPoints = reshape(propGeomXiCurves.controlPoints, [3, num1*num2]);
                    propGeomRef.weights = reshape(propGeomXiCurves.weights(1:3:end, :), [1, num1*num2]);
                end

                app.change_dict_list{patch}.vknotVector = propGeomRef.knotVct{1};
                app.change_dict_list{patch}.uknotVector = propGeomRef.knotVct{2};
                app.change_dict_list{patch}.controlPoints = propGeomRef.controlPoints;
                app.change_dict_list{patch}.weights = propGeomRef.weights;
            end


        end


        function DOFNum = findDOFsNURBSSurface(app,DOFNum, propGeom, xiSpan, etaSpan, numDOFsPerCP, idField)


            % Initialize counter
            counter = length(DOFNum) + 1;
            % Number of control points in xi-, and eta-directions
            numCPsXi = double(numel(propGeom.knotVct{1}) - propGeom.polOrd(1, 1) - 1);
            numCPsEta = double(numel(propGeom.knotVct{2}) - propGeom.polOrd(2, 1) - 1);

            %% 2. Update the array containing the DOF numbering across the specified knot span range
            for jj = etaSpan(1)*(numCPsEta - 1) + 1:etaSpan(2)*(numCPsEta - 1) + 1
                for ii = xiSpan(1)*(numCPsXi - 1) + 1:xiSpan(2)*(numCPsXi - 1) + 1

                    % Assign the DOF numbering based on the Control Point numbering and
                    % Cartesian direction and round to nearest integer
                    DOFNumbs = round(numDOFsPerCP*((jj - 1)*numCPsXi + ii-1) + idField);
                    DOFNum(counter:counter+numel(DOFNumbs)-1) = DOFNumbs;

                    % Update counter
                    counter = counter + numel(DOFNumbs);
                end
            end

            %% 3. Sort the resulting vector of DOF numberings and remove doublicate entries
            DOFNum = sort(unique(DOFNum));

        end


        function [stiffMtxEl, loadVctEl] = computeIGAElStiffMtxandForceVctReissnerMindlinPlate ...
                (app,propGeom, el, detJxiTildexi, propStr)

            numBFsXi = double(numel(propGeom.knotVct{1}) - propGeom.polOrd(1, 1) - 1);
            numBFsEta = double(numel(propGeom.knotVct{2}) - propGeom.polOrd(2, 1) - 1);
            numCPsEl = prod(propGeom.polOrd + 1);
            numDOFsEl = 3*numCPsEl;
            stiffMtxEl = zeros(numDOFsEl);
            loadVctEl = zeros(numDOFsEl, 1);

            Cmtx = [propStr.alpha*propStr.G*propStr.t 0 0 0 0
                0 propStr.alpha*propStr.G*propStr.t 0 0 0
                0 0 propStr.D propStr.nu*propStr.D  0
                0 0 propStr.nu*propStr.D propStr.D 0
                0 0 0 0 propStr.D/2*(1 - propStr.nu)];

            numGPs = propGeom.polOrd + 1;
            [xiTildeGP, GWxi] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs(1, 1));
            [etaTildeGP, GWeta] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs(2, 1));

            for jj = 1:numel(etaTildeGP)
                for ii = 1:numel(xiTildeGP)

                    xi = ((1 - xiTildeGP(ii))*el(1) + (1 + xiTildeGP(ii))*el(2))/2;
                    eta = ((1 - etaTildeGP(jj))*el(3) + (1 + etaTildeGP(jj))*el(4))/2;

                    knotSpanXi = app.findKnotSpan(xi, propGeom.knotVct{1}, numBFsXi);
                    knotSpanEta = app.findKnotSpan(eta, propGeom.knotVct{2}, numBFsEta);

                    ordDervs = 1;
                    dR = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xi; eta], propGeom.weights, ordDervs);

                    rangeXi = knotSpanXi-propGeom.polOrd(1, 1):knotSpanXi;
                    rangeEta = knotSpanEta-propGeom.polOrd(2, 1):knotSpanEta;
                    [kk, ll] = ndgrid(rangeXi, rangeEta);
                    dR = dR(:, (ll(:)-1)*numBFsXi + kk(:));
                    controlPoints = propGeom.controlPoints(:, (ll(:)-1)*numBFsXi + kk(:));

                    Rmtx = zeros(3, numDOFsEl);
                    Rmtx(1, 3*(1:numCPsEl) - 2) = dR(1, 1:numCPsEl);
                    Rmtx(2, 3*(1:numCPsEl) - 1) = dR(1, 1:numCPsEl);
                    Rmtx(3, 3*(1:numCPsEl)) = dR(1, 1:numCPsEl);

                    JmtxT = dR(2:3, :)*transpose(controlPoints(1:2, :));
                    dRdX = JmtxT\dR(2:3, :);
                    Bmtx = zeros(5, numDOFsEl);
                    Bmtx(1, 3*(1:numCPsEl) - 2) = dRdX(1, 1:numCPsEl);
                    Bmtx(1, 3*(1:numCPsEl)) = dR(1, 1:numCPsEl);
                    Bmtx(2, 3*(1:numCPsEl)- 2) = dRdX(2, 1:numCPsEl);
                    Bmtx(2, 3*(1:numCPsEl) - 1) = -dR(1, 1:numCPsEl);
                    Bmtx(3, 3*(1:numCPsEl)) = dRdX(1, 1:numCPsEl);
                    Bmtx(4, 3*(1:numCPsEl) - 1) = -dRdX(2, 1:numCPsEl);
                    Bmtx(5, 3*(1:numCPsEl) - 1) = -dRdX(1, 1:numCPsEl);
                    Bmtx(5, 3*(1:numCPsEl)) = dRdX(2, 1:numCPsEl);

                    stiffMtxEl = stiffMtxEl + transpose(Bmtx)*Cmtx*Bmtx*detJxiTildexi*det(JmtxT)*GWxi(ii)*GWeta(jj);
                    loadVctEl = loadVctEl + transpose(Rmtx)*[propStr.pBar; propStr.mxBar; propStr.myBar]* ...
                        detJxiTildexi*det(JmtxT)*GWxi(ii)*GWeta(jj);
                end
            end


        end


        function [stiffMtxEl, loadVctEl] = computeIGAElStiffMtxandForceVctTimoshenkoBeam ...
                (app, propGeom, el, propStr)

            numCPsEl = propGeom.polOrd + 1;
            numDOFsEl = 2*numCPsEl;
            stiffMtxEl = zeros(numDOFsEl);
            loadVctEl = zeros(numDOFsEl, 1);
            matMtx = [propStr.alpha*propStr.G*propStr.A 0
                0                                 propStr.E*propStr.I];
            numGP = propGeom.polOrd + 1;
            [xiTildeGP, GWxi] = app.getGaussPointsAndWeightsOverUnitDomain(numGP);

            detJxiTildexi = (el(2) - el(1))/2;

            for ii = 1:numel(xiTildeGP)

                xi = ((1 - xiTildeGP(ii))*el(1) + (1 + xiTildeGP(ii))*el(2))/2;

                knotSpan = app.findKnotSpan(xi, propGeom.knotVct, size(propGeom.controlPoints, 2));

                ordDerv = 1;
                dR = app.computeNURBSBasisFunctions1d(propGeom.knotVct, propGeom.polOrd, xi, propGeom.weights, ordDerv);

                dR = dR(:, knotSpan-propGeom.polOrd:knotSpan);
                controlPoints = propGeom.controlPoints(:, knotSpan-propGeom.polOrd:knotSpan);

                Jmtx = dR(2, :)*transpose(controlPoints(1, :));

                dRdX = Jmtx\dR(2, :);

                Rmtx = zeros(2, numDOFsEl);
                for jj = 1:numCPsEl
                    Rmtx(1, 2*jj - 1) = dR(1, jj);
                    Rmtx(2, 2*jj) = dR(1, jj);
                end

                Bmtx = zeros(2, numDOFsEl);
                for jj = 1:numCPsEl
                    Bmtx(1, 2*jj - 1) = dRdX(1, jj);
                    Bmtx(1, 2*jj) = dR(1, jj);
                    Bmtx(2, 2*jj) = dRdX(1, jj);
                end

                stiffMtxEl = stiffMtxEl + transpose(Bmtx)*matMtx*Bmtx*detJxiTildexi*det(Jmtx)*GWxi(ii);
                loadVctEl = loadVctEl + transpose(Rmtx)*[propStr.qBar
                    propStr.mBar]*detJxiTildexi*det(Jmtx)*GWxi(ii);

            end

        end


        function [stiffMtxEl, loadVctEl] = computeIGAElStiffMtxandForceVctReissnerMindlinPlateTrim ...
                (app,propGeom, detJac_tri_vec, propStr,GP_T_par,GW_T_par)
            numBFsXi = numel(propGeom.knotVct{1}) - propGeom.polOrd(1, 1) - 1;
            numBFsEta = numel(propGeom.knotVct{2}) - propGeom.polOrd(2, 1) - 1;
            numCPsEl = prod(propGeom.polOrd + 1);
            numDOFsEl = 3*numCPsEl;
            stiffMtxEl = zeros(numDOFsEl);
            loadVctEl = zeros(numDOFsEl, 1);
            Cmtx = [propStr.alpha*propStr.G*propStr.t 0                                 0                    0                     0
                0                                 propStr.alpha*propStr.G*propStr.t 0                    0                     0
                0                                 0                                 propStr.D            propStr.nu*propStr.D  0
                0                                 0                                 propStr.nu*propStr.D propStr.D             0
                0                                 0                                 0                    0                     propStr.D/2*(1 - propStr.nu)];
            for jj = 1:size(GP_T_par,1)

                xi = GP_T_par(jj,1);
                eta = GP_T_par(jj,2);
                knotSpanXi = app.findKnotSpan(xi, propGeom.knotVct{1}, numBFsXi);
                knotSpanEta = app.findKnotSpan(eta, propGeom.knotVct{2}, numBFsEta);

                ordDervs = 1;
                dR = app.computeNURBSBasisFunctions2d(propGeom.knotVct, propGeom.polOrd, [xi; eta], propGeom.weights, ordDervs);
                rangeXi = knotSpanXi-propGeom.polOrd(1, 1):knotSpanXi;
                rangeEta = knotSpanEta-propGeom.polOrd(2, 1):knotSpanEta;
                [kk, ll] = ndgrid(rangeXi, rangeEta);
                dR = dR(:, (ll(:)-1)*numBFsXi + kk(:));
                controlPoints = propGeom.controlPoints(:, (ll(:)-1)*numBFsXi + kk(:));

                Rmtx = zeros(3, numDOFsEl);
                Rmtx(1, 3*(1:numCPsEl) - 2) = dR(1, 1:numCPsEl);
                Rmtx(2, 3*(1:numCPsEl) - 1) = dR(1, 1:numCPsEl);
                Rmtx(3, 3*(1:numCPsEl)) = dR(1, 1:numCPsEl);

                JmtxT = dR(2:3, :)*transpose(controlPoints(1:2, :));
                dRdX = JmtxT\dR(2:3, :);
                Bmtx = zeros(5, numDOFsEl);
                Bmtx(1, 3*(1:numCPsEl) - 2) = dRdX(1, 1:numCPsEl);
                Bmtx(1, 3*(1:numCPsEl)) = dR(1, 1:numCPsEl);
                Bmtx(2, 3*(1:numCPsEl)- 2) = dRdX(2, 1:numCPsEl);
                Bmtx(2, 3*(1:numCPsEl) - 1) = -dR(1, 1:numCPsEl);
                Bmtx(3, 3*(1:numCPsEl)) = dRdX(1, 1:numCPsEl);
                Bmtx(4, 3*(1:numCPsEl) - 1) = -dRdX(2, 1:numCPsEl);
                Bmtx(5, 3*(1:numCPsEl) - 1) = -dRdX(1, 1:numCPsEl);
                Bmtx(5, 3*(1:numCPsEl)) = dRdX(2, 1:numCPsEl);
                stiffMtxEl = stiffMtxEl + transpose(Bmtx)*Cmtx*Bmtx*detJac_tri_vec(jj)*det(JmtxT)*GW_T_par(jj);
                loadVctEl = loadVctEl + transpose(Rmtx)*[propStr.pBar; propStr.mxBar; propStr.myBar]* ...
                    detJac_tri_vec(jj)*det(JmtxT)*GW_T_par(jj);
            end

        end
        
        function updateLabelDropDown(app)
            patches = app.num_patch; % Number of patches

            % Create a cell array to store numbers from 1 to numElements
            items = cell(1, patches+1); % +1 for the "all patches" item
            for i = 1:patches
                items{i} = num2str(i); % Convert number to string
            end

            % Add "all patches" as the last item
            items{end} = 'all patches';

            % Update the dropdown items
            app.PatchDropDown.Items = items;
        end

        
        function [K_patch,F_patch]= stiff_mtx_patch(app,patch,index)

            propStr.t=app.ThicknessmEditField.Value;
            propStr.alpha=app.ShearFactoralphaEditField.Value;
            propStr.mxBar = 0;
            propStr.myBar = 0;
            propStr.E = app.textbfYoungsModuluskNm2EditField.Value;
            propStr.nu = app.PoissonsRatioEditField_2.Value;
            propStr.G = propStr.E/2/(1 + propStr.nu);
            propStr.D = propStr.E*propStr.t^3/12/(1 - propStr.nu^2);
            propStr.pBar = -(app.textbfSurfaceLoadkNm2EditField.Value)*e3*(propStr.t)^3;
            app.propStructure=propStr;
            propGeomHRef.polOrd=[patch.p;patch.q];
            propGeomHRef.knotVct={patch.Xi,patch.Eta};
            propGeomHRef.weights=patch.weights;
            propGeomHRef.controlPoints=patch.CP;
            numBFsRef=[ numel(propGeomHRef.knotVct{1})-propGeomHRef.polOrd(1)-1;
                numel(propGeomHRef.knotVct{2})-propGeomHRef.polOrd(2)-1];
            numBFsRef=double(numBFsRef);
            numBFsRefTotal = prod(numBFsRef, "all");
            numCPsEl = prod(propGeomHRef.polOrd + 1);
            numDOFsEl = 3*numCPsEl;
            numDOFs = patch.noDOFs;
            K_patch = zeros(numDOFs);
            F_patch = zeros(numDOFs, 1);
            active_DOF=[];
            if app.dict_list{index}.isTrim==1
                xi_vecTrim=app.dict_list{index}.TrimPolygon(:,2);
                eta_vecTrim=app.dict_list{index}.TrimPolygon(:,1);
                trim_poly= polyshape(xi_vecTrim,eta_vecTrim);
            else
                trim_poly=[];
            end
            inactive_elements=0;
            for jj = propGeomHRef.polOrd(2, 1)+1:numel(propGeomHRef.knotVct{2})-propGeomHRef.polOrd(2, 1)-1
                for ii = propGeomHRef.polOrd(1, 1)+1:numel(propGeomHRef.knotVct{1})-propGeomHRef.polOrd(1, 1)-1
                    if diff(propGeomHRef.knotVct{1}(ii:ii + 1)) && diff(propGeomHRef.knotVct{2}(jj:jj + 1)) % Put this for trim plate as well
                        el = [propGeomHRef.knotVct{1}(ii) propGeomHRef.knotVct{1}(ii + 1) ...
                            propGeomHRef.knotVct{2}(jj) propGeomHRef.knotVct{2}(jj + 1)];
                        eft = zeros(1, numDOFsEl);
                        count = 1;
                        for ll = jj-propGeomHRef.polOrd(2, 1):jj
                            for kk = ii-propGeomHRef.polOrd(1, 1):ii
                                eft(count) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 2;
                                eft(count + 1) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 1;
                                eft(count + 2) = 3*((ll - 1)*numBFsRef(1, 1) + kk);
                                count = count + 3;
                            end
                        end
                        x_rect = [el(1); el(1); el(2); el(2); el(1)];
                        y_rect = [el(3); el(4); el(4); el(3); el(3)];
                        el_rect = polyshape(x_rect,y_rect);
                        if app.dict_list{index}.isTrim==1
                            intersectionPoly = intersect(el_rect, trim_poly);
                            if ~isempty(intersectionPoly.Vertices)  % If element surface and trimmed polygon surface have some overlap

                                rectVertices=[x_rect,y_rect];
                                [xIntersect, yIntersect] = polyxpoly(x_rect,y_rect, xi_vecTrim,eta_vecTrim);
                                if ~isempty(xIntersect)

                                    active_DOF=[active_DOF eft];
                                    rectOutsideVertices = rectVertices(~inpolygon(rectVertices(:, 1), rectVertices(:, 2), xi_vecTrim, eta_vecTrim), :);
                                    new_meshVert= [rectOutsideVertices;[xIntersect yIntersect]];
                                    triangulation = delaunay(new_meshVert(:,1), new_meshVert(:,2));
                                    numGPs = 4;
                                    [xiGP, GWxi] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs);
                                    [etaGP, GWeta] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs);
                                    GP_T_par=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),2);% 16 for each triangle
                                    GW_T_par=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),1);
                                    det_Jac_vec=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),1);
                                    counter=1;

                                    for tr= 1:size(triangulation,1)
                                        verTriangle=[new_meshVert(triangulation(tr,:),1) new_meshVert(triangulation(tr,:),2)];
                                        s1=verTriangle(1,1); s2=verTriangle(2,1);s3=verTriangle(3,1);
                                        t1=verTriangle(1,2); t2=verTriangle(2,2);t3=verTriangle(3,2);
                                        detJac_Triangle= det([-s1+s2 -t1+t2; -s1+s3 -t1+t3]);
                                        for gpa=1:numel(etaGP)
                                            for gpb=1:numel(xiGP)
                                                xiGTri= (1+xiGP(gpb))/2;
                                                etaGTri = (1-xiGP(gpb))*(1+etaGP(gpa))/4;
                                                gw_tr= (1-xiGP(gpb))*GWxi(gpb)*GWeta(gpa)/8;

                                                GP_T_par(counter,:)=GP_T_par(counter,:)+...
                                                    [(1-xiGTri-etaGTri)*s1+xiGTri*s2+etaGTri*s3 (1-xiGTri-etaGTri)*t1+xiGTri*t2+etaGTri*t3];
                                                GW_T_par(counter)=GW_T_par(counter)+gw_tr;
                                                det_Jac_vec(counter)=det_Jac_vec(counter)+detJac_Triangle;
                                                counter=counter+1;
                                            end
                                        end
                                    end
                                    [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlateTrim ...
                                        (propGeomHRef, det_Jac_vec, propStr,GP_T_par,GW_T_par);

                                else
                                    stiffMtxEl = zeros(numDOFsEl);
                                    loadVctEl = zeros(numDOFsEl, 1);
                                    inactive_elements=inactive_elements+1;
                                end
                            else
                                active_DOF=[active_DOF eft];

                                detJxiTildexi = (el(2) - el(1))*(el(4) - el(3))/4;

                                [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlate ...
                                    (propGeomHRef, el, detJxiTildexi, propStr);
                            end


                        else
                            active_DOF=[active_DOF eft];

                            detJxiTildexi = (el(2) - el(1))*(el(4) - el(3))/4;

                            [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlate ...
                                (propGeomHRef, el, detJxiTildexi, propStr);
                        end

                        K_patch(eft, eft) = K_patch(eft, eft) + stiffMtxEl;
                        F_patch(eft, 1) = F_patch(eft, 1) + loadVctEl;
                    end
                end
            end

            return;

        end


        function [KpDispI,CpDispI, KpDispJ] = computePenaltyMtcesPlatesCoupling(app,mtx_ind,patch_I, patch_J, alphaD, alphaR)

            propGeomHRef_I.polOrd=[patch_I.p;patch_I.q];
            propGeomHRef_I.knotVct={patch_I.Xi,patch_I.Eta};
            propGeomHRef_I.weights=patch_I.weights;
            propGeomHRef_I.controlPoints=patch_I.CP;
            propGeomHRef_J.polOrd=[patch_J.p;patch_J.q];
            propGeomHRef_J.knotVct={patch_J.Xi,patch_J.Eta};
            propGeomHRef_J.weights=patch_J.weights;
            propGeomHRef_J.controlPoints=patch_J.CP;
            numBFsRef_I = cellfun(@numel, propGeomHRef_I.knotVct) - propGeomHRef_I.polOrd - 1;
            numBFsRef_J = cellfun(@numel, propGeomHRef_J.knotVct) - propGeomHRef_J.polOrd - 1;
            numCPsElI = (patch_I.p + 1)*(patch_I.q + 1);
            numDOFsElI = 3*numCPsElI;
            numCPsElJ = (patch_J.p + 1)*(patch_J.q + 1);
            numDOFsElJ = 3*numCPsElJ;
            EFTI = zeros(1, numDOFsElI);
            EFTJ = zeros(1, numDOFsElJ);
            DOFNumberingI=patch_I.DOFNumbering;
            DOFNumberingJ=patch_J.DOFNumbering;
            BDisplacementsGCI = zeros(3, numDOFsElI);
            BDisplacementsGCJ = zeros(3, numDOFsElJ);
            numDOFsI = patch_I.noDOFs;
            numDOFsJ = patch_J.noDOFs;
            KpDispI = zeros(numDOFsI, numDOFsI);
            KpDispJ = zeros(numDOFsJ, numDOFsJ);
            CpDispI = zeros(numDOFsI, numDOFsJ);
            for num_gp=1:length(app.coupling_mtx{mtx_ind}(:,1))
                xi_crv=app.coupling_mtx{mtx_ind}(num_gp,1);
                gs_crv_wt= app.coupling_mtx{mtx_ind}(num_gp,2);
                gs_crv_jac=app.coupling_mtx{mtx_ind}(num_gp,3);
                xi_gp_I= app.coupling_mtx{mtx_ind}(num_gp,4);
                eta_gp_I= app.coupling_mtx{mtx_ind}(num_gp,5);
                xi_gp_J= app.coupling_mtx{mtx_ind}(num_gp,6);
                eta_gp_J= app.coupling_mtx{mtx_ind}(num_gp,7);
                %% Getting Basis Functions and their derivaties
                numDervs = 1;
                dR_I = app.computeNURBSBasisFunctions2d(propGeomHRef_I.knotVct, propGeomHRef_I.polOrd, [xi_gp_I; eta_gp_I], propGeomHRef_I.weights, numDervs);
                dR_J = app.computeNURBSBasisFunctions2d(propGeomHRef_J.knotVct, propGeomHRef_J.polOrd, [xi_gp_J; eta_gp_J], propGeomHRef_J.weights, numDervs);
                %% Getting knot spans of each gauss point in both patches
                knotSpanXi_I = app.findKnotSpan(xi_gp_I, propGeomHRef_I.knotVct{1}, numBFsRef_I(1, 1));
                knotSpanEta_I = app.findKnotSpan(eta_gp_I, propGeomHRef_I.knotVct{2}, numBFsRef_I(2, 1));

                knotSpanXi_J = app.findKnotSpan(xi_gp_J, propGeomHRef_J.knotVct{1}, numBFsRef_J(1, 1));
                knotSpanEta_J = app.findKnotSpan(eta_gp_J, propGeomHRef_J.knotVct{2}, numBFsRef_J(2, 1));
                %% Finding base vectors and non-zero basis functions of patch_I
                
                rangeXi = knotSpanXi_I-propGeomHRef_I.polOrd(1, 1):knotSpanXi_I;
                rangeEta = knotSpanEta_I-propGeomHRef_I.polOrd(2, 1):knotSpanEta_I;
                [mm, nn] = ndgrid(rangeXi, rangeEta);
                dR_I = dR_I(:, (nn(:)-1)*numBFsRef_I(1, 1) + mm(:));
                controlPoints = propGeomHRef_I.controlPoints(:, (nn(:)-1)*numBFsRef_I(1, 1) + mm(:));
                A_1= dR_I(2,:)*transpose(controlPoints);
                A_2= dR_I(3,:)*transpose(controlPoints);
                %% Finding non-zero basis functions of patch_J
                
                rangeXi = knotSpanXi_J-propGeomHRef_J.polOrd(1, 1):knotSpanXi_J;
                rangeEta = knotSpanEta_J-propGeomHRef_J.polOrd(2, 1):knotSpanEta_J;
                [mm, nn] = ndgrid(rangeXi, rangeEta);
                dR_J = dR_J(:, (nn(:)-1)*numBFsRef_I(1, 1) + mm(:));

                %% Computing Element Freedom Tables
                % Initialize of the counter
                rI = 1;
                % Relation global-local DoFs
                for cpj = knotSpanEta_I - patch_I.q:knotSpanEta_I
                    for cpi = knotSpanXi_I - patch_I.p:knotSpanXi_I
                        EFTI(rI) = DOFNumberingI(cpi, cpj, 1);
                        EFTI(rI + 1) = DOFNumberingI(cpi, cpj, 2);
                        EFTI(rI + 2) = DOFNumberingI(cpi, cpj, 3);

                        % update counter
                        rI = rI + 3;
                    end
                end

                % For patch J :
                % _____________

                % Initialize of the counter
                rJ = 1;

                % Relation global-local DoFs
                for cpj = knotSpanEta_J - patch_J.q:knotSpanEta_J
                    for cpi = knotSpanXi_J - patch_J.p:knotSpanXi_J
                        EFTJ(rJ) = DOFNumberingJ(cpi, cpj, 1);
                        EFTJ(rJ + 1) = DOFNumberingJ(cpi, cpj, 2);
                        EFTJ(rJ + 2) = DOFNumberingJ(cpi, cpj, 3);

                        % update counter
                        rJ = rJ + 3;
                    end
                end

                %% Computing basis functions and adding to global stiffness mtx

                % For patch J :
                % _____________

                % initialize counter
                kI = 0;

                % Loop over all the non-zero contributions at the span under study
                for c = 0:patch_I.q
                    for b = 0:patch_I.p
                        % Update counter
                        kI = kI + 1;
                        % Matrix containing the basis functions
                        BDisplacementsGCI(1,3*kI - 2) = dR_I(1,kI);    % displacement z dir
                        BDisplacementsGCI(2,3*kI - 1) = dR_I(1,kI);    % Rotation 1
                        BDisplacementsGCI(3,3*kI) = dR_I(1,kI);        % Rotation 2

                    end
                end

                % For patch J :
                % _____________

                % initialize counter
                kJ = 0;
                % Loop over all the non-zero contributions at the span under study
                for c = 0:patch_J.q
                    for b = 0:patch_J.p
                        % Update counter
                        kJ = kJ + 1;
                        % Matrix containing the basis functions
                        BDisplacementsGCJ(1, 3*kJ - 2) = dR_J(1,kJ);       % displacement z dir
                        BDisplacementsGCJ(2, 3*kJ - 1) = dR_J(1,kJ);       % Rotation 1
                        BDisplacementsGCJ(3, 3*kJ) = dR_J(1,kJ);           % Rotation 2


                    end
                end
                
                % app.coupling_curve.deg, app.coupling_curve.knt, app.coupling_curve.CP=data.crv_CPS;
                dR_curve = app.computeNURBSBasisFunctions1d(app.coupling_curve{mtx_ind}.knt, app.coupling_curve{mtx_ind}.deg, xi_crv, transpose(app.coupling_curve{mtx_ind}.CP(:,3)), 1);
                a_1_hat= dR_curve(2,:)*app.coupling_curve{mtx_ind}.CP(:,1);
                a_2_hat= dR_curve(2,:)*app.coupling_curve{mtx_ind}.CP(:,2);
                % base_vector of curve in par space of surf
                A_hat=[a_1_hat; a_2_hat];
                not_req_det=norm(A_1*a_1_hat + A_2*a_2_hat);
                J_1 = det(dR_I(2:3, :)*transpose(controlPoints(1:2, :)));
                elementLengthOnGP= norm(A_hat)*J_1*gs_crv_jac*gs_crv_wt;   % Three jacobians and gauss weight
                % For patch I :
                % _____________
                KpDispI(EFTI, EFTI) = KpDispI(EFTI, EFTI) + ...
                    (alphaD*(BDisplacementsGCI(1,:)'*BDisplacementsGCI(1,:))+ alphaR*(BDisplacementsGCI(2,:)'*BDisplacementsGCI(2,:))+...
                     alphaR*(BDisplacementsGCI(3,:)'*BDisplacementsGCI(3,:))*elementLengthOnGP);
                % For patch J :
                % _____________
                KpDispJ(EFTJ, EFTJ) = KpDispJ(EFTJ, EFTJ) + ...
                    ( alphaD*(BDisplacementsGCJ(1,:)'*BDisplacementsGCJ(1,:))+ alphaR*(BDisplacementsGCJ(2,:)'*BDisplacementsGCJ(2,:))+...
                     alphaR*(BDisplacementsGCJ(3,:)'*BDisplacementsGCJ(3,:)) *elementLengthOnGP);
                %  Compute the element coupling matrices at the GP
                CpDispI(EFTI, EFTJ) = CpDispI(EFTI, EFTJ) - ...
                     (alphaD*(BDisplacementsGCI(1,:)'*BDisplacementsGCJ(1,:))+ alphaR*(BDisplacementsGCI(2,:)'*BDisplacementsGCJ(2,:))+...
                     alphaR*(BDisplacementsGCI(3,:)'*BDisplacementsGCJ(3,:))*elementLengthOnGP);

            end

        end

        
        function [BSplinePatches,noDOFsPatches] = fill_up_patches(app)
            noDOFsPatches = 0;
            for i=1:app.num_patch
                BSplinePatches{i}.p= double(app.change_dict_list{i}.vDeg);
                BSplinePatches{i}.q= double(app.change_dict_list{i}.uDeg);
                BSplinePatches{i}.Xi= app.change_dict_list{i}.vknotVector;
                BSplinePatches{i}.Eta=app.change_dict_list{i}.uknotVector;
                BSplinePatches{i}.weights=app.change_dict_list{i}.weights;
                BSplinePatches{i}.CP=app.change_dict_list{i}.controlPoints;
                BSplinePatches{i}.CPd = BSplinePatches{i}.CP;
                BSplinePatches{i}.faceID=i; % Creating an ID for each face
                numBFs=[ numel(BSplinePatches{i}.Xi)-BSplinePatches{i}.p-1;
                    numel(BSplinePatches{i}.Eta)-BSplinePatches{i}.q-1];
                numBFs=double(numBFs);
                num_cps_i= prod(numBFs);
                BSplinePatches{i}.noDOFs = 3*num_cps_i;
                %% Compute the total number of DOFs for the multipatch structure
                noDOFsPatches = noDOFsPatches + BSplinePatches{i}.noDOFs;
                noDOFsPatch = BSplinePatches{i}.noDOFs;
                %% Create a freedom table for each patch in the MULTIPATCH GEOMETRY
                if i == 1
                    BSplinePatches{i}.EFTPatches = 1:noDOFsPatch;
                else
                    BSplinePatches{i}.EFTPatches = ...
                        BSplinePatches{i-1}.EFTPatches(length(BSplinePatches{i-1}.EFTPatches)) + ...
                        1:BSplinePatches{i-1}.EFTPatches(length(BSplinePatches{i-1}.EFTPatches)) + ...
                        noDOFsPatch;
                end
                %% Create a DOF numbering for each local patch
                nxi = numBFs(1,1);
                % Get the number of Control Points in eta-direction
                neta = numBFs(2,1);
                % Initialize the DOF numbering array
                BSplinePatches{i}.DOFNumbering = zeros(nxi,neta,3);
                % Compute the entries of the DOF numbering array
                k = 1;
                for cpj = 1:neta
                    for cpi = 1:nxi
                        BSplinePatches{i}.DOFNumbering(cpi,cpj,1) = k;
                        BSplinePatches{i}.DOFNumbering(cpi,cpj,2) = k + 1;
                        BSplinePatches{i}.DOFNumbering(cpi,cpj,3) = k + 2;
                        % Update counter
                        k = k + 3;
                    end
                end
                %% Getting patch stiffness matrix and load vector
                [BSplinePatches{i}.stiffMtx, BSplinePatches{i}.loadVct]=app.stiff_mtx_patch(BSplinePatches{i},i);
            end

        end
     

        function KpDispI =computePenaltyMtcesPlatesDirichlet(app,index,patch_I,alphaD, alphaR)
            
            propGeomHRef_I.polOrd=[patch_I.p;patch_I.q];
            propGeomHRef_I.knotVct={patch_I.Xi,patch_I.Eta};
            propGeomHRef_I.weights=patch_I.weights;
            propGeomHRef_I.controlPoints=patch_I.CP;
            numBFsRef = cellfun(@numel, propGeomHRef_I.knotVct) - propGeomHRef_I.polOrd - 1;
            numCPsElI = (patch_I.p + 1)*(patch_I.q + 1);
            numDOFsElI = 3*numCPsElI;
            EFTI = zeros(1, numDOFsElI);
            DOFNumberingI=patch_I.DOFNumbering;
            BDisplacementsGCI = zeros(3, numDOFsElI);
            numDOFsI = patch_I.noDOFs;
            KpDispI = zeros(numDOFsI, numDOFsI);
            for num_gp=1:length(app.dir_mtx{index}(:,1))
                xi_crv=app.dir_mtx{index}(num_gp,1);
                gs_crv_wt= app.dir_mtx{index}(num_gp,2);
                gs_crv_jac=app.dir_mtx{index}(num_gp,3);
                xi_gp_I= app.dir_mtx{index}(num_gp,4);
                eta_gp_I= app.dir_mtx{index}(num_gp,5);
               
                %% Getting Basis Functions and their derivaties
                numDervs = 1;
                dR_I = app.computeNURBSBasisFunctions2d(propGeomHRef_I.knotVct, propGeomHRef_I.polOrd, [xi_gp_I; eta_gp_I], propGeomHRef_I.weights, numDervs);
                %% Finding base vectors of patch_I
                knotSpanXi = app.findKnotSpan(xi_gp_I, propGeomHRef_I.knotVct{1}, numBFsRef(1, 1));
                knotSpanEta = app.findKnotSpan(eta_gp_I, propGeomHRef_I.knotVct{2}, numBFsRef(2, 1));
                rangeXi = knotSpanXi-propGeomHRef_I.polOrd(1, 1):knotSpanXi;
                rangeEta = knotSpanEta-propGeomHRef_I.polOrd(2, 1):knotSpanEta;
                [mm, nn] = ndgrid(rangeXi, rangeEta);
                dR_I = dR_I(:, (nn(:)-1)*numBFsRef(1, 1) + mm(:));
                controlPoints = propGeomHRef_I.controlPoints(:, (nn(:)-1)*numBFsRef(1, 1) + mm(:));
                A_1= dR_I(2,:)*transpose(controlPoints);
                A_2= dR_I(3,:)*transpose(controlPoints);

                %% Computing Element Freedom Tables
                % Initialize of the counter
                rI = 1;

                % Relation global-local DoFs
                for cpj = knotSpanEta - patch_I.q:knotSpanEta
                    for cpi = knotSpanXi - patch_I.p:knotSpanXi
                        EFTI(rI) = DOFNumberingI(cpi, cpj, 1);
                        EFTI(rI + 1) = DOFNumberingI(cpi, cpj, 2);
                        EFTI(rI + 2) = DOFNumberingI(cpi, cpj, 3);

                        % update counter
                        rI = rI + 3;
                    end
                end

                %% Computing basis functions and adding to global stiffness mtx

                % For patch I:
                % _____________

                % initialize counter
                kI = 0;
                % Loop over all the non-zero contributions at the span under study
                for c = 0:patch_I.q
                    for b = 0:patch_I.p
                        % Update counter
                        kI = kI + 1;
                        % Matrix containing the basis functions
                        BDisplacementsGCI(1,3*kI - 2) = dR_I(1,kI);    % displacement z dir
                        BDisplacementsGCI(2,3*kI - 1) = dR_I(1,kI);    % Rotation 1
                        BDisplacementsGCI(3,3*kI) = dR_I(1,kI);        % Rotation 2
                    end
                end
                
                % app.coupling_curve.deg, app.coupling_curve.knt, app.coupling_curve.CP=data.crv_CPS;
                dR_curve = app.computeNURBSBasisFunctions1d(app.dir_curve{index}.knt, app.dir_curve{index}.deg, xi_crv, transpose(app.dir_curve{index}.CP(:,3)), 1);
                a_1_hat= dR_curve(2,:)*app.dir_curve{index}.CP(:,1);
                a_2_hat= dR_curve(2,:)*app.dir_curve{index}.CP(:,2);
                % base_vector of curve in par space of surf
                A_hat=[a_1_hat; a_2_hat];
                not_req_det=norm(A_1*a_1_hat + A_2*a_2_hat);
                J_1 = det(dR_I(2:3, :)*transpose(controlPoints(1:2, :)));
                elementLengthOnGP= norm(A_hat)*J_1*gs_crv_jac*gs_crv_wt;   % Three jacobians and gauss weight
                % For patch I :
                % _____________
                KpDispI(EFTI, EFTI) = KpDispI(EFTI, EFTI) + ...
                    (alphaD*(BDisplacementsGCI(1,:)'*BDisplacementsGCI(1,:))+ alphaR*(BDisplacementsGCI(2,:)'*BDisplacementsGCI(2,:))+...
                     alphaR*(BDisplacementsGCI(3,:)'*BDisplacementsGCI(3,:))*elementLengthOnGP);

            end
        end
    
    
        function updateCommandHistory(app, newText)
            % Increment command counter
            app.commandCounter = app.commandCounter + 1;
            % Convert newText from cell array to character array if necessary
            if iscell(newText)
                newText = cell2mat(newText);
            end
            % Convert newText from string to character array if necessary
            if isstring(newText)
                newText = char(newText);
            end
            % Prepend serial number to newText
            newText = sprintf('%d: %s', app.commandCounter, newText);
            % Append new text to the list of outputs
            app.outputHistory.Value{end+1} = [newText,newline];
            % Concatenate all the output texts
            concatenatedText = strjoin(app.outputHistory.Value, [newline,newline]);
            % Update the output history
            app.outputHistory.Value = concatenatedText;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %NEED TO MODIFY THIS PART
            % Check if Python executable is present
            % [status, result] = system('python --version');
            % 
            % % Display the result
            % if status == 0
            %     disp('Python is installed.');
            %     disp(result);
            % else
            %     disp('Python is not installed or not in the system path.');
            % end
            app.commandCounter = 0;
            app.outputHistory.Value = "Just Started the App";
            % Override the fprintf function to redirect output to TextArea
            orig_fprintf = @fprintf;
            fprintf = @(varargin) app.updateCommandHistory(sprintf(varargin{:}));
            assignin('base', 'fprintf', orig_fprintf); % Save original fprintf to base workspace

            % Example: capture output and append to TextArea
            output1 = evalc('disp(''Hello, world!'')');
            output2 = evalc('disp([1, 2, 3; 4, 5, 6])');

            % Append captured output to TextArea
            app.updateCommandHistory(output1);
            app.updateCommandHistory(output2);


            % Example: print some output
            if app.begin==1
                app.num_patch=1;
                app.propGeomCircle.dim=1;
                app.propGeomCircle.polOrd = app.polyOrder;
                app.propGeomCircle.knotVct = app.knotVector;
                w = app.weight;
                app.propGeomCircle.weights = [1 w 1 w 1 w 1 w 1];
                CP1 = [0,1, 0];
                CP2 = [1,1, 0];
                CP3 = [1,0, 0];
                CP4 = [1,-1, 0];
                CP5 = [0,-1, 0];
                CP6 = [-1,-1, 0];
                CP7 = [-1,0, 0];
                CP8 = [-1,1, 0];
                CP9 = [0,1, 0];
                app.propGeomCircle.controlPoints = [CP1(1) CP2(1) CP3(1) CP4(1) CP5(1) CP6(1) CP7(1) CP8(1) CP9(1)
                    CP1(2) CP2(2) CP3(2) CP4(2) CP5(2) CP6(2) CP7(2) CP8(2) CP9(2)
                    0      0      0      0      0      0      0      0      0];
                app.controlPoints=app.propGeomCircle.controlPoints;
                app.weigtArray=[1 w 1 w 1 w 1 w 1];
            else
                app.propGeomCircle=app.propGeomCircle;
            end
            app.Label.Text= sprintf('Polynomial Degree: %d', app.polyOrder);
            app.changeProp= app.propGeomCircle;
            app.updatePlot;

        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            app.weight = app.WeightEditField.Value;
            app.startupFcn;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            app.updatePlot;
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: ControlPointsSwitch
        function ControlPointsSwitchValueChanged(app, event)
            % make it such that if there is no plot, it should not respond
            if app.ControlPointsSwitch.Value == 'Show'
                % Show the plots
                app.plot1.Visible = 'on';
                app.plot2.Visible = 'on';
            else
                % Hide the plots
                app.plot1.Visible = 'on';
                app.plot2.Visible = 'off';
            end

            switchValue = app.ControlPointsSwitch.Value;
            for i = 1:numel(app.UIAxes.Children)
                if isa(app.UIAxes.Children(i), 'matlab.graphics.primitive.Text')
                    % Adjust the comparison based on the switch options
                    if strcmp(switchValue, 'Hide')
                        app.UIAxes.Children(i).Visible = 'off';
                    else
                        app.UIAxes.Children(i).Visible = 'on';
                    end
                end
            end

        end

        % Value changed function: DegreeChangeSpinner
        function DegreeChangeSpinnerValueChanged(app, event)

            newValue = app.DegreeChangeSpinner.Value;
            app.performOrderElevationForNURBSCurve(app.propGeomCircle,newValue);
            app.updatePlot;

        end

        % Value changed function: NumberofKnotsSpinner
        function NumberofKnotsSpinnerValueChanged(app, event)

            newProp.controlPoints=app.controlPoints;
            newProp.weights=app.weigtArray;
            newProp.knotVct=app.knotVector;
            newProp.dim=app.dim;
            newProp.polOrd=app.polyOrder;
            newValue=app.NumberofKnotsSpinner.Value;
            Prop=app.performUniformKnotRefinementNURBSCurve(newProp,newValue);
            app.changeProp=Prop;
            app.updatePlot;

        end

        % Button pushed function: ChooseYourCADFileButton
        function ChooseYourCADFileButtonPushed(app, event)

            % Display file selection dialog
            [filename, pathname] = uigetfile('*.igs', 'Select a File');
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the file selection.');
                return;
            end
            if ~isequal(filename, 0) && ~isequal(pathname, 0)
                % Call Python script with the selected file
                app.CallPythonScript(fullfile(pathname, filename));
            end
            app.file_path=fullfile(pathname,filename);

            % Check the value of dim and switch tabs accordingly
            if app.dim == 1
                app.TabGroup3.SelectedTab = app.CurvesTab;
            elseif app.dim == 2
                app.TabGroup3.SelectedTab = app.PlatesTab;
            end


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            clickPoint = app.UIAxes.CurrentPoint;
            %Extract x, y, and z coordinates
            %[xCoord, yCoord] = ginput(1);
            xCoord = clickPoint(1, 1);
            yCoord = clickPoint(1, 2);
            zCoord = 0; % Assuming a 2D plot; set to approriate value if 3D
            % Display the coordinates (you can customize this part)
            disp(['Clicked at (x, y, z): (' num2str(xCoord) ', ' num2str(yCoord) ', ' num2str(zCoord) ')']);
        end

        % Button pushed function: PerformBeamIGAButton
        function PerformBeamIGAButtonPushed(app, event)

            Xi=app.changeProp.knotVct;
            p=app.changeProp.polOrd;
            CP=transpose(app.changeProp.controlPoints);
            CP(:,4)= app.changeProp.weights;

            isNURBS = false;
            for i = length(CP(:, 1))
                if CP(i, 4) ~= 1
                    isNURBS = true;
                    break;
                end
            end
            parameters.EYoung = app.YoungsModuluskNm2EditField.Value;
            parameters.Nu = app.PoissonsRatioEditField.Value;
            parameters.GShear = parameters.EYoung/(2*(1 + parameters.Nu));
            parameters.alpha = app.alphaEditField.Value;
            parameters.b = app.WidthmEditField.Value;
            parameters.h = app.DepthmEditField.Value;
            parameters.A = parameters.b*parameters.h;
            parameters.I = parameters.b*(parameters.h^3)/12;
            parameters.Aq = parameters.alpha*parameters.A;

            analysis.type= app.BeamTypeDropDown.Value;
            % Choose linear equation solver
            solve_LinearSystem = @app.solve_LinearSystemMatlabBackslashSolver;

            int.type = 'default';
            intError.type = 'user';

            % Number of Gauss points
            int.noGP = 6;
            int.noGPLoad = 6;
            intError.noGP = 12;

            % Postprocessing features
            % resultant : 'displacement', 'crossSectionalRotation', 'force', 'moment',
            % 'shearForce'
            graph.resultant = app.ResultTypeDropDown.Value;
            %graph.resultant = 'displacement';
            % Component (only for graph.resultant = 'displacement'): 'x', 'y', '2norm'
            graph.component = 'y';
            graph.postProcVisType = 'deformedAndUndeformedShape';
            graph.index = 1;
            graph.plotBasisFunctionsAndDerivs = 0;
            %% Apply boundary conditions
            % Dirichlet boundary conditions
            homDOFs = [];
            if strcmp(analysis.type, 'Bernoulli')
                % Clamp the left edge displacement of the beam
                if app.ClampLeftDisplacementCheckBox.Value==1
                    xib = [Xi(1) Xi(p + 1)];
                    dir = 1;
                    homDOFs = app.findDofsForBernoulliBeams2D ...
                        (homDOFs, xib, dir, CP);
                end
                if app.ClampLeftRotationCheckBox.Value==1
                    xib = [Xi(1) Xi(p + 1)];
                    dir = 2;
                    homDOFs = app.findDofsForBernoulliBeams2D ...
                        (homDOFs, xib, dir, CP);
                end
                if app.ClampRightDisplacementCheckBox.Value==1
                    xib = [Xi(length(Xi) - p) Xi(end)];
                    dir = 1;
                    homDOFs = app.findDofsForBernoulliBeams2D(homDOFs, xib, dir, CP);
                end
                if app.ClampRightRotationCheckBox.Value==1
                    xib = [Xi(length(Xi) - p) Xi(end)];
                    dir = 2;
                    homDOFs = app.findDofsForBernoulliBeams2D(homDOFs, xib, dir, CP);
                end
                %     xib = [Xi(length(Xi)-p-1) Xi(length(Xi))];

            elseif strcmp(analysis.type,'Timoshenko')
                % Clamp the left edge of the beam (3 DoFs two translations and 1 rotation)
                if app.ClampLeftDisplacementCheckBox.Value==1
                    xib = [Xi(1) Xi(p + 1)];
                    dir = 1;
                    homDOFs = app.findDofsForTimoshenkoBeams2D ...
                        (homDOFs, xib, dir, CP);
                end
                if app.ClampLeftRotationCheckBox.Value==1
                    xib = [Xi(1) Xi(p + 1)];
                    dir = 2;
                    homDOFs = app.findDofsForTimoshenkoBeams2D ...
                        (homDOFs, xib, dir, CP);
                    dir=3;
                    homDOFs = app.findDofsForTimoshenkoBeams2D ...
                        (homDOFs, xib, dir, CP);
                end
                if app.ClampRightDisplacementCheckBox.Value==1
                    xib = [Xi(length(Xi) - p) Xi(end)];
                    dir = 1;
                    homDOFs = app.findDofsForTimoshenkoBeams2D(homDOFs, xib, dir, CP);
                end
                if app.ClampRightRotationCheckBox.Value==1
                    xib = [Xi(length(Xi) - p) Xi(end)];
                    dir = 2;
                    homDOFs = app.findDofsForTimoshenkoBeams2D ...
                        (homDOFs, xib, dir, CP);
                    dir=3;
                    homDOFs = app.findDofsForTimoshenkoBeams2D ...
                        (homDOFs, xib, dir, CP);
                end
            end

            % On the application of a pressure load on the beam
            NBC.noCnd = 1;
            xib = [0 1]; % Distributed load
            % xib = 1; % Point load
            NBC.xiLoadExtension = {xib};
            NBC.etaLoadExtension = {'undefined'};
            pLoad = app.LoadkNmEditField.Value; % Distributed load
            % pLoad = + 1e2; % Point load
            NBC.loadAmplitude = {-pLoad};
            if strcmp(analysis.type, 'Bernoulli') % Distributed load
                NBC.computeLoadVct = ...
                    {'computeLoadVctLinePressureVectorForIGABernoulliBeam2D'};
            elseif strcmp(analysis.type, 'Timoshenko')
                NBC.computeLoadVct = {'computeLoadVctLinePressureVectorForIGATimoshenkoBeam2D'};
            end
            % if strcmp(analysis.type, 'Bernoulli') % Point load
            %     NBC.computeLoadVct = ...
            %         {'computeLoadPointVectorForIGABernoulliBeam2D'};
            % elseif strcmp(analysis.type, 'Timoshenko')
            %     NBC.computeLoadVct = {'computeLoadPointVectorForIGATimoshenkoBeam2D'};
            % end
            loadDir = 2;
            NBC.loadDirection = {loadDir};
            NBC.isFollower(1, 1) = false;
            NBC.isTimeDependent(1, 1) = false;

            % On the application of a point load on the beam
            % xib = 1;
            % dir = 2;
            % F = computeLoadPointVectorForIGATimoshenkoBeam2D ...
            %     (F, BSplinePatch, xib, etab, pLoad, dir, isFollower, int, 'outputEnabled');

            %% Fill up computational patch
            BSplinePatch.p = p;
            BSplinePatch.Xi = Xi;
            BSplinePatch.CP = CP;
            BSplinePatch.isNURBS = isNURBS;

            %% Compute the load vector only for the visualization of the reference configuration
            for iNBC = 1:NBC.noCnd
                F = [];
                funcHandle = str2func(NBC.computeLoadVct{iNBC});
                F = funcHandle ...
                    (F, BSplinePatch, NBC.xiLoadExtension{iNBC}, ...
                    NBC.etaLoadExtension{iNBC}, NBC.loadAmplitude{iNBC}, ...
                    NBC.loadDirection{iNBC}, NBC.isFollower(iNBC,1), 0, int, 'outputEnabled');
            end

            %% Plot reference configuration
            graph.index = app.plot_referenceConfigurationIGABeams ...
                (p, Xi, CP, isNURBS, homDOFs, F, analysis, graph, 'outputEnabled');

            %% Solve the problem
            [dHat, F, minElEdgeSize] = app.solve_IGABeamLinear2D ...
                (analysis, p, Xi, CP, homDOFs, NBC, parameters, isNURBS, ...
                solve_LinearSystem, int, 'outputEnabled');

            %% Postprocessing
            graph.index = app.plot_currentConfigurationIGABeamsLinear ...
                (analysis, p, Xi, CP, isNURBS, dHat, homDOFs, parameters, graph, ...
                'outputEnabled');

        end

        % Value changed function: DeltapxiSpinner
        function DeltapxiSpinnerValueChanged(app, event)
            dp = app.DeltapxiSpinner.Value;
            dq = app.DeltaqetaSpinner.Value;
            app.performOrderElevationForNURBSSurface(dp, dq);
            app.deg_dict_list=app.change_dict_list;
            app.updatePlot;

        end

        % Value changed function: DeltaqetaSpinner
        function DeltaqetaSpinnerValueChanged(app, event)
            dp = app.DeltapxiSpinner.Value;
            dq = app.DeltaqetaSpinner.Value;
            app.performOrderElevationForNURBSSurface(dp, dq);
            app.deg_dict_list=app.change_dict_list;
            app.updatePlot;

        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            cla(app.UIAxes_2);
        end

        % Button pushed function: VisualizeButton_2
        function VisualizeButton_2Pushed(app, event)
            app.updatePlot
        end

        % Value changed function: NumberofKnotsxiSpinner
        function NumberofKnotsxiSpinnerValueChanged(app, event)
            knotsRefXi = app.NumberofKnotsxiSpinner.Value;
            knotsRefEta = app.NumberofKnotsetaSpinner.Value;

            app.performKnotRefinementNURBSSurface(knotsRefXi, knotsRefEta);
            app.updatePlot;
        end

        % Value changed function: NumberofKnotsetaSpinner
        function NumberofKnotsetaSpinnerValueChanged(app, event)
            knotsRefXi = app.NumberofKnotsxiSpinner.Value;
            knotsRefEta = app.NumberofKnotsetaSpinner.Value;

            app.performKnotRefinementNURBSSurface(knotsRefXi, knotsRefEta);
            app.updatePlot;

        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            value = app.PlotTypeDropDown.Value;
            propGeomHRef=app.changeProp;
            propStr=app.propStructure;
            numBFsRef=[ numel(propGeomHRef.knotVct{1})-propGeomHRef.polOrd(1)-1;
                numel(propGeomHRef.knotVct{2})-propGeomHRef.polOrd(2)-1];
            numBFsRef=double(numBFsRef);
            numCPsEl = prod(propGeomHRef.polOrd + 1);
            numDOFsEl = 3*numCPsEl;
            syms wEx(X) betayEx(X) qxEx(X) Lsym psym Gsym Aqsym ...
                Esym Isym tsym alphaScf
            X0 = app.controlPoints(1,1);
            XLx = app.controlPoints(1,end);
            Y0 = app.controlPoints(2,1);
            YLy = app.controlPoints(2,end);
            wEx(X) = (psym*Lsym*X-psym*X^2/2)/Gsym/(Aqsym) - ...
                (-psym*X^4/24+psym*Lsym*X^3/6-psym*Lsym^2*X^2/4)/Esym/Isym;
            wExSubs(X) = subs(wEx,{Lsym, psym, Gsym, Esym, Isym, Aqsym}, ...
                {XLx, propStr.pBar, propStr.G, propStr.E, ...
                YLy*(propStr.t^3)/12, propStr.alpha*YLy*propStr.t});

            betayEx(X) = int(-1/(propStr.alpha*propStr.G*YLy*propStr.t)*...
                propStr.pBar - diff(diff(wEx, X), X));

            betayExSubs(X) = subs(betayEx, {Lsym, psym, Gsym, Esym, Isym, Aqsym}, ...
                {XLx, propStr.pBar, propStr.G, propStr.E, YLy*(propStr.t^3)/12, ...
                propStr.alpha*YLy*propStr.t});

            qxExSubs(X) = propStr.alpha*propStr.G*propStr.t*(diff(wExSubs, X) + betayExSubs(X));

            if strcmp(value,'Deformation')==1
                cla(app.UIAxes5);
                app.plotNURBSSurface(app.UIAxes5,app.deformProp, 100, 100, numBFsRef,1);
                hold (app.UIAxes5,'on')
                fsurf(app.UIAxes5,wExSubs, [X0 XLx Y0 YLy], FaceColor=[203 65 84]/255, ...
                    FaceAlpha=0.5, LineStyle="none");
                hold (app.UIAxes5,'off');
                legend( app.UIAxes5,["With hole", "Without hole"], Location="best");
                %title(app.UIAxes5,"Plate's deformation using isogeometric elements");
                title(app.UIAxes5,"Deformation of plate ");

            elseif strcmp(value,'Shear Force')==1
                cla(app.UIAxes5);
                numPts = 100;
                xiMid = linspace(propGeomHRef.knotVct{1}(1), propGeomHRef.knotVct{1}(end), numPts);
                etaMid = mean([propGeomHRef.knotVct{2}(1) propGeomHRef.knotVct{2}(end)]);
                qx = zeros(numPts, 1);
                XPt = zeros(numPts, 3);
                knotSpanXiMidSaved = nan;
                knotSpanEtaMidSaved = nan;
                for ii = 1:numPts
                    knotSpanXiMid = app.findKnotSpan(xiMid(ii), propGeomHRef.knotVct{1}, numBFsRef(1, 1));
                    knotSpanEtaMid = app.findKnotSpan(etaMid, propGeomHRef.knotVct{2}, numBFsRef(2, 1));
                    if ~isequal(knotSpanXiMid, knotSpanXiMidSaved) || ~isequal(knotSpanEtaMid, knotSpanEtaMidSaved)
                        eft = zeros(1, numDOFsEl);
                        counter = 1;
                        for ll = knotSpanEtaMid-propGeomHRef.polOrd(2, 1):knotSpanEtaMid
                            for kk = knotSpanXiMid-propGeomHRef.polOrd(1, 1):knotSpanXiMid
                                eft(counter) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 2;
                                eft(counter + 1) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 1;
                                eft(counter + 2) = 3*((ll - 1)*numBFsRef(1, 1) + kk);
                                counter = counter + 3;
                            end
                        end
                    end
                    numDervs = 1;
                    dR = app.computeNURBSBasisFunctions2d(propGeomHRef.knotVct, propGeomHRef.polOrd, [xiMid(ii); etaMid], propGeomHRef.weights, numDervs);
                    rangeXiMid = knotSpanXiMid-propGeomHRef.polOrd(1, 1):knotSpanXiMid;
                    rangeEtaMid = knotSpanEtaMid-propGeomHRef.polOrd(2, 1):knotSpanEtaMid;
                    [mm, nn] = ndgrid(rangeXiMid, rangeEtaMid);
                    dR = dR(:, (nn(:)-1)*numBFsRef(1, 1) + mm(:));
                    controlPoints = propGeomHRef.controlPoints(:, (nn(:)-1)*numBFsRef(1, 1) + mm(:));
                    XPt(ii, :) = dR(1, :)*transpose(controlPoints);
                    uEl = app.globDisp(eft, 1);
                    betaY = dR(1, :)*uEl(3:3:end);
                    JmtxT = dR(2:3, :)*transpose(controlPoints(1:2, :));
                    dwdX = JmtxT\(dR(2:3, :)*uEl(1:3:end));
                    qx(ii, 1) = propStr.alpha*propStr.G*propStr.t*(dwdX(1, 1) + betaY);
                    knotSpanXiMidSaved = knotSpanXiMid;
                    knotSpanEtaMidSaved = knotSpanEtaMid;
                end

                app.plotNURBSSurface(app.UIAxes5,app.changeProp, 100, 100, numBFsRef,1);
                hold(app.UIAxes5,'on');
                grid(app.UIAxes5,'on');
                xlim(app.UIAxes5,"auto")
                ylim(app.UIAxes5,"auto")
                zlim(app.UIAxes5,"auto")
                axis(app.UIAxes5, 'normal');
                hl1 = plot3(app.UIAxes5,XPt(:, 1), XPt(:, 2), qx, LineWidth=2, Color="red");
                %hl2 = plot3(app.UIAxes5,XPt(:, 1),  XPt(:, 2), qxExSubs(XPt(:, 1)), LineWidth=2, Color="black");
                %pbaspect([1, YLy/XLx, 1])
                hold(app.UIAxes5,'off');
                %legend([hl1 hl2], ["IGA", "Analytical"])
                view(app.UIAxes5,[-9 21])
            end
        end

        % Button pushed function: BeamIGAAFEMLabButton
        function BeamIGAAFEMLabButtonPushed(app, event)


            cla(app.UIAxes2_3);
            cla(app.UIAxes3_3);
            parameters.E = app.YoungsModuluskNm2EditField.Value;
            parameters.nu = app.PoissonsRatioEditField.Value;
            parameters.G = parameters.E/(2*(1 + parameters.nu));
            parameters.alpha = app.alphaEditField.Value;
            parameters.b = app.WidthmEditField.Value;
            parameters.h = app.DepthmEditField.Value;
            parameters.A = parameters.b*parameters.h;
            parameters.I = parameters.b*(parameters.h^3)/12;
            parameters.Aq = parameters.alpha*parameters.A;
            parameters.mBar = 0;
            parameters.qBar = -3e4*(parameters.h)^3;
            analysis.type= app.BeamTypeDropDown.Value;

            X0=app.changeProp.controlPoints(1,1);
            XL=app.changeProp.controlPoints(1,end);

            numCPsEl = app.changeProp.polOrd + 1;
            numDOFsEl = 2*numCPsEl;
            numBFsRef = size(app.changeProp.controlPoints,2);
            numDOFs = 2*numBFsRef;
            stiffMtx = zeros(numDOFs);
            loadVct = zeros(numDOFs, 1);
            for ii = app.changeProp.polOrd + 1 : numel(app.changeProp.knotVct) - app.changeProp.polOrd-1
                if diff(app.changeProp.knotVct(ii:ii + 1)) ~= 0
                    el = [app.changeProp.knotVct(ii) app.changeProp.knotVct(ii + 1)];
                    eft = zeros(1, numDOFsEl);
                    counter = 1;
                    for jj = ii-app.changeProp.polOrd:ii
                        eft(counter) = 2*jj - 1;
                        eft(counter + 1) = 2*jj;
                        counter = counter + 2;
                    end
                    [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctTimoshenkoBeam(app.changeProp, el,parameters);
                    stiffMtx(eft, eft) = stiffMtx(eft, eft) + stiffMtxEl;
                    loadVct(eft, 1) = loadVct(eft, 1) + loadVctEl;
                end
            end

            freeDOFs = 1:numDOFs;
            homDOFs = 1:2;
            [~, idx] = ismember(homDOFs, freeDOFs);
            freeDOFs(idx) = [];
            u = zeros(numDOFs, 1);
            u(freeDOFs) = stiffMtx(freeDOFs, freeDOFs)\loadVct(freeDOFs);
            propGeomBeamHRefDef = app.changeProp;
            scalFct = 1;
            propGeomBeamHRefDef.controlPoints(2, :) = scalFct*u(1:2:end);
            isPlotNumsAndIds = false;
            numPtsBeamRef = 100;
            cla(app.UIAxes2_3);
            cla(app.UIAxes3_3);
            app.plotNURBSCurve(app.UIAxes2_3,propGeomBeamHRefDef, numPtsBeamRef, isPlotNumsAndIds);
            hold(app.UIAxes2_3,'on');
            % ANALYTICAL SOLUTION
            propStr=parameters;
            syms wEx(X) betaEx(X) Lsym qsym Gsym Aqsym Esym Isym qxExSubs
            wEx(X) = (qsym*Lsym*X-qsym*X^2/2)/Gsym/(Aqsym) - ...
                (-qsym*X^4/24+qsym*Lsym*X^3/6-qsym*Lsym^2*X^2/4)/Esym/Isym;

            betaEx(X) = int(-1/(propStr.alpha*propStr.G*propStr.b*propStr.h)*propStr.qBar ...
                - diff(diff(wEx, X), X));

            wEx(X) = subs(wEx,{Lsym, qsym, Gsym, Esym, Isym, Aqsym}, ...
                {XL, propStr.qBar, propStr.G, propStr.E, propStr.b*(propStr.h^3)/12, ...
                propStr.alpha*propStr.b*propStr.h});

            betaEx(X) = subs(betaEx,{Lsym, qsym, Gsym, Esym, Isym, Aqsym}, ...
                {XL, propStr.qBar, propStr.G, propStr.E, propStr.b*(propStr.h^3)/12, ...
                propStr.alpha*propStr.b*propStr.h});

            qxExSubs(X) = propStr.alpha*propStr.G*propStr.h*(diff(wEx, X) + betaEx(X));

            x_axis=linspace(X0,XL,100);
            disp=[];
            sh_for=[];
            for num=1:100
                disp=[disp wEx(x_axis(num))];
                sh_for= [sh_for qxExSubs(x_axis(num))];
            end
            hf2=plot(app.UIAxes2_3,x_axis,disp,'LineStyle','--',LineWidth=1);
            legend(app.UIAxes2_3, ["IGA", "Analytical"], Location="best");
            title(app.UIAxes2_3,"Beam deformation ");

            hf3= plot(app.UIAxes3_3,x_axis,sh_for);
            hold(app.UIAxes3_3,'on');
            numPts = 100;
            knotSpanXiMidSaved = nan;
            xiMid = linspace(app.changeProp.knotVct(1), app.changeProp.knotVct(end), numPts);
            qx = zeros(numPts, 1);
            XPt = zeros(numPts, 3);
            propGeomHRef=app.changeProp;
            for ii=1:numPts
                knotSpanXiMid = app.findKnotSpan(xiMid(ii), app.changeProp.knotVct, numBFsRef(1, 1));
                if ~isequal(knotSpanXiMid, knotSpanXiMidSaved)
                    eft = zeros(1, numDOFsEl);
                    counter = 1;
                    for kk = knotSpanXiMid-propGeomHRef.polOrd:knotSpanXiMid
                        eft(counter) = 2*kk-1;
                        eft(counter + 1) = 2*kk;
                        counter = counter + 2;
                    end
                end
                numDervs = 1;

                dR = app.computeNURBSBasisFunctions1d(propGeomHRef.knotVct, propGeomHRef.polOrd, xiMid(ii), propGeomHRef.weights, numDervs);
                dR = dR(:, knotSpanXiMid-propGeomHRef.polOrd:knotSpanXiMid);
                controlPoints = propGeomHRef.controlPoints(:, knotSpanXiMid-propGeomHRef.polOrd:knotSpanXiMid);
                XPt(ii, :) = dR(1, :)*transpose(controlPoints);
                uEl = u(eft, 1);
                betaY = dR(1, :)*uEl(2:2:end);
                Jmtx = dR(2, :)*transpose(controlPoints(1, :));
                dwdX = Jmtx\(dR(2, :)*uEl(1:2:end));
                qx(ii, 1) = propStr.alpha*propStr.G*propStr.h*(dwdX(1, 1) + betaY);

            end
            hf4= plot(app.UIAxes3_3,x_axis,qx,'LineStyle','-',LineWidth=0.8);
            legend(app.UIAxes3_3, ["Analytical", "IGA"], Location="best");
            title(app.UIAxes3_3,"Beam Shear Force");


        end

        % Value changed function: ControlPolygonCheckBox
        function ControlPolygonCheckBoxValueChanged(app, event)
            app.updatePlot;
        end

        % Value changed function: ElementsCheckBox
        function ElementsCheckBoxValueChanged(app, event)
            app.updatePlot;
        end

        % Value changed function: NumandIDsCheckBox
        function NumandIDsCheckBoxValueChanged(app, event)
            app.updatePlot;
        end

        % Callback function: not associated with a component
        function PlateAnalysisButtonPushed(app, event)
            cla(app.UIAxes5);
            propStr.t=app.ThicknessmEditField.Value;
            propStr.alpha=app.ShearFactoralphaEditField.Value;
            propStr.mxBar = 0;
            propStr.myBar = 0;
            propStr.E = app.textbfYoungsModuluskNm2EditField.Value;
            propStr.nu = app.PoissonsRatioEditField_2.Value;
            propStr.G = propStr.E/2/(1 + propStr.nu);
            propStr.D = propStr.E*propStr.t^3/12/(1 - propStr.nu^2);
            propStr.pBar = -3e4*(propStr.t)^3;
            app.propStructure=propStr;
            propGeomHRef=app.changeProp;
            numBFsRef=[ numel(propGeomHRef.knotVct{1})-propGeomHRef.polOrd(1)-1;
                numel(propGeomHRef.knotVct{2})-propGeomHRef.polOrd(2)-1];
            numBFsRef=double(numBFsRef);
            numBFsRefTotal = prod(numBFsRef, "all");
            numCPsEl = prod(propGeomHRef.polOrd + 1);
            numDOFsEl = 3*numCPsEl;
            numDOFs = 3*numBFsRefTotal;
            stiffMtx = zeros(numDOFs);
            loadVct = zeros(numDOFs, 1);
            active_DOF=[];
            if app.propGeomTrim.isTrim==1
                xi_vecTrim=app.propGeomTrim.poly(:,2);
                eta_vecTrim=app.propGeomTrim.poly(:,1);
                trim_poly= polyshape(xi_vecTrim,eta_vecTrim);
            else
                trim_poly=[];
            end
            inactive_elements=0;

            for jj = propGeomHRef.polOrd(2, 1)+1:numel(propGeomHRef.knotVct{2})-propGeomHRef.polOrd(2, 1)-1
                for ii = propGeomHRef.polOrd(1, 1)+1:numel(propGeomHRef.knotVct{1})-propGeomHRef.polOrd(1, 1)-1
                    if diff(propGeomHRef.knotVct{1}(ii:ii + 1)) && diff(propGeomHRef.knotVct{2}(jj:jj + 1)) % Put this for trim plate as well
                        el = [propGeomHRef.knotVct{1}(ii) propGeomHRef.knotVct{1}(ii + 1) ...
                            propGeomHRef.knotVct{2}(jj) propGeomHRef.knotVct{2}(jj + 1)];
                        eft = zeros(1, numDOFsEl);
                        count = 1;
                        for ll = jj-propGeomHRef.polOrd(2, 1):jj
                            for kk = ii-propGeomHRef.polOrd(1, 1):ii
                                eft(count) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 2;
                                eft(count + 1) = 3*((ll - 1)*numBFsRef(1, 1) + kk) - 1;
                                eft(count + 2) = 3*((ll - 1)*numBFsRef(1, 1) + kk);
                                count = count + 3;
                            end
                        end
                        x_rect = [el(1); el(1); el(2); el(2); el(1)];
                        y_rect = [el(3); el(4); el(4); el(3); el(3)];
                        el_rect = polyshape(x_rect,y_rect);
                        if app.propGeomTrim.isTrim==1
                            intersectionPoly = intersect(el_rect, trim_poly);
                            TF = overlaps(el_rect,trim_poly);
                            if ~isempty(intersectionPoly.Vertices)

                                rectVertices=[x_rect,y_rect];
                                [xIntersect, yIntersect] = polyxpoly(x_rect,y_rect, xi_vecTrim,eta_vecTrim);
                                if ~isempty(xIntersect)

                                    active_DOF=[active_DOF eft];
                                    rectOutsideVertices = rectVertices(~inpolygon(rectVertices(:, 1), rectVertices(:, 2), xi_vecTrim, eta_vecTrim), :);
                                    new_meshVert= [rectOutsideVertices;[xIntersect yIntersect]];
                                    triangulation = delaunay(new_meshVert(:,1), new_meshVert(:,2));
                                    numGPs = 4;
                                    [xiGP, GWxi] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs);
                                    [etaGP, GWeta] = app.getGaussPointsAndWeightsOverUnitDomain(numGPs);
                                    GP_T_par=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),2);% 16 for each triangle
                                    GW_T_par=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),1);
                                    det_Jac_vec=zeros(numel(xiGP)*numel(etaGP)*size(triangulation,1),1);
                                    counter=1;

                                    for tr= 1:size(triangulation,1)
                                        verTriangle=[new_meshVert(triangulation(tr,:),1) new_meshVert(triangulation(tr,:),2)];
                                        s1=verTriangle(1,1); s2=verTriangle(2,1);s3=verTriangle(3,1);
                                        t1=verTriangle(1,2); t2=verTriangle(2,2);t3=verTriangle(3,2);
                                        detJac_Triangle= det([-s1+s2 -t1+t2; -s1+s3 -t1+t3]);
                                        for gpa=1:numel(etaGP)
                                            for gpb=1:numel(xiGP)
                                                xiGTri= (1+xiGP(gpb))/2;
                                                etaGTri = (1-xiGP(gpb))*(1+etaGP(gpa))/4;
                                                gw_tr= (1-xiGP(gpb))*GWxi(gpb)*GWeta(gpa)/8;

                                                GP_T_par(counter,:)=GP_T_par(counter,:)+...
                                                    [(1-xiGTri-etaGTri)*s1+xiGTri*s2+etaGTri*s3 (1-xiGTri-etaGTri)*t1+xiGTri*t2+etaGTri*t3];
                                                GW_T_par(counter)=GW_T_par(counter)+gw_tr;
                                                det_Jac_vec(counter)=det_Jac_vec(counter)+detJac_Triangle;
                                                counter=counter+1;
                                            end
                                        end
                                    end
                                    [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlateTrim ...
                                        (propGeomHRef, det_Jac_vec, propStr,GP_T_par,GW_T_par);

                                else
                                    stiffMtxEl = zeros(numDOFsEl);
                                    loadVctEl = zeros(numDOFsEl, 1);
                                    inactive_elements=inactive_elements+1;
                                end
                            else
                                active_DOF=[active_DOF eft];

                                detJxiTildexi = (el(2) - el(1))*(el(4) - el(3))/4;

                                [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlate ...
                                    (propGeomHRef, el, detJxiTildexi, propStr);
                            end


                        else
                            active_DOF=[active_DOF eft];

                            detJxiTildexi = (el(2) - el(1))*(el(4) - el(3))/4;

                            [stiffMtxEl, loadVctEl] = app.computeIGAElStiffMtxandForceVctReissnerMindlinPlate ...
                                (propGeomHRef, el, detJxiTildexi, propStr);
                        end

                        stiffMtx(eft, eft) = stiffMtx(eft, eft) + stiffMtxEl;
                        loadVct(eft, 1) = loadVct(eft, 1) + loadVctEl;
                    end
                end
            end

                homDOFs=[];
                if app.CheckBox_1.Value==1 % clamp edge xi =0
                    xiSpan = [propGeomHRef.knotVct{1}(1) propGeomHRef.knotVct{1}(1)];
                    etaSpan = [propGeomHRef.knotVct{2}(1) propGeomHRef.knotVct{2}(end)];
                    numDOFsPerCP = 3;
                    idField = 1:3;
                    homDOFs = app.findDOFsNURBSSurface(homDOFs, propGeomHRef, xiSpan, etaSpan, numDOFsPerCP, idField);
                end
                if app.CheckBox_2.Value==1 % clamp edge xi =1
                    xiSpan = [propGeomHRef.knotVct{1}(end) propGeomHRef.knotVct{1}(end)];
                    etaSpan = [propGeomHRef.knotVct{2}(1) propGeomHRef.knotVct{2}(end)];
                    numDOFsPerCP = 3;
                    idField = 1:3;
                    homDOFs = app.findDOFsNURBSSurface(homDOFs, propGeomHRef, xiSpan, etaSpan, numDOFsPerCP, idField);
                end
                if app.CheckBox_3.Value==1 % clamp edge eta =0
                    xiSpan = [propGeomHRef.knotVct{1}(1) propGeomHRef.knotVct{1}(end)];
                    etaSpan = [propGeomHRef.knotVct{2}(1) propGeomHRef.knotVct{2}(1)];
                    numDOFsPerCP = 3;
                    idField = 1:3;
                    homDOFs = app.findDOFsNURBSSurface(homDOFs, propGeomHRef, xiSpan, etaSpan,numDOFsPerCP, idField);
                end


                if app.CheckBox_4.Value==1 % clamp edge eta =1
                    xiSpan = [propGeomHRef.knotVct{1}(1) propGeomHRef.knotVct{1}(end)];
                    etaSpan = [propGeomHRef.knotVct{2}(end) propGeomHRef.knotVct{2}(end)];
                    numDOFsPerCP = 3;
                    idField = 1:3;
                    homDOFs = app.findDOFsNURBSSurface(homDOFs, propGeomHRef, xiSpan,etaSpan, numDOFsPerCP, idField);
                end

                dofId = find(vecnorm(stiffMtx, 2, 2)==0);
                freeDOFs = 1:numDOFs;
                active_DOF=unique(active_DOF);
                inactive_DOF=setdiff(freeDOFs,active_DOF);
                freeDOFs(ismember(freeDOFs,homDOFs)) = [];
                freeDOFs(ismember(freeDOFs,inactive_DOF))=[];
                u = zeros(numDOFs, 1);
                u(freeDOFs) = stiffMtx(freeDOFs, freeDOFs)\loadVct(freeDOFs); % solving for displacements
                app.globDisp=u;

                propGeomHRefDef = propGeomHRef;
                propGeomHRefDef.controlPoints(3, :) = propGeomHRefDef.controlPoints(3, :) + transpose(1*u(1:3:end));
                app.deformProp=propGeomHRefDef;
                app.plotNURBSSurface(app.UIAxes5,propGeomHRefDef, 100, 100, numBFsRef,1);
                hold (app.UIAxes5,'on');
                grid(app.UIAxes5,'on');
                X0 = app.controlPoints(1,1);
                XLx = app.controlPoints(1,end);
                Y0 = app.controlPoints(2,1);
                YLy = app.controlPoints(2,end);
                syms wEx(X) betayEx(X) qxEx(X) Lsym psym Gsym Aqsym ...
                    Esym Isym tsym alphaScf
                wEx(X) = (psym*Lsym*X-psym*X^2/2)/Gsym/(Aqsym) - ...
                    (-psym*X^4/24+psym*Lsym*X^3/6-psym*Lsym^2*X^2/4)/Esym/Isym;
                wExSubs(X) = subs(wEx,{Lsym, psym, Gsym, Esym, Isym, Aqsym}, ...
                    {XLx, propStr.pBar, propStr.G, propStr.E, ...
                    YLy*(propStr.t^3)/12, propStr.alpha*YLy*propStr.t});

                % fsurf(app.UIAxes5,wExSubs, [X0 XLx Y0 YLy], FaceColor=[203 65 84]/255, ...
                %     FaceAlpha=0.5, LineStyle="none");
                % hold (app.UIAxes5,'off');
                % legend( app.UIAxes5,["IGA", "Analytical"], Location="best")
                %title(app.UIAxes5,"Plate's deformation using isogeometric elements")
                title(app.UIAxes5,"Deformation of Plate")
        end

        % Button pushed function: SetBCongeometryButton
        function SetBCongeometryButtonPushed(app, event)
            output_file = 'b_rep.mat';
            anacondaPythonExecutable = 'C:\Users\oslon\anaconda3\python.exe';
            pythonScript = 'C:\Users\oslon\Desktop\MSc\Thesis\Code Scripts\Python Files\boundary_gui.py';
            %Check if Anaconda Python executable exists
            if exist(anacondaPythonExecutable, 'file') == 2
                %Build the command
                command = sprintf('%s "%s" "%s" "%s" "%d" "%d"', anacondaPythonExecutable, pythonScript, app.file_path,...
                    "boundary",app.NumberofKnotsxiSpinner.Value, app.NumberofKnotsetaSpinner.Value);
                %Call Python script
                [status, ~] = system(command, '-echo');
                if status == 0
                    disp("In MATLAB dirichlet now")
                    data_load = load(output_file);
                    app.dir_info=data_load.dir_list;
                    for i=1:length(app.dir_info)
                        data=app.dir_info{i};
                        app.dir_mtx{i}=[data.cur_pts(:),data.gs_wts(:),data.gs_jac(:),data.xi_vls_1(:),data.eta_vls_1(:)];
                        app.dir_curve{i}.deg=double(data.crv_deg);
                        app.dir_curve{i}.knt=data.crv_knotVec;
                        app.dir_curve{i}.CP=data.crv_CPS;
                    end
                    disp(app.dir_mtx);
                    app.Lamp.Color= [0,1,0];
                else
                    app.Lamp.Color=[1,0,0];
                end
            end
        end

        % Callback function: not associated with a component
        function PatchCouplingGUIButtonPushed(app, event)
            output_file = 'coup.mat';

            anacondaPythonExecutable = 'C:\Users\oslon\anaconda3\python.exe';
            pythonScript = 'C:\Users\oslon\Desktop\MSc\Thesis\Code Scripts\Python Files\coupling_gui_new_logic.py';
            %Check if Anaconda Python executable exists
            if exist(anacondaPythonExecutable, 'file') == 2
                %Build the command
                command = sprintf('%s "%s" "%s" "%s" "%d" "%d"', anacondaPythonExecutable, pythonScript, app.file_path,...
                    "coupling",app.NumberofKnotsxiSpinner.Value, app.NumberofKnotsetaSpinner.Value);
                %Call Python script
                [status, ~] = system(command, '-echo');
                if status == 0
                    disp("In MATLAB coupling now")
                    data = load(output_file);
                    app.coupling_mtx=[data.cur_pts_1(:),data.gs_wts(:),data.gs_jac(:),data.xi_vls_1(:),data.eta_vls_1(:),...
                        data.xi_vls_2(:),data.eta_vls_2(:)];
                    app.coupling_curve.deg=double(data.crv_deg);
                    app.coupling_curve.knt=data.crv_knotVec;
                    app.coupling_curve.CP=data.crv_CPS;
                    disp(app.coupling_mtx);
                    app.Lamp_2.Color= [0,1,0];
                else
                    app.Lamp_2.Color=[1,0,0];
                end

            end
        end

        % Value changed function: PatchLabelsCheckBox
        function PatchLabelsCheckBoxValueChanged(app, event)

            app.updatePlot
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
           
            % Getting Patch Informations
            [BSplinePatches,noDOFsPatches]= app.fill_up_patches;
            K_multiPatch = zeros(noDOFsPatches, noDOFsPatches); % Initializing Global Stiffness Matrix for MultiPatch System
            F_multiPatch = zeros(noDOFsPatches,1); % Initializing Global Force Vectir for MultiPatch System
            KPenalty = zeros(noDOFsPatches, noDOFsPatches); % Initializing Global Penalty Matrix
            alphaD=10^5;
            alphaR=10^3;
            %% B_Rep Weak Dirichlet
            %app.dir_mtx=[data.cur_pts(:),data.gs_wts(:),data.gs_jac(:),data.xi_vls_1(:),data.eta_vls_1(:),data.crv_deg, data.crv_knotVec, data.crv_CPS];
            numDir=length(app.dir_info);
            for j=1:numDir
                dir_data=app.dir_info{j};
                dirFace_num=dir_data.face_id;
                patch_dir=BSplinePatches{dirFace_num};
                Kp_dirichlet=app.computePenaltyMtcesPlatesDirichlet(j,patch_dir,alphaD, alphaR);
                KPenalty(patch_dir.EFTPatches,patch_dir.EFTPatches) = KPenalty(patch_dir.EFTPatches,patch_dir.EFTPatches) + Kp_dirichlet;
            end
            %nonzero_entries1 = sum(nnz(Kp_dirichlet)); To check number of non-zero entries 
       
            %% B_Rep Penalty Coupling
            %app.coupling_mtx{i}=[data.cur_pts_1(:),data.gs_wts(:),data.gs_jac(:),data.xi_vls_1(:),data.eta_vls_1(:),data.xi_vls_2(:),data.eta_vls_2(:)
            %                   data.crv_deg, data.crv_knotVec, data.crv_CPS];
            % Looping through the connections and get the matrices
            if app.num_patch>1
                numConnections=length(app.coupling_info);
                for i = 1:numConnections
                    coupling_data=app.coupling_info{i};
                    id1=coupling_data.face_I_ind;
                    id2=coupling_data.face_J_ind;
                    patch_I=BSplinePatches{id1};
                    patch_J=BSplinePatches{id2};

                    [KpDispI, CpDispI, KpDispJ] =app.computePenaltyMtcesPlatesCoupling(i,patch_I, patch_J, alphaD, alphaR);
                    % To check number of nonzero_entries3 = sum(nnz(KpDispI));

                    % Assemble the stiffness matrix contribution to patch 1
                    KPenalty(patch_I.EFTPatches,patch_I.EFTPatches) = KPenalty(patch_I.EFTPatches,patch_I.EFTPatches) + KpDispI ;
                    % Assemble the stiffness matrix contribution to patch 2
                    KPenalty(patch_J.EFTPatches,patch_J.EFTPatches) = KPenalty(patch_J.EFTPatches,patch_J.EFTPatches) + KpDispJ;
                    % Assemble to the coupling matrix contribution of the variational problem in patch 1
                    KPenalty(patch_I.EFTPatches,patch_J.EFTPatches) = KPenalty(patch_I.EFTPatches,patch_J.EFTPatches) + CpDispI ;
                    % Assemble to the coupling matrix contribution of the variational problem in patch 2
                    KPenalty(patch_J.EFTPatches,patch_I.EFTPatches) = KPenalty(patch_J.EFTPatches,patch_I.EFTPatches) +CpDispI';
                end
            end
            %% 1ii. Assemble to the master tangent stiffness matrix and residual vector
            for iPatches = 1:app.num_patch
                K_multiPatch(BSplinePatches{iPatches}.EFTPatches,...
                    BSplinePatches{iPatches}.EFTPatches) = K_multiPatch(BSplinePatches{iPatches}.EFTPatches,...
                    BSplinePatches{iPatches}.EFTPatches)+ BSplinePatches{iPatches}.stiffMtx;
                F_multiPatch(BSplinePatches{iPatches}.EFTPatches) = F_multiPatch(BSplinePatches{iPatches}.EFTPatches)+...
                    BSplinePatches{iPatches}.loadVct;
            end
            K_multiPatch = K_multiPatch + KPenalty;

            disp("In multi-patch attempting to solve the linear equation Ku=F")
            u_multi_patch_full=K_multiPatch\F_multiPatch;

            cla(app.UIAxes5);
            % Plotting the deformed shape
            for i=1:app.num_patch
                u_i= u_multi_patch_full(BSplinePatches{i}.EFTPatches);
                app.change_dict_list{i}.controlPoints(3, :)= app.change_dict_list{i}.controlPoints(3, :)+ transpose(1*u_i(1:3:end));
                app.Loop=i;
                numPtsXi=100;
                numPtsEta=100;
                prop.polOrd= [double(app.change_dict_list{i}.vDeg);double(app.change_dict_list{i}.uDeg)];
                prop.knotVct= {app.change_dict_list{i}.vknotVector,app.change_dict_list{i}.uknotVector};
                prop.weights=app.change_dict_list{i}.weights;
                prop.controlPoints=app.change_dict_list{i}.controlPoints;
                prop.dim=app.dim;

                numBFs=[ numel(prop.knotVct{1})-prop.polOrd(1)-1;
                    numel(prop.knotVct{2})-prop.polOrd(2)-1];
                numBFs=double(numBFs);

                app.plotNURBSSurface(app.UIAxes5,prop, numPtsXi, numPtsEta, numBFs,i);
                axis(app.UIAxes5, 'tight');
                hold(app.UIAxes5,'on');
            end
            hold(app.UIAxes_2,'off');
            app.TabGroup_2.SelectedTab = app.PostProcessingTab_2;
        end

        % Button pushed function: AutomaticCouplingButton
        function AutomaticCouplingButtonPushed(app, event)
            output_file = 'coup.mat';

            anacondaPythonExecutable = 'C:\Users\oslon\anaconda3\python.exe';
            pythonScript = 'C:\Users\oslon\Desktop\MSc\Thesis\Code Scripts\Python Files\multi-patch-coupling.py';
            %Check if Anaconda Python executable exists
            if exist(anacondaPythonExecutable, 'file') == 2
                %Build the command
                command = sprintf('%s "%s" "%s" "%s" "%d" "%d"', anacondaPythonExecutable, pythonScript, app.file_path,...
                    "coupling",app.NumberofKnotsxiSpinner.Value, app.NumberofKnotsetaSpinner.Value);
                %Call Python script
                [status, ~] = system(command, '-echo');
                if status == 0
                    disp("In MATLAB coupling now")
                    data_load = load(output_file);
                    app.coupling_info=data_load.coupling_list;
                    for i=1:length(app.coupling_info)
                        data=app.coupling_info{i};
                        app.coupling_mtx{i}=[data.cur_pts_1(:),data.gs_wts(:),data.gs_jac(:),data.xi_vls_1(:),data.eta_vls_1(:),...
                            data.xi_vls_2(:),data.eta_vls_2(:)];
                        app.coupling_curve{i}.deg=double(data.crv_deg);
                        app.coupling_curve{i}.knt=data.crv_knotVec;
                        app.coupling_curve{i}.CP=data.crv_CPS;
                    end
                    app.Lamp_2.Color= [0,1,0];
                else
                    app.Lamp_2.Color=[1,0,0];
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'winter');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'ISOGEOMETRIC   ANALYSIS   APP';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup3);
            app.WelcomeTab.Title = 'Welcome';
            app.TUMLabel = uilabel(app.WelcomeTab);
            app.TUMLabel.Text = 'TUM';
            app.ChooseYourCADFileButton = uibutton(app.WelcomeTab, 'push');
            app.ChooseYourCADFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseYourCADFileButtonPushed, true);
            app.ChooseYourCADFileButton.Text = 'Choose Your CAD File';
            app.TWTLabel = uilabel(app.WelcomeTab);
            app.TWTLabel.Text = 'TWT';
            app.CurvesTab = uitab(app.TabGroup3);
            app.CurvesTab.Title = 'Curves';
            app.TabGroup = uitabgroup(app.CurvesTab);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.UIAxes = uiaxes(app.DesignTab);
            title(app.UIAxes, 'NURBS GEOMETRY')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.WeightEditFieldLabel = uilabel(app.DesignTab);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.DesignTab, 'numeric');
            app.WeightEditField.Limits = [0 10];
            app.WeightEditField.ValueDisplayFormat = '%.1f';
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = 1;
            app.TabGroup2 = uitabgroup(app.DesignTab);
            app.DegreeElevationTab = uitab(app.TabGroup2);
            app.DegreeElevationTab.Title = 'Degree Elevation';
            app.DegreeChangeSpinnerLabel = uilabel(app.DegreeElevationTab);
            app.DegreeChangeSpinnerLabel.Text = 'Degree Change ';
            app.DegreeChangeSpinner = uispinner(app.DegreeElevationTab);
            app.DegreeChangeSpinner.Limits = [0 10];
            app.DegreeChangeSpinner.ValueDisplayFormat = '%.0f';
            app.DegreeChangeSpinner.ValueChangedFcn = createCallbackFcn(app, @DegreeChangeSpinnerValueChanged, true);
            app.Label = uilabel(app.DegreeElevationTab);
            app.Label.Text = '';
            app.KnotInsertionTab = uitab(app.TabGroup2);
            app.KnotInsertionTab.Title = 'Knot Insertion';
            app.NumberofKnotsSpinnerLabel = uilabel(app.KnotInsertionTab);
            app.NumberofKnotsSpinnerLabel.Text = 'Number of Knots';
            app.NumberofKnotsSpinner = uispinner(app.KnotInsertionTab);
            app.NumberofKnotsSpinner.Limits = [0 10];
            app.NumberofKnotsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofKnotsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofKnotsSpinnerValueChanged, true);
            app.VisualizeButton = uibutton(app.DesignTab, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.ClearPlotButton = uibutton(app.DesignTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ControlPointsSwitchLabel = uilabel(app.DesignTab);
            app.ControlPointsSwitchLabel.Text = 'Control Points';
            app.ControlPointsSwitch = uiswitch(app.DesignTab, 'slider');
            app.ControlPointsSwitch.Items = {'Hide', 'Show'};
            app.ControlPointsSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlPointsSwitchValueChanged, true);
            app.ControlPointsSwitch.Value = 'Hide';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.PerformBeamIGAButton = uibutton(app.AnalysisTab, 'push');
            app.PerformBeamIGAButton.ButtonPushedFcn = createCallbackFcn(app, @PerformBeamIGAButtonPushed, true);
            app.PerformBeamIGAButton.Text = 'Perform Beam IGA';
            app.BeamTypeDropDownLabel = uilabel(app.AnalysisTab);
            app.BeamTypeDropDownLabel.Text = 'Beam Type';
            app.BeamTypeDropDown = uidropdown(app.AnalysisTab);
            app.BeamTypeDropDown.Items = {'Bernoulli', 'Timoshenko'};
            app.BeamTypeDropDown.Value = 'Bernoulli';
            app.YoungsModuluskNm2Label = uilabel(app.AnalysisTab);
            app.YoungsModuluskNm2Label.Interpreter = 'latex';
            app.YoungsModuluskNm2Label.Text = 'Youngs Modulus (kN/$m^2$)';
            app.YoungsModuluskNm2EditField = uieditfield(app.AnalysisTab, 'numeric');
            app.YoungsModuluskNm2EditField.Value = 10000000;
            app.PoissonsRatioEditFieldLabel = uilabel(app.AnalysisTab);
            app.PoissonsRatioEditFieldLabel.Text = 'Poissons Ratio';
            app.PoissonsRatioEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PoissonsRatioEditField.Value = 0.3;
            app.WidthmEditFieldLabel = uilabel(app.AnalysisTab);
            app.WidthmEditFieldLabel.Text = 'Width (m)';
            app.WidthmEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.WidthmEditField.Value = 0.1;
            app.DepthmEditFieldLabel = uilabel(app.AnalysisTab);
            app.DepthmEditFieldLabel.Text = 'Depth (m)';
            app.DepthmEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DepthmEditField.Value = 0.1;
            app.alphaEditFieldLabel = uilabel(app.AnalysisTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.alphaEditField.ValueDisplayFormat = '%.3f';
            app.alphaEditField.Value = 0.8333;
            app.LoadkNmEditFieldLabel = uilabel(app.AnalysisTab);
            app.LoadkNmEditFieldLabel.Text = 'Load (kN/m)';
            app.LoadkNmEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.LoadkNmEditField.Value = 1;
            app.TabGroup4 = uitabgroup(app.AnalysisTab);
            app.LeftBCTab = uitab(app.TabGroup4);
            app.LeftBCTab.Title = 'Left BC';
            app.ClampLeftDisplacementCheckBox = uicheckbox(app.LeftBCTab);
            app.ClampLeftDisplacementCheckBox.Text = 'Clamp Left Displacement';
            app.ClampLeftRotationCheckBox = uicheckbox(app.LeftBCTab);
            app.ClampLeftRotationCheckBox.Text = 'Clamp Left Rotation';
            app.RightBCTab = uitab(app.TabGroup4);
            app.RightBCTab.Title = 'Right BC';
            app.ClampRightDisplacementCheckBox = uicheckbox(app.RightBCTab);
            app.ClampRightDisplacementCheckBox.Text = 'Clamp Right Displacement';
            app.ClampRightRotationCheckBox = uicheckbox(app.RightBCTab);
            app.ClampRightRotationCheckBox.Text = 'Clamp Right Rotation';
            app.ResultTypeDropDownLabel = uilabel(app.AnalysisTab);
            app.ResultTypeDropDownLabel.Text = 'Result Type';
            app.ResultTypeDropDown = uidropdown(app.AnalysisTab);
            app.ResultTypeDropDown.Items = {'displacement', 'crossSectionalRotation', 'force', 'moment', 'shearForce'};
            app.ResultTypeDropDown.Value = 'displacement';
            app.BeamIGAAFEMLabButton = uibutton(app.AnalysisTab, 'push');
            app.BeamIGAAFEMLabButton.ButtonPushedFcn = createCallbackFcn(app, @BeamIGAAFEMLabButtonPushed, true);
            app.BeamIGAAFEMLabButton.Text = 'Beam IGA AFEM Lab';
            app.PostProcessingTab_3 = uitab(app.TabGroup);
            app.PostProcessingTab_3.Title = 'Post-Processing';
            app.UIAxes3_3 = uiaxes(app.PostProcessingTab_3);
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes2_3 = uiaxes(app.PostProcessingTab_3);
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes4 = uiaxes(app.PostProcessingTab_3);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PlatesTab = uitab(app.TabGroup3);
            app.PlatesTab.Title = 'Plates';
            app.TabGroup_2 = uitabgroup(app.PlatesTab);
            app.DesignTab_2 = uitab(app.TabGroup_2);
            app.DesignTab_2.Title = 'Design';
            app.UIAxes_2 = uiaxes(app.DesignTab_2);
            title(app.UIAxes_2, 'NURBS GEOMETRY')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TabGroup2_2 = uitabgroup(app.DesignTab_2);
            app.DegreeElevationTab_2 = uitab(app.TabGroup2_2);
            app.DegreeElevationTab_2.Title = 'Degree Elevation';
            app.DeltaPxiLabel = uilabel(app.DegreeElevationTab_2);
            app.DeltaPxiLabel.Interpreter = 'latex';
            app.DeltaPxiLabel.Text = '$\Delta$p   ($\xi$) ';
            app.DeltapxiSpinner = uispinner(app.DegreeElevationTab_2);
            app.DeltapxiSpinner.Limits = [0 10];
            app.DeltapxiSpinner.ValueDisplayFormat = '%.0f';
            app.DeltapxiSpinner.ValueChangedFcn = createCallbackFcn(app, @DeltapxiSpinnerValueChanged, true);
            app.Label_2 = uilabel(app.DegreeElevationTab_2);
            app.Label_2.Text = '';
            app.DeltaqetaLabel = uilabel(app.DegreeElevationTab_2);
            app.DeltaqetaLabel.Interpreter = 'latex';
            app.DeltaqetaLabel.Text = '$\Delta$q   ($\eta$) ';
            app.DeltaqetaSpinner = uispinner(app.DegreeElevationTab_2);
            app.DeltaqetaSpinner.Limits = [0 10];
            app.DeltaqetaSpinner.ValueDisplayFormat = '%.0f';
            app.DeltaqetaSpinner.ValueChangedFcn = createCallbackFcn(app, @DeltaqetaSpinnerValueChanged, true);
            app.Label_3 = uilabel(app.DegreeElevationTab_2);
            app.Label2 = uilabel(app.DegreeElevationTab_2);
            app.Label2.Text = 'Label2';
            app.KnotInsertionTab_2 = uitab(app.TabGroup2_2);
            app.KnotInsertionTab_2.Title = 'Knot Insertion';
            app.NumberofKnotsxiLabel = uilabel(app.KnotInsertionTab_2);
            app.NumberofKnotsxiLabel.Interpreter = 'latex';
            app.NumberofKnotsxiLabel.Text = 'Number of Knots (\xi)';
            app.NumberofKnotsxiSpinner = uispinner(app.KnotInsertionTab_2);
            app.NumberofKnotsxiSpinner.Limits = [0 50];
            app.NumberofKnotsxiSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofKnotsxiSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofKnotsxiSpinnerValueChanged, true);
            app.NumberofKnotsetaLabel = uilabel(app.KnotInsertionTab_2);
            app.NumberofKnotsetaLabel.Interpreter = 'latex';
            app.NumberofKnotsetaLabel.Text = 'Number of Knots (\eta)';
            app.NumberofKnotsetaSpinner = uispinner(app.KnotInsertionTab_2);
            app.NumberofKnotsetaSpinner.Limits = [0 50];
            app.NumberofKnotsetaSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofKnotsetaSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofKnotsetaSpinnerValueChanged, true);
            app.VisualizeButton_2 = uibutton(app.DesignTab_2, 'push');
            app.VisualizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_2Pushed, true);
            app.VisualizeButton_2.Text = 'Visualize';
            app.ClearPlotButton_2 = uibutton(app.DesignTab_2, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.SurfacePlotPropertiesPanel = uipanel(app.DesignTab_2);
            app.SurfacePlotPropertiesPanel.Title = 'Surface Plot Properties';
            app.ControlPolygonCheckBox = uicheckbox(app.SurfacePlotPropertiesPanel);
            app.ControlPolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlPolygonCheckBoxValueChanged, true);
            app.ControlPolygonCheckBox.Text = 'Control Polygon';
            app.ElementsCheckBox = uicheckbox(app.SurfacePlotPropertiesPanel);
            app.ElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElementsCheckBoxValueChanged, true);
            app.ElementsCheckBox.Text = 'Elements';
            app.ElementsCheckBox.Value = true;
            app.NumandIDsCheckBox = uicheckbox(app.SurfacePlotPropertiesPanel);
            app.NumandIDsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumandIDsCheckBoxValueChanged, true);
            app.NumandIDsCheckBox.Text = 'Num and IDs';
            app.PatchLabelsCheckBox = uicheckbox(app.SurfacePlotPropertiesPanel);
            app.PatchLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PatchLabelsCheckBoxValueChanged, true);
            app.PatchLabelsCheckBox.Text = 'Patch Labels';
            app.PatchDropDownLabel = uilabel(app.DesignTab_2);
            app.PatchDropDownLabel.Text = 'Patch';
            app.PatchDropDown = uidropdown(app.DesignTab_2);
            app.PatchDropDown.Items = {' 1'};
            app.PatchDropDown.Value = ' 1';
            app.AnalysisTab_2 = uitab(app.TabGroup_2);
            app.AnalysisTab_2.Title = 'Analysis';
            app.AnalysisTypeDropDownLabel = uilabel(app.AnalysisTab_2);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.AnalysisTab_2);
            app.AnalysisTypeDropDown.Items = {'Reissner-Mindlin Plate', 'Kirchhoff-Love Shell'};
            app.AnalysisTypeDropDown.Value = 'Reissner-Mindlin Plate';
            app.PoissonsRatioEditField_2Label = uilabel(app.AnalysisTab_2);
            app.PoissonsRatioEditField_2Label.Text = 'Poissons Ratio';
            app.PoissonsRatioEditField_2 = uieditfield(app.AnalysisTab_2, 'numeric');
            app.ThicknessmEditFieldLabel = uilabel(app.AnalysisTab_2);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessmEditField = uieditfield(app.AnalysisTab_2, 'numeric');
            app.ThicknessmEditField.Value = 0.1;
            app.ShearFactoralphaEditFieldLabel = uilabel(app.AnalysisTab_2);
            app.ShearFactoralphaEditFieldLabel.Text = 'Shear Factor (alpha)';
            app.ShearFactoralphaEditField = uieditfield(app.AnalysisTab_2, 'numeric');
            app.ShearFactoralphaEditField.ValueDisplayFormat = '%.3f';
            app.ShearFactoralphaEditField.Value = 0.8333;
            app.SurfaceLoadkNm2Label = uilabel(app.AnalysisTab_2);
            app.SurfaceLoadkNm2Label.Interpreter = 'latex';
            app.SurfaceLoadkNm2Label.Text = '\textbf{Surface Load}(kN/$m^{2}$)';
            app.textbfSurfaceLoadkNm2EditField = uieditfield(app.AnalysisTab_2, 'numeric');
            app.textbfSurfaceLoadkNm2EditField.Value = -30;
            app.TabGroup5 = uitabgroup(app.AnalysisTab_2);
            app.BCsusingParametersTab = uitab(app.TabGroup5);
            app.BCsusingParametersTab.Title = 'BCs using Parameters';
            app.CheckBox_1 = uicheckbox(app.BCsusingParametersTab);
            app.CheckBox_1.Text = '';
            app.Dirich_shell_1 = uilabel(app.BCsusingParametersTab);
            app.Dirich_shell_1.Interpreter = 'latex';
            app.Dirich_shell_1.Text = 'Clamp Edge $\xi$=0';
            app.Dirich_shell_2 = uilabel(app.BCsusingParametersTab);
            app.Dirich_shell_2.Interpreter = 'latex';
            app.Dirich_shell_2.Text = 'Clamp Edge $\xi$=1';
            app.Dirich_shell_3 = uilabel(app.BCsusingParametersTab);
            app.Dirich_shell_3.Interpreter = 'latex';
            app.Dirich_shell_3.Text = 'Clamp Edge $\eta$=0';
            app.Dirich_shell_4 = uilabel(app.BCsusingParametersTab);
            app.Dirich_shell_4.Interpreter = 'latex';
            app.Dirich_shell_4.Text = 'Clamp Edge $\eta$=1';
            app.CheckBox_2 = uicheckbox(app.BCsusingParametersTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.BCsusingParametersTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.BCsusingParametersTab);
            app.CheckBox_4.Text = '';
            app.YoungsModuluskNmLabel = uilabel(app.AnalysisTab_2);
            app.YoungsModuluskNmLabel.Interpreter = 'latex';
            app.YoungsModuluskNmLabel.Text = '\textbf{Youngs Modulus} (kN/$m^{2}$)';
            app.textbfYoungsModuluskNm2EditField = uieditfield(app.AnalysisTab_2, 'numeric');
            app.textbfYoungsModuluskNm2EditField.Value = 10000000;
            app.SetBCongeometryButton = uibutton(app.AnalysisTab_2, 'push');
            app.SetBCongeometryButton.ButtonPushedFcn = createCallbackFcn(app, @SetBCongeometryButtonPushed, true);
            app.SetBCongeometryButton.Text = 'Set BC on geometry';
            app.Lamp = uilamp(app.AnalysisTab_2);
            app.Lamp_2 = uilamp(app.AnalysisTab_2);
            app.PerformAnalysisButton = uibutton(app.AnalysisTab_2, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.AutomaticCouplingButton = uibutton(app.AnalysisTab_2, 'push');
            app.AutomaticCouplingButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticCouplingButtonPushed, true);
            app.AutomaticCouplingButton.Text = 'Automatic Coupling';
            app.PostProcessingTab_2 = uitab(app.TabGroup_2);
            app.PostProcessingTab_2.Title = 'Post Processing';
            app.UIAxes5 = uiaxes(app.PostProcessingTab_2);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.PlotTypeDropDownLabel = uilabel(app.PostProcessingTab_2);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.PostProcessingTab_2);
            app.PlotTypeDropDown.Items = {'Deformation', 'Shear Force'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Deformation';
            app.CommandHistoryTab = uitab(app.TabGroup3);
            app.CommandHistoryTab.Title = 'Command History';
            app.outputHistory = uitextarea(app.CommandHistoryTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = igaSync

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end