classdef ParseExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChooseYourCADFileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        dim
        polyOrder
        pU
        pV
        knotVector
        knotU
        knotV
        weigtArray
        controlPoints
        propGeomCircle
        changeProp
    end
    
    methods (Access = private)
        
      function CallPythonScript(app, filePath)

            %Specify the full path to Anaconda Python executable
            anacondaPythonExecutable = 'C:\Users\oslon\anaconda3\python.exe';
            pythonScript = 'C:\Users\oslon\Desktop\MSc\Thesis\Code Scripts\parse.py';
            outputFile = 'output.mat';
            %Check if Anaconda Python executable exists
            if exist(anacondaPythonExecutable, 'file') == 2
                %Build the command
                command = sprintf('%s "%s" "%s"', anacondaPythonExecutable, pythonScript, filePath);
                %Call Python script
                [status, ~] = system(command, '-echo');
                %result = jsondecode(output);
                if status == 0
                    data = load(outputFile);
                    app.dim=data.dimension;
                    if app.dim==1
                        app.polyOrder=data.polDegree;
                        app.weigtArray=data.weights;
                        app.controlPoints=data.controlPoints;
                        app.propGeomCircle.controlPoints=app.controlPoints;
                        app.propGeomCircle.knotVct=app.knotVector;
                        app.propGeomCircle.weights=app.weigtArray;
                        app.propGeomCircle.polOrd=app.polyOrder;
                        app.changeProp=app.propGeomCircle;

                    else
                        app.pU=data.uDeg;
                        app.pV=data.vDeg;
                        app.knotU=data.uknotVector;
                        app.knotV=data.vknotVector;
                        app.weigtArray=data.weights;
                        app.controlPoints=data.controlPoints;

                        app.propGeomCircle.dim=app.dim;
                        app.propGeomCircle.knotVct={app.knotU;app.knotV};
                        app.propGeomCircle.polOrd=[app.pU;app.pV];
                        app.propGeomCircle.weights=app.weigtArray;
                        app.propGeomCircle.controlPoints=app.controlPoints;
                        app.changeProp=app.propGeomCircle;

                        disp(app.controlPoints)
                        disp(app.pU)
                        disp(app.pV)
                        disp(app.knotU)
                        disp(app.knotV)

                    end
                else
                    %Error executing Python script
                    disp('Error executing Python script.');
                end
            else
                %Anaconda Python executable not found
                disp('Anaconda Python executable not found. Please check the path.');
            end


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseYourCADFileButton
        function ChooseYourCADFileButtonPushed(app, event)
             % Display file selection dialog
            [filename, pathname] = uigetfile('*.igs', 'Select a File');
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the file selection.');
                return;
            end
            if ~isequal(filename, 0) && ~isequal(pathname, 0)
                % Call Python script with the selected file
                app.CallPythonScript(fullfile(pathname, filename));
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseYourCADFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseYourCADFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseYourCADFileButtonPushed, true);
            app.ChooseYourCADFileButton.Text = 'Choose Your CAD File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParseExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end